classdef BatteryManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        battMgrUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewTestMenu                     matlab.ui.container.Menu
        LoadSavedTestMenu               matlab.ui.container.Menu
        SaveCurrentTestMenu             matlab.ui.container.Menu
        SaveDatatoFileMenu              matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        EventLoggerMenu                 matlab.ui.container.Menu
        ExperimentLogFileMenu           matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        DataSettingsMenu                matlab.ui.container.Menu
        ConfigureDevicesMenu            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AppManualMenu                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SingleCell1STab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        ChargeCommandButtonGroup_BC_1S  matlab.ui.container.ButtonGroup
        IdleButton_BC_1S                matlab.ui.control.RadioButton
        ChargeButton_BC_1S              matlab.ui.control.RadioButton
        DischargeButton_BC_1S           matlab.ui.control.RadioButton
        CountCapacityButton_BC_1S       matlab.ui.control.RadioButton
        DurationsSpinnerLabel           matlab.ui.control.Label
        DurationsSpinner_BC_1S          matlab.ui.control.Spinner
        TargetSOCSpinnerLabel           matlab.ui.control.Label
        TargetSOCSpinner_BC_1S          matlab.ui.control.Spinner
        StartButton_BC_1S               matlab.ui.control.Button
        StopConditionDropDownLabel      matlab.ui.control.Label
        StopConditionDropDown_BC_1S     matlab.ui.control.DropDown
        AppliedCurrentASpinnerLabel     matlab.ui.control.Label
        AppliedCurrentSpinner_BC_1S     matlab.ui.control.Spinner
        VoltageVSpinnerLabel            matlab.ui.control.Label
        VoltageSpinner_BC_1S            matlab.ui.control.Spinner
        BasicControlCheckBox_1S         matlab.ui.control.CheckBox
        PauseButton_BC_1S               matlab.ui.control.StateButton
        BatteryMonitorPanel             matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        MonitorTabGroup_1S              matlab.ui.container.TabGroup
        DataReadingsTab                 matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UITable_single                  matlab.ui.control.Table
        DataPlotsTab                    matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        RunProgramPanel                 matlab.ui.container.Panel
        ProgramFileDropDownLabel        matlab.ui.control.Label
        ProgramFileDropDown_RP_1S       matlab.ui.control.DropDown
        OpenButton_RP_1S                matlab.ui.control.Button
        StartButton_RP_1S               matlab.ui.control.StateButton
        RunProgramCheckBox_1S           matlab.ui.control.CheckBox
        CyclingPanel                    matlab.ui.container.Panel
        AppliedCurrentASpinner_2Label   matlab.ui.control.Label
        AppliedCurrentASpinner_CYC_1S   matlab.ui.control.Spinner
        NumberofcyclesSpinnerLabel      matlab.ui.control.Label
        NumberofcyclesSpinner_CYC_1S    matlab.ui.control.Spinner
        StartButton_CYC_1S              matlab.ui.control.Button
        CyclingCheckBox_1S              matlab.ui.control.CheckBox
        PauseButton_CYC_1S              matlab.ui.control.StateButton
        SeriesStackedSTab               matlab.ui.container.Tab
        ParallelStackedPTab             matlab.ui.container.Tab
        SeriesParallelPackSPTab         matlab.ui.container.Tab
        quickAccessBar                  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TestProgressPanel               matlab.ui.container.Panel
        progressGrid                    matlab.ui.container.GridLayout
        TimerLabel                      matlab.ui.control.Label
        TimerValueLabel                 matlab.ui.control.Label
        CyclesLabel                     matlab.ui.control.Label
        CyclesValueLabel                matlab.ui.control.Label
        StatusPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ErrorStatusLabel_2              matlab.ui.control.Label
        ErrorStatusInd                  matlab.ui.control.Lamp
        ChargingStatusLabel_2           matlab.ui.control.Label
        ChargeStatusInd                 matlab.ui.control.Lamp
        ChargeStatusLabel               matlab.ui.control.Label
        ErrorStatusLabel                matlab.ui.control.Label
        EmergencyButton                 matlab.ui.control.StateButton
        NeededActionsTextAreaLabel      matlab.ui.control.Label
        NeededActionsTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        % Objects for the connectable devices
        eStop
        psu
        eload
        thermo
        
        indicator = struct ("red", [1.00,0.00,0.00], ...
                            "clear", [0.90,0.90,0.90],...
                            "orange", [0.93,0.69,0.13], ...
                            "green", [0.00,1.00,0.00], ...
                            "blue", [0.00,0.45,0.74]); % Color values for the Status indicators
        
        dataQ = parallel.pool.PollableDataQueue;     
        errorQ = parallel.pool.PollableDataQueue; 
%         cmdQ     
        randQ = parallel.pool.PollableDataQueue; 
        testSetting % Test Settings include battery and test configurations of how to run the test

        prgRunTimer = timer; % The timer function that runs when a test is started to receive data and errors from the parallel worker
        
        testRunning % 
        
        dataTable % Description
        cellIDs % Description
        stackArg % Description
        
        timeout = 5; % App-level Timeout of 5 seconds
    end
    
    properties (Access = public)
        devConfigsApp % Device Configs App
        eventLogApp % Error Logger App
        cmdQ
        prgRunFeval % Output from parallel executions
        
        chargeStatus    % Struct Containing the current charge status. Initialized in startup function                
        errorStatus     % Struct Containing the current error status. Initialized in startup function
        Val
        battData
        pool % Variable containing the parallel pool for the session
    end
    
    methods (Access = public)
        
        function updateChargeStatus(app, status)
            app.ChargeStatusLabel.Text = status.text;
            app.ChargeStatusInd.Color = status.color;
        end
        
        function updateErrorStatus(app, status)
            app.ErrorStatusLabel.Text = status.text;
            app.ErrorStatusInd.Color = status.color;
        end
        
        function getcmdQ(app)
            % Get Command Queue from parallel function so that commands like
            %  "stop" and "pause" can be sent
            
            if app.randQ.QueueLength > 0, app.cmdQ = poll(app.randQ); end
        end
    end
    
    methods (Access = private)
        
        function prgRunTimerStopped(app, varargin)
            app.sendCmdToParFun("stop");
            
            parState = "";
            if ~isempty(app.prgRunFeval) && isvalid(app.prgRunFeval)
                parState = app.prgRunFeval.State;
            end
            app.prgRunFeval = [];
            disp(parState + " : Timer Stopped")
            app.testRunning = false;
            app.StartButton_BC_1S.Text = "Start";
            app.devConfigsApp.disconnectAllDevices();
            
            pause(2);
            % RandQ - Random messages from test script
            while app.randQ.QueueLength ~= 0
                msg = app.randQ.poll();
                
                if isstring(msg)
                    disp(msg)
                end
            end
        end
        
        function prgRunTimer_Callback(app, varargin)
%             data = [];
%             disp("app.dataQ.QueueLength: " + app.dataQ.QueueLength)
            while app.dataQ.QueueLength ~= 0
                structData = app.dataQ.poll();
                app.battData = [app.battData; [structData.time, structData.data]];
            end
            if ~isempty(app.battData)
                tNew = [app.battData(:, 1), app.battData(:, 2),...
                    app.battData(:, 3), app.battData(:, 4), app.battData(:, 5),...
                    app.battData(:, 11), app.battData(:, 12)];
                            
                app.UITable_single.Data = [app.UITable_single.Data; tNew];
                app.battData = [];
%                 row = length(app.UITable_single.Data(:, 1)); col = 1;
                drawnow
            end
    
            % RandQ - Random messages from test script
            while app.randQ.QueueLength ~= 0
                msg = app.randQ.poll();
                
                if isstring(msg)
                    disp(msg)
                elseif isvalid(msg)
                    app.cmdQ = msg;
                end
            end
            
           while app.errorQ.QueueLength ~= 0
                err = app.errorQ.poll();
                if isstruct(err)
                    app.eventLogApp.Add(err.code, err.msg); % Show the exception on the Error Logger app
                elseif strcmpi(class(err), "string")
                    app.eventLogApp.Add(ErrorCode.UNKNOWN_ERROR, err); % Show the exception on the Error Logger app
                elseif strcmpi(class(err), "MException")
                    Handle_Exception(app, err);
                end
                
            end
%             disp("Timer: app.prgRunFeval.State = " + app.prgRunFeval.State)
            if isvalid(app.prgRunFeval)
                if strcmp(app.prgRunFeval.State, 'finished') ...
                        || strcmp(app.prgRunFeval.State, 'failed')...
                        || strcmp(app.prgRunFeval.State, 'unavailable')
                    
                    if strcmpi(app.testSetting.cellConfig, "single")
                        StartButton_BC_1SPushed(app, []); % Switch button off
                    end
                end
            else
                disp("app.prgRunFeval is NOT Valid")
            end
        end
        
        function Handle_Exception(app, MEX, varargin)
            %Handle_Exception Used to catch and display exceptions on the
            %Error Log app
            %   Inputs:
            %       app         : App object. Can otherwise add it behind
            %                       function i.e. app.Handle_Exception(MEX, varargin)
            %       MEX         : If the function is being called anywhere
            %                       in the program, this input will be a
            %                       Matlab EXception. Otherwise, if it is
            %                       being called by the USBTimer object,
            %                       then it is the Timer object.
            %       varargin    : If the USBTimer object is calling this
            %                       function, a struct of exceptions will
            %                       be recieved through varargin{1}.
            %
            
            if nargin <= 2
                stk = MEX.stack;
                mexStr = newline + string(char(9)) + string(MEX.message) + newline;
                for i=1:length(stk)
                    mexStr = mexStr + char(9) ...
                        + sprintf("Error in %s (line %d)",...
                        strrep(stk(i).name,'.','/'), stk(i).line);
                    %                 if i ~= length(stk)
                    mexStr = mexStr + newline + char(9); % char(9) = \tab
                    %                 end
                end
                
            else
                % Used in the case when the timer obj sends a exception in
                % stead of the typical Matlab Exception as seen above. The
                % timer object first sends the timer obj and then the
                % exception
                timerObj = MEX; % Receive the Timer object since it is not a MEX
                MEXstruct = varargin{1};
                MEX = MEXstruct.Data; % Receive the actaul MEX data from varargin
                
                mexStr = newline + string(char(9)) + string(MEX.message) + newline;
                mexStr = mexStr + char(9) ...
                    + sprintf("Error while evaluating %s for timer '%s'", MEXstruct.Type, timerObj.Name);
                mexStr = mexStr + newline + char(9); % char(9) = \tab
            end
            
            app.eventLogApp.Add(ErrorCode.EXCEPTION, mexStr); % Show the exception on the Error Logger app
        end
        
        function preTestConfigs(app)
            if ~isempty(app.devConfigsApp) && isempty(app.devConfigsApp.stackArg)
                        % Make sure user has configured devices before
                        % trying to start
                        
            end
            app.stackArg = app.devConfigsApp.getStackInfo();
            app.cellIDs = convertCharsToStrings(app.stackArg.Properties.RowNames);
            
            % Start parallel pool if it is not already running
            checkPool = gcp('nocreate');
            if isempty(checkPool)
                app.pool = parpool("local", 1);
            else
                app.pool = checkPool;
            end
            
        end
        
        function postTestConfigs(app)
%             getcmdQ(app);
            
        end
        
        function sendCmdToParFun(app, command)
            if ~isempty(app.cmdQ)
                send(app.cmdQ, command);
            else
                uialert(app.battMgrUIFigure, "Unable to send command '" + command + "' to parallel function.")
            end
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.battMgrUIFigure.Visible = false;
            app.NeededActionsTextArea.Value = "To Start, Go To 'File > New Test' to configure devices and begin test." + newline;
            
            app.chargeStatus = struct  ("eStop",        struct("text", "E-STOP", "color", app.indicator.red), ...
                                        "charging",     struct("text", "CHARGING", "color", app.indicator.orange), ...
                                        "idle",         struct("text", "IDLE", "color", app.indicator.clear), ...
                                        "discharging",  struct("text", "DISCHARGING", "color", app.indicator.blue));
                            
            app.errorStatus = struct   ("none",         struct("text", "NONE", "color", app.indicator.clear), ...
                                        "idle",         struct("text", "IDLE", "color", app.indicator.clear), ...
                                        "discharging",  struct("text", "DISCHARGING", "color", app.indicator.clear));
            app.battMgrUIFigure.Visible = true;                        
            app.TabGroup.Visible = false;
            
            app.eventLogApp = EventLogger(app);
            app.eventLogApp.errLogUIFigure.Visible = false;
            

        end

        % Close request function: battMgrUIFigure
        function battMgrUIFigureCloseRequest(app, event)
            if ~isempty(app.devConfigsApp) && isvalid(app.devConfigsApp)
                app.devConfigsApp.disconnectAllDevices();
                app.devConfigsApp.devConfigsUIFigure.Visible = false; % Hide and close in background
            end
            
            app.eventLogApp.errLogUIFigure.Visible = false; % Hide and close in background
            app.battMgrUIFigure.Visible = false; % Hide and close in background
            
            if ~isempty(app.pool)
                delete(app.pool);
            end
            if ~isempty(app.eventLogApp) 
                if isvalid(app.eventLogApp)
                    delete(app.eventLogApp)
                end
            end
            if ~isempty(app.devConfigsApp) 
                if isvalid(app.devConfigsApp)
                    delete(app.devConfigsApp)
                end
            end
            delete(app)

        end

        % Menu selected function: NewTestMenu
        function NewTestMenuSelected(app, event)
            % if the argument passed is not empty and is a valid handle
            if (~isempty(app.devConfigsApp) && isvalid(app.devConfigsApp)) 
                app.devConfigsApp.disconnectAllDevices();
                delete(app.devConfigsApp)
            end
            app.NeededActionsTextArea.Value = "Opening Device Configuration app...";
            pause(0.1);
            app.devConfigsApp = DeviceConfigs(app);
            app.NeededActionsTextArea.Value = "Select Test Type and/or open up the Error Logger in 'Open> Error Logger'";
            app.TabGroup.Visible = true;

        end

        % Menu selected function: ConfigureDevicesMenu
        function ConfigureDevicesMenuSelected(app, event)
            app.devConfigsApp.devConfigsUIFigure.Visible = true;
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            value = app.EmergencyButton.Value;
            
            app.devConfigsApp.toggleEStop(value);
            app.devConfigsApp.EmergencyButton_EStop.Value = value;            
            
            if value == 1
                app.updateChargeStatus(app.chargeStatus.eStop);
            else
                app.updateChargeStatus(app.chargeStatus.idle);
            end
        end

        % Value changed function: BasicControlCheckBox_1S
        function BasicControlCheckBox_1SValueChanged(app, event)
            value = app.BasicControlCheckBox_1S.Value;
            if value == 1
                % Deactivate the other control options
                app.CyclingCheckBox_1S.Value = ~value;
                app.RunProgramCheckBox_1S.Value = ~value;
                CyclingCheckBox_1SValueChanged(app, []);
                RunProgramCheckBox_1SValueChanged(app, []);
                
                % Enable components
                parent = app.BasicControlCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
%                     switch components(i)
%                         case app.ChargeCommandButtonGroup_BC_1S
%                             buttons = app.ChargeCommandButtonGroup_BC_1S.Children;
%                             for j = 1:length(buttons)
%                                 buttons(j).Enable = true;
%                             end
%                         case app.AppliedCurrentSpinner_BC_1S
%                             components(i).Enable = true;
%                             
%                         case app.StopConditionDropDown_BC_1S
%                             components(i).Enable = true;
%                     end
                    
                    if components(i) == app.ChargeCommandButtonGroup_BC_1S
                        buttons = app.ChargeCommandButtonGroup_BC_1S.Children;
                        for j = 1:length(buttons)
                            buttons(j).Enable = true;
                        end
                    elseif components(i) == app.AppliedCurrentSpinner_BC_1S ...
                            || components(i) == app.StopConditionDropDown_BC_1S
                        components(i).Enable = true;
                    end
                end
            else
                % Disable components
                parent = app.BasicControlCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if components(i) == app.ChargeCommandButtonGroup_BC_1S
                        buttons = app.ChargeCommandButtonGroup_BC_1S.Children;
                        for j = 1:length(buttons)
                            buttons(j).Enable = false;
                        end
                    elseif components(i) ~= app.BasicControlCheckBox_1S
                        components(i).Enable = false;
                    end
                end
            end
        end

        % Value changed function: CyclingCheckBox_1S
        function CyclingCheckBox_1SValueChanged(app, event)
            value = app.CyclingCheckBox_1S.Value;
            
            if value == 1
                % Deactivate the other control options
                app.BasicControlCheckBox_1S.Value = ~value;
                app.RunProgramCheckBox_1S.Value = ~value;
                BasicControlCheckBox_1SValueChanged(app, []);
                RunProgramCheckBox_1SValueChanged(app, []);
                
                % Enable components
                parent = app.CyclingCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    components(i).Enable = true;
                end
            else
                % Disable components
                parent = app.CyclingCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if components(i) ~= app.CyclingCheckBox_1S
                        components(i).Enable = false;
                    end
                end
            end
        end

        % Value changed function: RunProgramCheckBox_1S
        function RunProgramCheckBox_1SValueChanged(app, event)
            value = app.RunProgramCheckBox_1S.Value;
            if value == 1
                % Deactivate the other control options
                app.CyclingCheckBox_1S.Value = ~value;
                app.BasicControlCheckBox_1S.Value = ~value;
                CyclingCheckBox_1SValueChanged(app, []);
                BasicControlCheckBox_1SValueChanged(app, []);
                
                % Enable components
                parent = app.RunProgramCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    components(i).Enable = true;
                end
            else
                % Disable components
                parent = app.RunProgramCheckBox_1S.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if components(i) ~= app.RunProgramCheckBox_1S
                        components(i).Enable = false;
                    end
                end
            end
        end

        % Value changed function: StopConditionDropDown_BC_1S
        function StopConditionDropDown_BC_1SValueChanged(app, event)
            value = app.StopConditionDropDown_BC_1S.Value;
            
            app.DurationsSpinner_BC_1S.Enable = false;
            app.TargetSOCSpinner_BC_1S.Enable = false;
            app.VoltageSpinner_BC_1S.Enable = false;
            app.StartButton_BC_1S.Enable = false;
            app.PauseButton_BC_1S.Enable = false;
            
            switch value
                case "Duration"
                    app.DurationsSpinner_BC_1S.Enable = true;
                case "Target SOC"
                    app.TargetSOCSpinner_BC_1S.Enable = true;
                case "Voltage"
                    app.VoltageSpinner_BC_1S.Enable = true;
                otherwise
                    return;
            end
            app.StartButton_BC_1S.Enable = true;
            
        end

        % Button pushed function: StartButton_BC_1S
        function StartButton_BC_1SPushed(app, event)
            
            % Ask user for save Name and text explanation
            % Maybe don't require the user to click connect, just to select
            % all parameters
            
            if app.StartButton_BC_1S.Text == "Start"
                
                app.PauseButton_BC_1S.Enable = true;
                app.StartButton_BC_1S.Text = "Stop";
                
                pause(0.1);
                
                app.preTestConfigs();
                
                switch app.ChargeCommandButtonGroup_BC_1S.SelectedObject
                    case app.ChargeButton_BC_1S
                        chargeCurr = app.AppliedCurrentSpinner_BC_1S.Value;
                        
                        app.testSetting = app.devConfigsApp.getTestSettings();
                        
                        
                        switch app.StopConditionDropDown_BC_1S.Value
                            case "Duration" 
                                app.prgRunFeval = parfeval(app.pool, @chargeToTime, 1,...
                                    app.DurationsSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                                
                            case "Target SOC"
                                app.prgRunFeval = parfeval(app.pool, @chargeToSOC, 1,... 
                                    app.TargetSOCSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.devConfigsApp.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                            case "Voltage"
                                app.prgRunFeval = parfeval(app.pool, @chargeToVolt, 1,...
                                    app.VoltageSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.devConfigsApp.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                                disp("After Prg Run")
                            otherwise
                                % app.eventLogApp.
                        end
                    case app.DischargeButton_BC_1S
                        chargeCurr = app.AppliedCurrentSpinner_BC_1S.Value;
                        
                        app.testSetting = app.devConfigsApp.getTestSettings();
                        
                        
                        switch app.StopConditionDropDown_BC_1S.Value
                            case "Duration" 
                                app.prgRunFeval = parfeval(app.pool, @dischargeToTime, 1,...
                                    app.DurationsSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                                
                            case "Target SOC"
                                app.prgRunFeval = parfeval(app.pool, @dischargeToSOC, 1,... 
                                    app.TargetSOCSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.devConfigsApp.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                            case "Voltage"
                                app.prgRunFeval = parfeval(app.pool, @dischargeToVolt, 1,...
                                    app.VoltageSpinner_BC_1S.Value, chargeCurr, ...
                                    'cellIDs',          app.cellIDs,...
                                    'psuArgs',          app.devConfigsApp.psuArg, ...
                                    'eloadArgs',        app.devConfigsApp.eloadArg, ...
                                    'sysMCUArgs',       app.devConfigsApp.sysMCUArg, ...
                                    'saveArgs',         app.devConfigsApp.saveArg, ...
                                    'tempModArgs',      app.devConfigsApp.tempModArg, ...
                                    'stackArgs',        app.devConfigsApp.stackArg, ...
                                    'caller',           "gui", ...
                                    'dataQ',            app.dataQ, ...
                                    'randQ',            app.randQ, ...
                                    'errorQ',           app.errorQ, ...
                                    'testSettings',     app.testSetting);
                                disp("After Prg Run")
                            otherwise
                                % app.eventLogApp.
                        end
                    case app.CountCapacityButton_BC_1S
                        
                    otherwise
                        % Idle
                        
                end
                %                 
                app.prgRunTimer.ExecutionMode = 'fixedSpacing';
                app.prgRunTimer.Period = app.testSetting.readPeriod + 0.05;
                app.prgRunTimer.StartDelay = 3;
                %             app.prgRunTimer.TasksToExecute = 1000;
                app.prgRunTimer.StopFcn = @app.prgRunTimerStopped;
                app.prgRunTimer.TimerFcn = @app.prgRunTimer_Callback;
                app.prgRunTimer.ErrorFcn = {@app.Handle_Exception, []};
                
                % Start Program Run timer
                start(app.prgRunTimer);
                app.testRunning = true;
                                
                
%                 for col = testSettings.data2Record
%                     if 
%                     
%                 end
                
%                 RunTime = []; Voltage = []; Current = []; AhCap = []; SOC = []; Chnl_9 = []; Chnl_10 = []; 
                
%                 app.dataTable = table(RunTime, Voltage, Current, AhCap, SOC, Chnl_9, Chnl_10);
%                 app.UITable_single.Data = app.dataTable;
                app.UITable_single.ColumnName = ...
                        ["RunTime", "Voltage", "Current", "AhCap", "SOC", "Chnl_9", "Chnl_10"];
                
                app.postTestConfigs();

                    
            elseif app.StartButton_BC_1S.Text == "Stop"
                stop(app.prgRunTimer);
%                 delete(app.prgRunTimer);
                app.PauseButton_BC_1S.Enable = false;
                app.StartButton_BC_1S.Text = "Start";
                app.testRunning = false;                

            end
            
        end

        % Menu selected function: EventLoggerMenu
        function EventLoggerMenuSelected(app, event)
            app.eventLogApp.errLogUIFigure.Visible = true;
        end

        % Value changed function: PauseButton_BC_1S
        function PauseButton_BC_1SValueChanged(app, event)
            value = app.PauseButton_BC_1S.Value;
            if value == 1
                sendCmdToParFun(app, 'pause');
            else
                sendCmdToParFun(app, 'unpause');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.battMgrUIFigure = uifigure('Visible', 'off');
            app.battMgrUIFigure.Name = 'Battery Manager';
            app.battMgrUIFigure.CloseRequestFcn = createCallbackFcn(app, @battMgrUIFigureCloseRequest, true);
            app.battMgrUIFigure.Tag = 'battMgr';
            app.FileMenu = uimenu(app.battMgrUIFigure);
            app.FileMenu.Text = 'File';
            app.NewTestMenu = uimenu(app.FileMenu);
            app.NewTestMenu.MenuSelectedFcn = createCallbackFcn(app, @NewTestMenuSelected, true);
            app.NewTestMenu.Separator = 'on';
            app.NewTestMenu.Accelerator = 'n';
            app.NewTestMenu.Text = 'New Test';
            app.LoadSavedTestMenu = uimenu(app.FileMenu);
            app.LoadSavedTestMenu.Text = 'Load Saved Test';
            app.SaveCurrentTestMenu = uimenu(app.FileMenu);
            app.SaveCurrentTestMenu.Text = 'Save Current Test';
            app.SaveDatatoFileMenu = uimenu(app.FileMenu);
            app.SaveDatatoFileMenu.Separator = 'on';
            app.SaveDatatoFileMenu.Accelerator = 'S';
            app.SaveDatatoFileMenu.Text = 'Save Data to File';
            app.OpenMenu = uimenu(app.battMgrUIFigure);
            app.OpenMenu.Text = 'Open';
            app.EventLoggerMenu = uimenu(app.OpenMenu);
            app.EventLoggerMenu.MenuSelectedFcn = createCallbackFcn(app, @EventLoggerMenuSelected, true);
            app.EventLoggerMenu.Tooltip = {'Start the Error Log'};
            app.EventLoggerMenu.Accelerator = 'Q';
            app.EventLoggerMenu.Text = 'Error Logger';
            app.ExperimentLogFileMenu = uimenu(app.OpenMenu);
            app.ExperimentLogFileMenu.Separator = 'on';
            app.ExperimentLogFileMenu.Text = 'Experiment Log File';
            app.SettingsMenu = uimenu(app.battMgrUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.DataSettingsMenu = uimenu(app.SettingsMenu);
            app.DataSettingsMenu.Tooltip = {'Configure the settings for saving data such as the file name, frequency etc'};
            app.DataSettingsMenu.Text = 'Data Settings';
            app.ConfigureDevicesMenu = uimenu(app.SettingsMenu);
            app.ConfigureDevicesMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureDevicesMenuSelected, true);
            app.ConfigureDevicesMenu.Text = 'Configure Devices';
            app.HelpMenu = uimenu(app.battMgrUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AppManualMenu = uimenu(app.HelpMenu);
            app.AppManualMenu.Accelerator = 'H';
            app.AppManualMenu.Text = 'App Manual';
            app.TabGroup = uitabgroup(app.battMgrUIFigure);
            app.SingleCell1STab = uitab(app.TabGroup);
            app.SingleCell1STab.Tooltip = {'Configurations for a single Cell'};
            app.SingleCell1STab.Title = 'Single Cell (1S)';
            app.GridLayout4 = uigridlayout(app.SingleCell1STab);
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Tooltip = {'Basic Control involves timed charge and discharge, SOC-based charging and discharging as well as Capacity Counting'};
            app.ChargeCommandButtonGroup_BC_1S = uibuttongroup(app.Panel);
            app.ChargeCommandButtonGroup_BC_1S.Title = 'Charge Command';
            app.IdleButton_BC_1S = uiradiobutton(app.ChargeCommandButtonGroup_BC_1S);
            app.IdleButton_BC_1S.Text = 'Idle';
            app.IdleButton_BC_1S.Value = true;
            app.ChargeButton_BC_1S = uiradiobutton(app.ChargeCommandButtonGroup_BC_1S);
            app.ChargeButton_BC_1S.Text = 'Charge';
            app.DischargeButton_BC_1S = uiradiobutton(app.ChargeCommandButtonGroup_BC_1S);
            app.DischargeButton_BC_1S.Text = 'Discharge';
            app.CountCapacityButton_BC_1S = uiradiobutton(app.ChargeCommandButtonGroup_BC_1S);
            app.CountCapacityButton_BC_1S.Text = 'Count Capacity';
            app.DurationsSpinnerLabel = uilabel(app.Panel);
            app.DurationsSpinnerLabel.Text = 'Duration (s)';
            app.DurationsSpinner_BC_1S = uispinner(app.Panel);
            app.DurationsSpinner_BC_1S.Limits = [0 Inf];
            app.DurationsSpinner_BC_1S.Enable = 'off';
            app.TargetSOCSpinnerLabel = uilabel(app.Panel);
            app.TargetSOCSpinnerLabel.Text = {'Target SOC (%)'; ''};
            app.TargetSOCSpinner_BC_1S = uispinner(app.Panel);
            app.TargetSOCSpinner_BC_1S.Step = 0.1;
            app.TargetSOCSpinner_BC_1S.Limits = [0 100];
            app.TargetSOCSpinner_BC_1S.ValueDisplayFormat = '%5.f';
            app.TargetSOCSpinner_BC_1S.Enable = 'off';
            app.StartButton_BC_1S = uibutton(app.Panel, 'push');
            app.StartButton_BC_1S.ButtonPushedFcn = createCallbackFcn(app, @StartButton_BC_1SPushed, true);
            app.StartButton_BC_1S.Enable = 'off';
            app.StartButton_BC_1S.Text = 'Start';
            app.StopConditionDropDownLabel = uilabel(app.Panel);
            app.StopConditionDropDownLabel.Text = 'Stop Condition';
            app.StopConditionDropDown_BC_1S = uidropdown(app.Panel);
            app.StopConditionDropDown_BC_1S.Items = {'None', 'Duration', 'Target SOC', 'Voltage'};
            app.StopConditionDropDown_BC_1S.ValueChangedFcn = createCallbackFcn(app, @StopConditionDropDown_BC_1SValueChanged, true);
            app.StopConditionDropDown_BC_1S.Value = 'None';
            app.AppliedCurrentASpinnerLabel = uilabel(app.Panel);
            app.AppliedCurrentASpinnerLabel.Text = {'Applied Current (A)'; ''};
            app.AppliedCurrentSpinner_BC_1S = uispinner(app.Panel);
            app.AppliedCurrentSpinner_BC_1S.Step = 0.1;
            app.AppliedCurrentSpinner_BC_1S.Limits = [0 60];
            app.AppliedCurrentSpinner_BC_1S.ValueDisplayFormat = '%5.2f';
            app.VoltageVSpinnerLabel = uilabel(app.Panel);
            app.VoltageVSpinnerLabel.Text = 'Voltage (V)';
            app.VoltageSpinner_BC_1S = uispinner(app.Panel);
            app.VoltageSpinner_BC_1S.Step = 0.1;
            app.VoltageSpinner_BC_1S.Limits = [0 6];
            app.VoltageSpinner_BC_1S.ValueDisplayFormat = '%5.2f';
            app.VoltageSpinner_BC_1S.Enable = 'off';
            app.BasicControlCheckBox_1S = uicheckbox(app.Panel);
            app.BasicControlCheckBox_1S.ValueChangedFcn = createCallbackFcn(app, @BasicControlCheckBox_1SValueChanged, true);
            app.BasicControlCheckBox_1S.Tooltip = {'Check to activate Basic Control'};
            app.BasicControlCheckBox_1S.Text = 'Basic Control';
            app.BasicControlCheckBox_1S.Value = true;
            app.PauseButton_BC_1S = uibutton(app.Panel, 'state');
            app.PauseButton_BC_1S.ValueChangedFcn = createCallbackFcn(app, @PauseButton_BC_1SValueChanged, true);
            app.PauseButton_BC_1S.Enable = 'off';
            app.PauseButton_BC_1S.Text = 'Pause';
            app.BatteryMonitorPanel = uipanel(app.GridLayout4);
            app.BatteryMonitorPanel.Tooltip = {'Displays the battey measurements as they are obtained'};
            app.BatteryMonitorPanel.Title = 'Battery Monitor';
            app.GridLayout6 = uigridlayout(app.BatteryMonitorPanel);
            app.MonitorTabGroup_1S = uitabgroup(app.GridLayout6);
            app.DataReadingsTab = uitab(app.MonitorTabGroup_1S);
            app.DataReadingsTab.Title = 'Data Readings';
            app.GridLayout7 = uigridlayout(app.DataReadingsTab);
            app.UITable_single = uitable(app.GridLayout7);
            app.UITable_single.ColumnName = '';
            app.UITable_single.RowName = {};
            app.DataPlotsTab = uitab(app.MonitorTabGroup_1S);
            app.DataPlotsTab.Title = 'Data Plots';
            app.Panel_2 = uipanel(app.GridLayout6);
            app.RunProgramPanel = uipanel(app.GridLayout4);
            app.ProgramFileDropDownLabel = uilabel(app.RunProgramPanel);
            app.ProgramFileDropDownLabel.Text = 'Program File';
            app.ProgramFileDropDown_RP_1S = uidropdown(app.RunProgramPanel);
            app.ProgramFileDropDown_RP_1S.Items = {'Select ...'};
            app.ProgramFileDropDown_RP_1S.Enable = 'off';
            app.ProgramFileDropDown_RP_1S.Value = 'Select ...';
            app.OpenButton_RP_1S = uibutton(app.RunProgramPanel, 'push');
            app.OpenButton_RP_1S.Enable = 'off';
            app.OpenButton_RP_1S.Tooltip = {'Open .m scripts'};
            app.OpenButton_RP_1S.Text = 'Open';
            app.StartButton_RP_1S = uibutton(app.RunProgramPanel, 'state');
            app.StartButton_RP_1S.Enable = 'off';
            app.StartButton_RP_1S.Text = 'Start';
            app.RunProgramCheckBox_1S = uicheckbox(app.RunProgramPanel);
            app.RunProgramCheckBox_1S.ValueChangedFcn = createCallbackFcn(app, @RunProgramCheckBox_1SValueChanged, true);
            app.RunProgramCheckBox_1S.Tooltip = {'Check to run matlab script'};
            app.RunProgramCheckBox_1S.Text = 'Run Program';
            app.CyclingPanel = uipanel(app.GridLayout4);
            app.AppliedCurrentASpinner_2Label = uilabel(app.CyclingPanel);
            app.AppliedCurrentASpinner_2Label.Text = {'Applied Current (A)'; ''};
            app.AppliedCurrentASpinner_CYC_1S = uispinner(app.CyclingPanel);
            app.AppliedCurrentASpinner_CYC_1S.Step = 0.1;
            app.AppliedCurrentASpinner_CYC_1S.Limits = [0 60];
            app.AppliedCurrentASpinner_CYC_1S.ValueDisplayFormat = '%5.2f';
            app.AppliedCurrentASpinner_CYC_1S.Enable = 'off';
            app.NumberofcyclesSpinnerLabel = uilabel(app.CyclingPanel);
            app.NumberofcyclesSpinnerLabel.Text = 'Number of cycles';
            app.NumberofcyclesSpinner_CYC_1S = uispinner(app.CyclingPanel);
            app.NumberofcyclesSpinner_CYC_1S.Step = 0.1;
            app.NumberofcyclesSpinner_CYC_1S.Limits = [0 100];
            app.NumberofcyclesSpinner_CYC_1S.ValueDisplayFormat = '%5.f';
            app.NumberofcyclesSpinner_CYC_1S.Enable = 'off';
            app.StartButton_CYC_1S = uibutton(app.CyclingPanel, 'push');
            app.StartButton_CYC_1S.Enable = 'off';
            app.StartButton_CYC_1S.Text = 'Start';
            app.CyclingCheckBox_1S = uicheckbox(app.CyclingPanel);
            app.CyclingCheckBox_1S.ValueChangedFcn = createCallbackFcn(app, @CyclingCheckBox_1SValueChanged, true);
            app.CyclingCheckBox_1S.Tooltip = {'Check to activate Cycling'};
            app.CyclingCheckBox_1S.Text = 'Cycling';
            app.PauseButton_CYC_1S = uibutton(app.CyclingPanel, 'state');
            app.PauseButton_CYC_1S.Enable = 'off';
            app.PauseButton_CYC_1S.Text = 'Pause';
            app.SeriesStackedSTab = uitab(app.TabGroup);
            app.SeriesStackedSTab.Title = 'Series Stacked (#S)';
            app.ParallelStackedPTab = uitab(app.TabGroup);
            app.ParallelStackedPTab.Title = 'Parallel Stacked (#P)';
            app.SeriesParallelPackSPTab = uitab(app.TabGroup);
            app.SeriesParallelPackSPTab.Title = 'Series-Parallel Pack (#S#P)';
            app.quickAccessBar = uipanel(app.battMgrUIFigure);
            app.GridLayout5 = uigridlayout(app.quickAccessBar);
            app.TestProgressPanel = uipanel(app.GridLayout5);
            app.TestProgressPanel.Title = 'Test Progress';
            app.progressGrid = uigridlayout(app.TestProgressPanel);
            app.TimerLabel = uilabel(app.progressGrid);
            app.TimerLabel.Text = 'Timer: ';
            app.TimerValueLabel = uilabel(app.progressGrid);
            app.TimerValueLabel.Tooltip = {'Timer in DD:HH:MM:SS'};
            app.TimerValueLabel.Text = '00:00:00:00';
            app.CyclesLabel = uilabel(app.progressGrid);
            app.CyclesLabel.Text = 'Cycles: ';
            app.CyclesValueLabel = uilabel(app.progressGrid);
            app.CyclesValueLabel.Text = '0';
            app.StatusPanel = uipanel(app.GridLayout5);
            app.GridLayout2 = uigridlayout(app.StatusPanel);
            app.ErrorStatusLabel_2 = uilabel(app.GridLayout2);
            app.ErrorStatusLabel_2.Text = 'Error Status:';
            app.ErrorStatusInd = uilamp(app.GridLayout2);
            app.ChargingStatusLabel_2 = uilabel(app.GridLayout2);
            app.ChargingStatusLabel_2.Text = 'Charging Status:';
            app.ChargeStatusInd = uilamp(app.GridLayout2);
            app.ChargeStatusLabel = uilabel(app.GridLayout2);
            app.ChargeStatusLabel.Text = 'IDLE';
            app.ErrorStatusLabel = uilabel(app.GridLayout2);
            app.ErrorStatusLabel.Text = 'NONE';
            app.EmergencyButton = uibutton(app.GridLayout5, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Enable = 'off';
            app.EmergencyButton.Icon = 'stop.png';
            app.EmergencyButton.Text = 'EMERGENCY';
            app.NeededActionsTextAreaLabel = uilabel(app.GridLayout5);
            app.NeededActionsTextAreaLabel.Text = 'Needed Actions';
            app.NeededActionsTextArea = uitextarea(app.GridLayout5);
            % Show the figure after all components are created
            app.battMgrUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatteryManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.battMgrUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.battMgrUIFigure)
        end
    end
end