classdef DeviceConfigs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        devConfigsUIFigure              matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        devConfigCtrlPanel              matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LoadDeviceConfigsButton         matlab.ui.control.Button
        SaveDeviceConfigsButton         matlab.ui.control.Button
        ConnectALLDevicesButton         matlab.ui.control.StateButton
        PrevDeviceButton                matlab.ui.control.Button
        NextDeviceButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BatteryPackTab                  matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        CellIDsPanel                    matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        DeleteCellsButton               matlab.ui.control.Button
        AddCellsButton                  matlab.ui.control.Button
        CellConfigurationPanel          matlab.ui.container.Panel
        CellStackArrangementDropDownLabel  matlab.ui.control.Label
        cellConfigDropDown              matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        EditTableButton_BattPack        matlab.ui.control.StateButton
        SaveChangesButton               matlab.ui.control.Button
        DiscardChangesButton            matlab.ui.control.Button
        DataRecordingPanel              matlab.ui.container.Panel
        SampleTimesSpinnerLabel         matlab.ui.control.Label
        SampleTimeSpinner               matlab.ui.control.Spinner
        SaveFileNameEditFieldLabel      matlab.ui.control.Label
        SaveFileNameEditField           matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel     matlab.ui.control.Label
        SaveDirectoryEditField          matlab.ui.control.EditField
        ChooseButton                    matlab.ui.control.Button
        PurposeofTestTextAreaLabel      matlab.ui.control.Label
        PurposeofTestTextArea           matlab.ui.control.TextArea
        SelectDatatoRecordLabel         matlab.ui.control.Label
        SelectDatatoRecordListBox       matlab.ui.control.ListBox
        MeasurementDevicesPanel         matlab.ui.container.Panel
        VoltageDropDownLabel            matlab.ui.control.Label
        VoltageDropDown_BattPack        matlab.ui.control.DropDown
        CurrentDropDownLabel            matlab.ui.control.Label
        CurrentDropDown_BattPack        matlab.ui.control.DropDown
        TemperatureLabel                matlab.ui.control.Label
        TempDropDown_BattPack           matlab.ui.control.DropDown
        EStopMCUTab                     matlab.ui.container.Tab
        EStopGrid                       matlab.ui.container.GridLayout
        SerialPortPanel_EStop           matlab.ui.container.Panel
        SerialPortGrid_EStop            matlab.ui.container.GridLayout
        COMPortDropDown_Label           matlab.ui.control.Label
        COMPortDropDown_EStop           matlab.ui.control.DropDown
        BaudRateDropDown_2Label         matlab.ui.control.Label
        BaudRateDropDown_EStop          matlab.ui.control.DropDown
        TimeoutEditField_2Label         matlab.ui.control.Label
        TimeoutEditField_EStop          matlab.ui.control.EditField
        EmergencyButton_EStop           matlab.ui.control.StateButton
        ResponseTextAreaLabel           matlab.ui.control.Label
        ResponseTextArea_EStop          matlab.ui.control.TextArea
        ByteOrderDropDownLabel          matlab.ui.control.Label
        ByteOrderDropDown_EStop         matlab.ui.control.DropDown
        TerminatorLabel                 matlab.ui.control.Label
        TerminatorDropDown_EStop        matlab.ui.control.DropDown
        StopBitsDropDownLabel_2         matlab.ui.control.Label
        StopBitsDropDown_EStop          matlab.ui.control.DropDown
        ConnectionMethodPanel_EStop     matlab.ui.container.Panel
        ConnectEStopButton              matlab.ui.control.StateButton
        ConnectionmethodDropDown_2Label  matlab.ui.control.Label
        ConnectionmethodDropDown_EStop  matlab.ui.control.DropDown
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        SerialPortPanel_PSU             matlab.ui.container.Panel
        SerialPortGrid_PSU              matlab.ui.container.GridLayout
        ClearAlarmCodesButton_PSU       matlab.ui.control.Button
        TerminalOutputsButtonGroup_PSU  matlab.ui.container.ButtonGroup
        ONButton_PSU                    matlab.ui.control.ToggleButton
        OFFButton_PSU                   matlab.ui.control.ToggleButton
        GetDeviceDetailsButton_PSU      matlab.ui.control.Button
        COMPortDropDownLabel            matlab.ui.control.Label
        COMPortDropDown_PSU             matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        BaudRateDropDown_PSU            matlab.ui.control.DropDown
        TimeoutEditFieldLabel           matlab.ui.control.Label
        TimeoutEditField_PSU            matlab.ui.control.EditField
        ByteOrderDropDownLabel_2        matlab.ui.control.Label
        ByteOrderDropDown_PSU           matlab.ui.control.DropDown
        TerminatorLabel_2               matlab.ui.control.Label
        TerminatorDropDown_PSU          matlab.ui.control.DropDown
        ResponseTextAreaLabel_2         matlab.ui.control.Label
        ResponseTextArea_PSU            matlab.ui.control.TextArea
        SetControlOwnerListBoxLabel     matlab.ui.control.Label
        SetControlOwnerListBox_PSU      matlab.ui.control.ListBox
        StopBitsDropDownLabel           matlab.ui.control.Label
        StopBitsDropDown_PSU            matlab.ui.control.DropDown
        LANCOMMPanel                    matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        PSUDropDownLabel                matlab.ui.control.Label
        PSUDropDown                     matlab.ui.control.DropDown
        ConnectonMethodGrid_PSU         matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel   matlab.ui.control.Label
        ConnectionmethodDropDown_PSU    matlab.ui.control.DropDown
        ConnectPSUButton                matlab.ui.control.StateButton
        SelfTestCheckBox_PSU            matlab.ui.control.CheckBox
        ElectronicLoadTab               matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GridLayout4_2                   matlab.ui.container.GridLayout
        SerialPortPanel_ELoad           matlab.ui.container.Panel
        SerialPortGrid_ELoad            matlab.ui.container.GridLayout
        ClearAlarmCodesButton_ELoad     matlab.ui.control.Button
        TerminalOutputsButtonGroup_ELoad  matlab.ui.container.ButtonGroup
        ONButton_ELoad                  matlab.ui.control.ToggleButton
        OFFButton_ELoad                 matlab.ui.control.ToggleButton
        GetDeviceDetailsButton_ELoad    matlab.ui.control.Button
        COMPortDropDownLabel_2          matlab.ui.control.Label
        COMPortDropDown_ELoad           matlab.ui.control.DropDown
        BaudRateDropDownLabel_2         matlab.ui.control.Label
        BaudRateDropDown_ELoad          matlab.ui.control.DropDown
        TimeoutEditFieldLabel_2         matlab.ui.control.Label
        TimeoutEditField_ELoad          matlab.ui.control.EditField
        ByteOrderDropDownLabel_3        matlab.ui.control.Label
        ByteOrderDropDown_ELoad         matlab.ui.control.DropDown
        TerminatorLabel_3               matlab.ui.control.Label
        TerminatorDropDown_ELoad        matlab.ui.control.DropDown
        ResponseTextAreaLabel_3         matlab.ui.control.Label
        ResponseTextArea_ELoad          matlab.ui.control.TextArea
        SetControlOwnerListBoxLabel_2   matlab.ui.control.Label
        SetControlOwnerListBox_ELoad    matlab.ui.control.ListBox
        StopBitsDropDownLabel_3         matlab.ui.control.Label
        StopBitsDropDown_ELoad          matlab.ui.control.DropDown
        GPIBPanel                       matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        GridLayout6_2                   matlab.ui.container.GridLayout
        ELoadLabel                      matlab.ui.control.Label
        ELoadDropDown                   matlab.ui.control.DropDown
        ConnectonMethodGrid_ELoad       matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel_2  matlab.ui.control.Label
        ConnectionmethodDropDown_ELoad  matlab.ui.control.DropDown
        ConnectELoadButton              matlab.ui.control.StateButton
        SelfTestCheckBox_ELoad          matlab.ui.control.CheckBox
        TemperatureModuleTab            matlab.ui.container.Tab
        GridLayout3_3                   matlab.ui.container.GridLayout
        GridLayout4_3                   matlab.ui.container.GridLayout
        SerialPortPanel_TempMod         matlab.ui.container.Panel
        SerialPortGrid_TempMod          matlab.ui.container.GridLayout
        COMPortDropDownLabel_3          matlab.ui.control.Label
        COMPortDropDown_TempMod         matlab.ui.control.DropDown
        BaudRateDropDownLabel_3         matlab.ui.control.Label
        BaudRateDropDown_TempMod        matlab.ui.control.DropDown
        TimeoutEditFieldLabel_3         matlab.ui.control.Label
        TimeoutEditField_TempMod        matlab.ui.control.EditField
        ByteOrderDropDownLabel_4        matlab.ui.control.Label
        ByteOrderDropDown_TempMod       matlab.ui.control.DropDown
        TerminatorLabel_4               matlab.ui.control.Label
        TerminatorDropDown_TempMod      matlab.ui.control.DropDown
        ResponseTextAreaLabel_4         matlab.ui.control.Label
        ResponseTextArea_TempMod        matlab.ui.control.TextArea
        StopBitsDropDownLabel_4         matlab.ui.control.Label
        StopBitsDropDown_TempMod        matlab.ui.control.DropDown
        GetTempDataButton_TempMod       matlab.ui.control.Button
        ConnectedChannelsListBoxLabel   matlab.ui.control.Label
        ConnectedChannelsListBox        matlab.ui.control.ListBox
        GPIBPanel_TempMod               matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        GridLayout6_3                   matlab.ui.container.GridLayout
        TempModLabel                    matlab.ui.control.Label
        TempModDropDown                 matlab.ui.control.DropDown
        ConnectonMethodGrid_TempMod     matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel_3  matlab.ui.control.Label
        ConnectionmethodDropDown_TempMod  matlab.ui.control.DropDown
        ConnectTempModButton            matlab.ui.control.StateButton
        SelfTestCheckBox_TempMod        matlab.ui.control.CheckBox
        BalancerTab                     matlab.ui.container.Tab
        SystemMCUTab                    matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout5_4                   matlab.ui.container.GridLayout
        GridLayout6_4                   matlab.ui.container.GridLayout
        MCUDropDownLabel                matlab.ui.control.Label
        SysMCUDropDown                  matlab.ui.control.DropDown
        ConnectonMethodGrid_SysMCU      matlab.ui.container.GridLayout
        ConnectionmethodDropDownLabel_4  matlab.ui.control.Label
        ConnectionmethodDropDown_SysMCU  matlab.ui.control.DropDown
        ConnectSysMCUButton             matlab.ui.control.StateButton
        SelfTestCheckBox_SysMCU         matlab.ui.control.CheckBox
        RelayStateButtonGroup           matlab.ui.container.ButtonGroup
        DeactivateRelayButton           matlab.ui.control.ToggleButton
        ActivateRelayButton             matlab.ui.control.ToggleButton
        GridLayout9                     matlab.ui.container.GridLayout
        ConnectionPanel_SysMCU          matlab.ui.container.Panel
        ConnectionGrid_SysMCU           matlab.ui.container.GridLayout
        ResponseTextAreaLabel_5         matlab.ui.control.Label
        ResponseTextArea_SysMCU         matlab.ui.control.TextArea
        SerialPanel_SysMCU              matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        COMPortDropDownLabel_4          matlab.ui.control.Label
        COMPortDropDown_SysMCU          matlab.ui.control.DropDown
        BaudRateDropDownLabel_4         matlab.ui.control.Label
        BaudRateDropDown_SysMCU         matlab.ui.control.DropDown
        ByteOrderDropDownLabel_5        matlab.ui.control.Label
        ByteOrderDropDown_SysMCU        matlab.ui.control.DropDown
        StopBitsDropDownLabel_5         matlab.ui.control.Label
        StopBitsDropDown_SysMCU         matlab.ui.control.DropDown
        TerminatorLabel_5               matlab.ui.control.Label
        TerminatorDropDown_SysMCU       matlab.ui.control.DropDown
        TimeoutEditFieldLabel_4         matlab.ui.control.Label
        TimeoutEditField_SysMCU         matlab.ui.control.EditField
        HIDDevicePanel_SysMCU           matlab.ui.container.Panel
        PinSettingsPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        DCTriggerPinsPanel              matlab.ui.container.Panel
        Trigger1Checkbox_SysMCU         matlab.ui.control.CheckBox
        Trigger2Checkbox_SysMCU         matlab.ui.control.CheckBox
        Trigger3Checkbox_SysMCU         matlab.ui.control.CheckBox
        Trigger4Checkbox_SysMCU         matlab.ui.control.CheckBox
        Trigger5Checkbox_SysMCU         matlab.ui.control.CheckBox
        ActivateButton_1                matlab.ui.control.StateButton
        ActivateButton_2                matlab.ui.control.StateButton
        ActivateButton_3                matlab.ui.control.StateButton
        ActivateButton_4                matlab.ui.control.StateButton
        ActivateButton_5                matlab.ui.control.StateButton
        Trigger1SpinnerLabel            matlab.ui.control.Label
        Trigger1Spinner                 matlab.ui.control.Spinner
        Trigger2SpinnerLabel            matlab.ui.control.Label
        Trigger2Spinner                 matlab.ui.control.Spinner
        Trigger3SpinnerLabel            matlab.ui.control.Label
        Trigger3Spinner                 matlab.ui.control.Spinner
        Trigger4SpinnerLabel            matlab.ui.control.Label
        Trigger4Spinner                 matlab.ui.control.Spinner
        Trigger5SpinnerLabel            matlab.ui.control.Label
        Trigger5Spinner                 matlab.ui.control.Spinner
        Trigger1InvertCheckbox_SysMCU   matlab.ui.control.CheckBox
        Trigger2InvertCheckbox_SysMCU   matlab.ui.control.CheckBox
        Trigger3InvertCheckbox_SysMCU   matlab.ui.control.CheckBox
        Trigger4InvertCheckbox_SysMCU   matlab.ui.control.CheckBox
        Trigger5InvertCheckbox_SysMCU   matlab.ui.control.CheckBox
        InvertLabel                     matlab.ui.control.Label
        EnableLabel                     matlab.ui.control.Label
        StartTimeEditField1             matlab.ui.control.EditField
        DurationEditField1              matlab.ui.control.EditField
        StartTimesLabel                 matlab.ui.control.Label
        DurationsLabel                  matlab.ui.control.Label
        StartTimeEditField2             matlab.ui.control.EditField
        DurationEditField2              matlab.ui.control.EditField
        StartTimeEditField3             matlab.ui.control.EditField
        DurationEditField3              matlab.ui.control.EditField
        StartTimeEditField4             matlab.ui.control.EditField
        DurationEditField4              matlab.ui.control.EditField
        StartTimeEditField5             matlab.ui.control.EditField
        DurationEditField5              matlab.ui.control.EditField
        RelayPinsPanel_2                matlab.ui.container.Panel
        RelayPinLabel                   matlab.ui.control.Label
        Relay1Spinner_SysMCU            matlab.ui.control.Spinner
        RelayPinLabel_2                 matlab.ui.control.Label
        Relay2Spinner_SysMCU            matlab.ui.control.Spinner
        SignalMeasurementPinsPanel      matlab.ui.container.Panel
        VoltagePinLabel                 matlab.ui.control.Label
        VoltagePlusSpinner_SysMCU       matlab.ui.control.Spinner
        VoltagePinLabel_2               matlab.ui.control.Label
        VoltageMinusSpinner_SysMCU      matlab.ui.control.Spinner
        CurrentPinLabel                 matlab.ui.control.Label
        CurrentPlusSpinner_SysMCU       matlab.ui.control.Spinner
        CurrentPinLabel_2               matlab.ui.control.Label
        CurrentMinusSpinner_SysMCU      matlab.ui.control.Spinner
        TextBoxContextMenu_EStop        matlab.ui.container.ContextMenu
        ClearMenu_ELoad                 matlab.ui.container.Menu
        TextBoxContextMenu_PSU          matlab.ui.container.ContextMenu
        ClearMenu_PSU                   matlab.ui.container.Menu
        TextBoxContextMenu_TempMod      matlab.ui.container.ContextMenu
        ClearMenu_TempMod               matlab.ui.container.Menu
        CellTreeContextMenu             matlab.ui.container.ContextMenu
        AddCellPackMenu                 matlab.ui.container.Menu
        ExpandMenu                      matlab.ui.container.Menu
        ExpandMenu_2                    matlab.ui.container.Menu
        ExpandAllMenu                   matlab.ui.container.Menu
        CollapseMenu                    matlab.ui.container.Menu
        CollapseMenu_2                  matlab.ui.container.Menu
        CollapseAllMenu                 matlab.ui.container.Menu
        DeselectAllMenu                 matlab.ui.container.Menu
        CellDeleteContextMenu           matlab.ui.container.ContextMenu
        DeleteCellPackMenu              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %         serialPortUpdateTimer % Timer that updates the drop down every once in a while
        %         serialPortUpdateInterval = 100; % ms
        mainApp % Parent App (Battery Manager)
%         dialog_addCell % Dialog App for adding new cells
        
        backupFileName = "devConfigs_BackupData"; % File name for file that contains the previous entries for the component on the app.
        
        appDataLoc % Path of the GUI data directory
        
        nextTab % Variable to store the state (or tab) for the "Next Tab" button
        prevTab % Variable to store the state (or tab) for the "Previous Tab" button
        
        batteryParam % Variable to store single cell parameters
        packParam  % Variable to store pack parameters
        trigPins % Vector to store the Trigger pins used by the System MCU
        trigInvertOut = zeros(5, 1); % Variable to store the inversion state of the output of each trigger
        trigStartTimes = {};
        trigDurations = {};


        LJAsm % LabJack Assembler? It is used for initializing the Labjack MCU though
        LJHandle % For Labjack
    end
    
    properties (Access = public)
        % Objects for the connectable devices
        eStop
        psu
        eload
        tempMod
        sysMCU
        dialog_addCells
        psuName         % Property to store the PSU device being used in this program. This is so the right class file can be used
        eloadName       % Property to store the eload device being used in this program. This is so the right class file can be used
        tempModName     % Property to store the eload device being used in this program. This is so the right class file can be used
        sysMCUName      % Property to store the sys MCU device being used in this program. This is so the right class file can be used
        
        battDataLoc     % Path for common Battery data directory
        
        stackArg        % Arguments about the cells being tested
        eStopArg        % Arguments from the GUI used to initialize the EStop object
        psuArg          % Arguments from the GUI used to initialize the PSU object
        eloadArg        % Arguments from the GUI used to initialize the ELoad object
        tempModArg      % Arguments from the GUI used to initialize the Thermo Module object
        balArg          % Arguments from the GUI used to initialize the Balancer object
        sysMCUArg       % Arguments from the GUI used to initialize the Relay MCU object
        saveArg         % Arguments from the GUI used to save Data results
        data2RecordArg  % Arguments from the GUI about the Data to record
        
        newCellsAdded % A cell Array of added data

        val
        devConnected % Description
        
        saveName
        saveDir
    end
    
    methods (Access = private)
        function updateSerialPortDropdowns(app)
            serialAvail = serialportlist("available"); % Get all serial ports currently available
            list = ["Select COM Port", serialAvail];
            app.COMPortDropDown_PSU.Items = list;
            app.COMPortDropDown_EStop.Items = list;
        end
        
        function updateSerialPortDropdown(~, dropdown)
            serialAvail = serialportlist("available"); % Get all serial ports currently available
            list = ["Select COM Port", serialAvail];
            dropdown.Items = list;
        end
        
        function updateTextArea(~, textArea, response)
            %updateTextArea Update Response Text Area
            cellArrayText = textArea.Value;
            if strcmp(cellArrayText(end), '') && length(cellArrayText) == 1
                textArea.Value = string(response) + newline + newline;
            else
                cellArrayText{end+1, 1} = char(response); % + newline;
                textArea.Value = cellArrayText;
                %                 textArea.Value = [textArea.Value; char(response); ""];
            end
        end
        
        
        function saveAllArgs(app)
            %saveAllArgs Saves all the arguments in components likely to
            %remain the same so user does not have to enter it every time
            
            % List of variables
            psuArgs = app.psuArg;
            
            save(app.appDataLoc + app.backupFileName, ...
                "psuArgs")
            
        end
        
        function updateCellNodes(app) 
            app.Tree.Children.delete; % Remove all nodes first
            
            if app.cellConfigDropDown.Value == "single"
                
                % First level nodes
                uniqueChemList = sort(unique(app.batteryParam.chemistry));
                len = length(uniqueChemList);
                chems = cell(1, len);
                cellNodes = cell(len, height(app.batteryParam));
                
                for i = 1:length(uniqueChemList)
                    chems{i} = uitreenode(app.Tree,'Text', uniqueChemList(i));
                    chems{i}.ContextMenu = app.CellTreeContextMenu;
                    
%                     cmChildren = chems{i}.ContextMenu.Children;
%                     for j = 1:length(cmChildren)
%                         if cmChildren(j) == app.RemoveCellPackMenu
%                             cmChildren(j).Enable = false;
%                         end
%                     end
                    
                    
                    % Second level nodes
                    rows = (app.batteryParam.chemistry==uniqueChemList(i));
                    cellList = sort(app.batteryParam(rows, "chemistry").Properties.RowNames);
                    
                    for j = 1:length(cellList)
                        cellNodes{i, j} = uitreenode(chems{i},'Text',cellList{j});
%                         cellNodes{i, j}.ContextMenu = app.CellDeleteContextMenu;
                    end
                    chems{i}.expand();
                end
            else
                % First level nodes
                uniqueChemList = sort(unique(app.packParam.chemistry));
                len = length(uniqueChemList);
                chems = cell(1, len); 
                cells = cell(len, height(app.packParam));
                
                for i = 1:length(uniqueChemList)
                    chems{i} = uitreenode(app.Tree,'Text', uniqueChemList(i));
                    chems{i}.ContextMenu = app.CellTreeContextMenu;

                    % Second level nodes
                    rows = (app.packParam.chemistry==uniqueChemList(i));
                    cellList = sort(app.packParam(rows, "chemistry").Properties.RowNames);
                    
                    for j = 1:length(cellList)
                        cells{i, j} = uitreenode(chems{i},'Text', cellList{j});
%                         cells{i, j}.ContextMenu = app.CellDeleteContextMenu;
                    end
                    chems{i}.expand();
                end
            end
            
        end
        
        
        function switchRelays(app, state)
            relayPins = [app.Relay1Spinner_SysMCU.Value, ...
                    app.Relay2Spinner_SysMCU.Value];
            % Request a single-ended bit change.
            app.sysMCU.AddRequestS(app.LJHandle,'LJ_ioPUT_DIGITAL_BIT', relayPins(1),state, 0, 0); % Negative Line
            
            % Request a single-ended bit change.
            app.sysMCU.AddRequestS(app.LJHandle,'LJ_ioPUT_DIGITAL_BIT', relayPins(2),state, 0, 0);% % Positive Line
            
            % Execute the requests.
            app.sysMCU.GoOne(app.LJHandle);
        end
        
        function switchTrig(app, state, pin)
            if strcmpi(pin, "all")
                for i = 1:length(app.trigPins)
                    % Request a single-ended bit change.
                    app.sysMCU.AddRequestS(app.LJHandle,'LJ_ioPUT_DIGITAL_BIT', app.trigPins(i), ~(state&app.trigInvertOut(i)), 0, 0); % Negative Line
                end
            else
                % Request a single-ended bit change.
                app.sysMCU.AddRequestS(app.LJHandle,'LJ_ioPUT_DIGITAL_BIT', pin, state, 0, 0); % Negative Line
            end
            
            % Execute the requests.
            app.sysMCU.GoOne(app.LJHandle);
        end
    end
    
    
    methods (Access = public)
        
        function enable_EStops(app, action)
            %Enable_EStops Enables or disables all EStops in the program
            app.EmergencyButton_EStop.Enable = action;
            if (~isempty(app.mainApp) && isvalid(app.mainApp)) % if the argument passed is not empty and is a valid handle
                app.mainApp.EmergencyButton.Enable = action;
            end
        end
        
        
        function connectAllDevices(app)
            app.ConnectALLDevicesButton.Value = 1;
            ConnectALLDevicesButtonValueChanged(app, []);
        end
        
        function disconnectAllDevices(app)
            
            % Disable PSU Output Terminals and Disconnect COMM
            if (~isempty(app.psu) && isvalid(app.psu)) % if the argument passed is not empty and is a valid handle
                app.OFFButton_PSU.Value = true;
                response = disconnect(app.psu) + newline;
                app.updateTextArea(app.ResponseTextArea_PSU, response); 
                app.ConnectPSUButton.Value = 0;
                ConnectPSUButtonValueChanged(app, []);
            end
           
            
            % Disable ELoad Output Terminals and Disconnect COMM
            if (~isempty(app.eload) && isvalid(app.eload)) % if the argument passed is not empty and is a valid handle
                app.OFFButton_ELoad.Value = true;
                response = Disconnect(app.eload) + newline;
                app.updateTextArea(app.ResponseTextArea_ELoad, response);
                app.ConnectELoadButton.Value = 0;
                ConnectELoadButtonValueChanged(app, []);
            end
            
            % Disconnect Temp Mod
            if (~isempty(app.tempMod) && isvalid(app.tempMod)) % if the argument passed is not empty and is a valid handle
                app.ConnectTempModButton.Value = 0;
                ConnectTempModButtonValueChanged(app, [])
            end;
            
            % Discconect Sys MCU
            if (~isempty(app.sysMCU) && isvalid(app.sysMCU)) % if the argument passed is not empty and is a valid handle
                app.RelayStateButtonGroup.SelectedObject = app.DeactivateRelayButton;
                RelayStateButtonGroupSelectionChanged(app, []);
                app.ConnectSysMCUButton.Value = 0;
                ConnectSysMCUButtonValueChanged(app, []);  
            end          
            
            % Disable EStop Last( Disconnect COMM)
            if (~isempty(app.eStop) && isvalid(app.eStop)) % if the argument passed is not empty and is a valid handle
                app.EmergencyButton_EStop.Value = 0;
                EmergencyButton_EStopValueChanged(app, []);
                app.ConnectEStopButton.Value = 0;
                ConnectEStopButtonValueChanged(app, []); 
            end 
            
            if app.ConnectALLDevicesButton.Value == 1
                app.ConnectALLDevicesButton.Value = 0;
            end
        end
        
        
        function toggleEStop(app, value)
            writeline(app.eStop, string(double(value)));
            response = "";
            
            try
                % Wait for data to be received
                while(app.eStop.NumBytesAvailable == 0)
                end
                
                while (app.eStop.NumBytesAvailable ~= 0)
                    response = response + readline(app.eStop);
                end
                
                app.updateTextArea(app.ResponseTextArea_EStop, response + newline);
            catch MEX
                app.updateTextArea(app.ResponseTextArea_EStop, "No Response.");
                rethrow(MEX);
            end
        end
        
        function settings = getTestSettings(app)
            settings = struct;
            
            settings.readPeriod     = app.SampleTimeSpinner.Value;
            settings.cellConfig     = app.cellConfigDropDown.Value;
            settings.dataLocation   = app.battDataLoc;
            settings.saveDir        = app.saveDir;
            settings.saveName       = app.saveName;
            settings.purpose        = app.PurposeofTestTextArea.Value;
            settings.tempChnls      = str2double(app.ConnectedChannelsListBox.Value);
            settings.relayPins      = [app.Relay1Spinner_SysMCU.Value, app.Relay2Spinner_SysMCU.Value];
            settings.trigPins       = app.trigPins;
            settings.trigInvert     = app.trigInvertOut;
            settings.trigStartTimes = app.trigStartTimes;
            settings.trigDurations  = app.trigDurations;
            settings.data2Record    = app.data2RecordArg;
            settings.voltMeasDev    = app.VoltageDropDown_BattPack.Value;
            settings.currMeasDev    = app.CurrentDropDown_BattPack.Value;
            settings.tempMeasDev    = app.TempDropDown_BattPack.Value;
            
            % Backup data related to saving data only when a test has used
            % it. i.e. Been called by the main app
            app.saveArg.readPeriod = app.SampleTimeSpinner.Value;
            app.saveArg.saveDir = app.saveDir;
            app.saveArg.saveName = app.saveName;
            app.saveArg.purpose = settings.purpose;
            saveArgs = app.saveArg;
            save(app.appDataLoc + app.backupFileName, 'saveArgs', '-append')
            
            app.saveArg.data2Record = app.data2RecordArg;
            data2RecordArgs = app.data2RecordArg;
            save(app.appDataLoc + app.backupFileName, 'data2RecordArgs', '-append')
        end
        
        function stackInfo = getStackInfo(app)
            app.stackArg = app.UITable.Data;
            stackInfo = app.stackArg;
        end
        
        function updateBattPackParams(app, config, params)
            if config == "single"
%                 prevHeight = height(app.batteryParam);
                rowNames_old = string(app.batteryParam.Row);
                rowNames_In = string(params.Row);
                
                % check to see if there are any old cells being updated
                addNew_Ind = []; updateOld_Ind = [];
                for i = 1:length(rowNames_In)
                    if ~ismember(rowNames_In(i), rowNames_old)
                        addNew_Ind(i) = i;
                    else
                        updateOld_Ind(i) = i;
                    end
                end
                
                if ~isempty(addNew_Ind)
                    app.batteryParam = [app.batteryParam;...
                                         params(rowNames_In(addNew_Ind), :)];
                end
                
                if ~isempty(updateOld_Ind)
                    app.batteryParam(rowNames_In(updateOld_Ind), :) ...
                        = params(rowNames_In(updateOld_Ind), :);
                end
                
                
                batteryParam = app.batteryParam; %#ok<*ADPROPLC>
                save(app.battDataLoc + "007BatteryParam", 'batteryParam');
                
            else
%                 prevHeight = height(app.packParam);
                rowNames_old = string(app.packParam.Row);
                rowNames_In = string(params.Row);
                
                % check to see if there are any old cells being updated
                addNew_Ind = []; updateOld_Ind = [];
                for i = 1:length(rowNames_In)
                    if ~ismember(rowNames_In(i), rowNames_old)
                        addNew_Ind(i) = i;
                    else
                        updateOld_Ind(i) = i;
                    end
                end
                
                if ~isempty(addNew_Ind)
                    app.packParam = [app.packParam;...
                                         params(rowNames_In(addNew_Ind), :)];
                end
                
                if ~isempty(updateOld_Ind)
                    app.packParam(rowNames_In(updateOld_Ind), :) ...
                        = params(rowNames_In(updateOld_Ind), :);
                end

                packParam = app.packParam; %#ok<*ADPROPLC> 
                save(app.battDataLoc + "007PackParam", "packParam")
            end
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            %% Gets the full path for the current file and changes directory
            currFilePath = mfilename('fullpath');
            % Seperates the path directory and the filename
            [path, ~, ~] = fileparts(currFilePath);
            
            app.appDataLoc = path + "\GUI_Data\";
            
            newStr = extractBetween(app.appDataLoc,"",...
                           "03DataGen","Boundaries","inclusive");
            app.battDataLoc = newStr + "\01CommonDataForBattery\";
            
            % Update all serial port dropdowns to serial ports current
            % available
            load(app.battDataLoc + "007BatteryParam", 'batteryParam');
            load(app.battDataLoc + "007PackParam", "packParam")
            app.batteryParam = batteryParam;  
            app.packParam = packParam;  
            app.updateSerialPortDropdowns();
            
            % Load Previous Session settings
            if isfile(app.appDataLoc + app.backupFileName + ".mat")
                load(app.appDataLoc + app.backupFileName);
                
                if exist('eStopArgs', 'var')
                    app.eStopArg = eStopArgs;
                end
                if exist('psuArgs', 'var')
                    app.psuArg = psuArgs;
                    if isfield(app.psuArg, 'devName')
                        app.psuName = app.psuArg.devName;
                    end
                end
                if exist('eloadArgs', 'var')
                    app.eloadArg = eloadArgs;
                    if isfield(app.eloadArg, 'devName')
                        app.eloadName = app.eloadArg.devName;
                    end
                end
                if exist('tempModArgs', 'var')
                    app.tempModArg = tempModArgs;
                    if isfield(app.tempModArg, 'devName')
                        app.tempModName = app.tempModArg.devName;
                    end
                end
                if exist('balArgs', 'var')
                    app.balArg = balArgs;
                end
                if exist('sysMCUArgs', 'var')
                    app.sysMCUArg = sysMCUArgs;
                    if isfield(app.sysMCUArg, 'devName')
                        app.sysMCUName = app.sysMCUArg.devName;
                    end
                end
                if exist('saveArgs', 'var')
                    app.saveArg = saveArgs;
                    app.SampleTimeSpinner.Value = app.saveArg.readPeriod;
                    app.SaveDirectoryEditField.Value = string(app.saveArg.saveDir);
                    app.SaveFileNameEditField.Value = string(app.saveArg.saveName);
                    app.saveDir = string(app.saveArg.saveDir);
                    app.saveName = string(app.saveArg.saveName);
                    app.PurposeofTestTextArea.Value = string(app.saveArg.purpose);
                end
                if exist('data2RecordArgs', 'var')
                    app.data2RecordArg = data2RecordArgs;
                    app.SelectDatatoRecordListBox.Value = app.data2RecordArg;
                    SelectDatatoRecordListBoxValueChanged(app, []);
                end
            end
            
            % Update Cell Information in Cell Configuration
            app.updateCellNodes();
            
            % Set the previous and Next Tabs based on the default selected
            % tab
            app.prevTab = app.SystemMCUTab;
            app.nextTab = app.EStopMCUTab;
            
            if nargin == 2
                main_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(main_App) && isvalid(main_App))
                    app.mainApp = main_App;
                end
            end
        end

        % Close request function: devConfigsUIFigure
        function devConfigsUIFigureCloseRequest(app, event)
            % if the argument passed is not empty and is a valid handle
            if ~isempty(app.mainApp)
                if isvalid(app.mainApp)
                    app.devConfigsUIFigure.Visible = false;
                end
            else
                app.disconnectAllDevices();
%                 app.saveAllArgs();
                delete(app)
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_EStop
        function ConnectionmethodDropDown_EStopValueChanged(app, event)
            value = app.ConnectionmethodDropDown_EStop.Value;
            switch value
                case ""
                    % Disable all components if "Don't Connect" is selected
                    components = app.SerialPortGrid_EStop.Children;
                    for i = 1:length(components)
                        components(i).Enable = false;
                    end
                    app.enable_EStops(false);
                    app.ConnectEStopButton.Enable = false;
                    
%                     % Reenable the components to choose a connection method
%                     components = app.ConnectionMethodPanel_EStop;
%                     for i = 1:length(components)
%                         components(i).Enable = true;
%                     end
                    return;
                case "serial"
                    % update the drop down with a list of available serial
                    % com ports
                    app.updateSerialPortDropdown(app.COMPortDropDown_EStop);
                    
                    components = app.SerialPortGrid_EStop.Children;
                    for i = 1:length(components)
                        components(i).Enable = true;
                    end
                    app.ConnectEStopButton.Enable = true;
                    app.EmergencyButton_EStop.Enable = false;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.eStopArg)
                        if ismember(app.eStopArg.COMPort,...
                                app.COMPortDropDown_EStop.Items)
                            app.COMPortDropDown_EStop.Value = upper(app.eStopArg.COMPort);
                        end
                        app.BaudRateDropDown_EStop.Value = string(app.eStopArg.baudRate);
                        app.StopBitsDropDown_EStop.Value = string(app.eStopArg.stopBits);
                        app.ByteOrderDropDown_EStop.Value = app.eStopArg.byteOrder;
                        app.TerminatorDropDown_EStop.Value = app.eStopArg.terminator;
                        app.TimeoutEditField_EStop.Value = string(app.eStopArg.timeout);
                    end
                    
                otherwise
                    return;
            end

        end

        % Value changed function: EmergencyButton_EStop
        function EmergencyButton_EStopValueChanged(app, event)
            value = app.EmergencyButton_EStop.Value;
            
            app.toggleEStop(value);
            
            if ~isempty(app.mainApp)
                app.mainApp.EmergencyButton.Value = value;
                
                if value == 1
                    app.mainApp.updateChargeStatus(app.mainApp.chargeStatus.eStop);
                else
                    app.mainApp.updateChargeStatus(app.mainApp.chargeStatus.idle);
                end
            end

            
        end

        % Value changed function: ConnectEStopButton
        function ConnectEStopButtonValueChanged(app, event)
            value = app.ConnectEStopButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_EStop.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_EStop.Value);
                        baudRate = str2double(app.BaudRateDropDown_EStop.Value);
                        byteOrder = app.ByteOrderDropDown_EStop.Value;
                        stopBits = str2double(app.StopBitsDropDown_EStop.Value);
                        terminator = app.TerminatorDropDown_EStop.Value;
                        timeout = str2double(app.TimeoutEditField_EStop.Value);
                        
                        app.eStopArg.type = app.ConnectionmethodDropDown_EStop.Value;
                        app.eStopArg.COMPort = COMPort;
                        app.eStopArg.baudRate = baudRate;
                        app.eStopArg.byteOrder = byteOrder;
                        app.eStopArg.stopBits = stopBits;
                        app.eStopArg.terminator = terminator;
                        app.eStopArg.timeout = timeout;
                        
                        eStopArgs = app.eStopArg;
                        save(app.appDataLoc + app.backupFileName, 'eStopArgs', '-append')
                        
                        app.eStop = serialport(COMPort, baudRate);
                        app.eStop.StopBits = stopBits;
                        app.eStop.ByteOrder = byteOrder;
                        configureTerminator(app.eStop,terminator)
                        app.eStop.Timeout = timeout;
                        app.COMPortDropDown_EStop.Enable = false;

                end
                
                app.enable_EStops(true);
                
                app.updateTextArea(app.ResponseTextArea_EStop, 'E-Stop Connected!');
                
            else
                switch app.ConnectionmethodDropDown_EStop.Value
                    case "serial"
                        app.eStop = [];
                        app.COMPortDropDown_EStop.Enable = true;
                end
                app.enable_EStops(false);
                app.updateTextArea(app.ResponseTextArea_EStop, 'E-Stop Disconnected!');
            end
        end

        % Value changed function: PSUDropDown
        function PSUDropDownValueChanged(app, event)
            value = app.PSUDropDown.Value;
            if value == ""
%                 app.psuName = value;
                app.ConnectPSUButton.Enable = false;
                
            else
                app.psuName = value;
                app.ConnectPSUButton.Enable = true;
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_PSU
        function ConnectionmethodDropDown_PSUValueChanged(app, event)
            value = app.ConnectionmethodDropDown_PSU.Value;
            switch value
                case ""
                    % Disable [SerialPortGrid_PSU]'s components if "Don't Connect" is selected
                    components = app.SerialPortGrid_PSU.Children;
                    for i = 1:length(components)
                        if components(i) == app.TerminalOutputsButtonGroup_PSU
                            buttons = app.TerminalOutputsButtonGroup_PSU.Children;
                            for j = 1:length(buttons)
                                buttons(j).Enable = false;
                            end
                        else
                            components(i).Enable = false;
                        end
                    end
                    app.ConnectPSUButton.Enable = false;
                    return;
                    
                case "serial"
                    app.updateSerialPortDropdown(app.COMPortDropDown_PSU);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    components = app.SerialPortGrid_PSU.Children;
                    for i = 1:length(components)
                        if (components(i) ~= app.TerminalOutputsButtonGroup_PSU) ...
                                && (components(i) ~= app.GetDeviceDetailsButton_PSU)...
                                && (components(i) ~= app.ClearAlarmCodesButton_PSU)...
                                && (components(i) ~= app.SetControlOwnerListBox_PSU)
                            components(i).Enable = true;
                        end
                    end
                    app.ConnectPSUButton.Enable = true;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.psuArg)
                        if ismember(app.psuArg.COMPort,...
                                app.COMPortDropDown_PSU.Items)
                            app.COMPortDropDown_PSU.Value = upper(app.psuArg.COMPort);
                        end
                        app.BaudRateDropDown_PSU.Value = string(app.psuArg.baudRate);
                        app.StopBitsDropDown_PSU.Value = string(app.psuArg.stopBits);
                        app.ByteOrderDropDown_PSU.Value = app.psuArg.byteOrder;
                        app.TerminatorDropDown_PSU.Value = app.psuArg.terminator;
                        app.TimeoutEditField_PSU.Value = string(app.psuArg.timeout);
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectPSUButton
        function ConnectPSUButtonValueChanged(app, event)
            value = app.ConnectPSUButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_PSU.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_PSU.Value);
                        baudRate = str2double(app.BaudRateDropDown_PSU.Value);
                        byteOrder = app.ByteOrderDropDown_PSU.Value;
                        stopBits = str2double(app.StopBitsDropDown_PSU.Value);
                        terminator = app.TerminatorDropDown_PSU.Value;
                        timeout = str2double(app.TimeoutEditField_PSU.Value);
                        
                        if COMPort == "Select COM Port"
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a COM Port!", "Connection Error")
                            app.ConnectPSUButton.Value = false;
                            return;
                        end
                        
                        if app.PSUDropDown.Value == ""
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                            app.ConnectPSUButton.Value = false;
                            return;
                        end
                        
                        
                        app.psuArg.type = app.ConnectionmethodDropDown_PSU.Value;
                        app.psuArg.devName = app.PSUDropDown.Value;
                        app.psuArg.COMPort = COMPort;
                        app.psuArg.baudRate = baudRate;
                        app.psuArg.byteOrder = byteOrder;
                        app.psuArg.stopBits = stopBits;
                        app.psuArg.terminator = terminator;
                        app.psuArg.timeout = timeout;
                        
                        psuArgs = app.psuArg;
                        save(app.appDataLoc + app.backupFileName, 'psuArgs', '-append')
                        
                        if app.SelfTestCheckBox_PSU.Value == 1
                            switch upper(app.psuName)
                                case "APM"
                                    app.psu = APM_PSU(COMPort, "baudRate", baudRate);
                                    app.psu.stopBits = stopBits;
                                    app.psu.byteOrder = byteOrder;
                                    app.psu.terminator = terminator;
                                    app.psu.timeout = timeout;
                                    app.updateTextArea(app.ResponseTextArea_PSU, "PSU Connected!");
                                otherwise
                                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                                    return;
                            end
                            
                            app.ClearAlarmCodesButton_PSU.Enable = true;
                            app.ONButton_PSU.Enable = true;
                            app.OFFButton_PSU.Enable = true;
                            app.GetDeviceDetailsButton_PSU.Enable = true;
                            app.SetControlOwnerListBox_PSU.Enable = true;
                        end
                end
                
                app.COMPortDropDown_PSU.Enable = false;
                
                
            else
                switch app.ConnectionmethodDropDown_PSU.Value
                    case "serial"
                        if app.SelfTestCheckBox_PSU.Value == true
                            response = disconnect(app.psu);
                            app.psu = [];
                            app.updateTextArea(app.ResponseTextArea_PSU, ...
                                string(response + newline + newline + "PSU Disconnected!"));
                        end
                        
                        app.COMPortDropDown_PSU.Enable = true;
                end
                app.OFFButton_PSU.Value = true;
                app.ClearAlarmCodesButton_PSU.Enable = false;
                app.ONButton_PSU.Enable = false;
                app.OFFButton_PSU.Enable = false;
                app.GetDeviceDetailsButton_PSU.Enable = false;
                app.SetControlOwnerListBox_PSU.Enable = false;


            end
        end

        % Value changed function: SetControlOwnerListBox_PSU
        function SetControlOwnerListBox_PSUValueChanged(app, event)
            value = app.SetControlOwnerListBox_PSU.Value;
            switch upper(app.psuName)
                case "APM"
                    response = setSystCtrl(app.psu, value);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            % Update Response Text Area
            app.updateTextArea(app.ResponseTextArea_PSU, response);

            
        end

        % Selection changed function: TerminalOutputsButtonGroup_PSU
        function TerminalOutputsButtonGroup_PSUSelectionChanged(app, event)
            selectedButton = app.TerminalOutputsButtonGroup_PSU.SelectedObject;
            switch upper(app.psuName)
                case "APM"
                    if selectedButton == app.ONButton_PSU
                        response = connect(app.psu);
                    else
                        response = disconnect(app.psu);
                    end
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            
            updateTextArea(app, app.ResponseTextArea_PSU, response);
        end

        % Button pushed function: GetDeviceDetailsButton_PSU
        function GetDeviceDetailsButton_PSUPushed(app, event)
            
            switch upper(app.psuName)
                case "APM"
                    response = WhoAmI(app.psu);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_PSU, "Invalid PSU Device.");
                    return;
            end
            
            updateTextArea(app, app.ResponseTextArea_PSU, response);
        end

        % Value changed function: COMPortDropDown_PSU
        function COMPortDropDown_PSUValueChanged(app, event)
%             value = app.COMPortDropDown_PSU.Value;
            app.updateSerialPortDropdown(app.COMPortDropDown_PSU);
        end

        % Button pushed function: ClearAlarmCodesButton_PSU
        function ClearAlarmCodesButton_PSUPushed(app, event)
            reply = ClearAlarmCode(app.psu);
            if reply == "Alarm Cleared"
                app.updateTextArea(app.ResponseTextArea_PSU, "Errors/Alarms Cleared.");
            else
                app.updateTextArea(app.ResponseTextArea_PSU, "Errors/Alarms NOT Cleared - " + reply);
            end
        end

        % Menu selected function: ClearMenu_PSU
        function ClearMenu_PSUSelected(app, event)
            app.ResponseTextArea_PSU.Value = "";
        end

        % Value changed function: ELoadDropDown
        function ELoadDropDownValueChanged(app, event)
            value = app.ELoadDropDown.Value;
            if value == ""
%                 app.eloadName = value;
                app.ConnectELoadButton.Enable = false;
                
            else
                app.eloadName = value;
                app.ConnectELoadButton.Enable = true;
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_ELoad
        function ConnectionmethodDropDown_ELoadValueChanged(app, event)
            value = app.ConnectionmethodDropDown_ELoad.Value;
            
            switch value
                case ""
                    % Disable [SerialPortGrid_ELoad]'s components if "Don't Connect" is selected
                    components = app.SerialPortGrid_ELoad.Children;
                    for i = 1:length(components)
                        if components(i) == app.TerminalOutputsButtonGroup_ELoad
                            buttons = app.TerminalOutputsButtonGroup_ELoad.Children;
                            for j = 1:length(buttons)
                                buttons(j).Enable = false;
                            end
                        else
                            components(i).Enable = false;
                        end
                    end
                    app.ConnectELoadButton.Enable = false;
                    return;
                    
                case "serial"
                    app.updateSerialPortDropdown(app.COMPortDropDown_ELoad);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    components = app.SerialPortGrid_ELoad.Children;
                    for i = 1:length(components)
                        if (components(i) ~= app.TerminalOutputsButtonGroup_ELoad) ...
                                && (components(i) ~= app.GetDeviceDetailsButton_ELoad)...
                                && (components(i) ~= app.ClearAlarmCodesButton_ELoad)...
                                && (components(i) ~= app.SetControlOwnerListBox_ELoad)
                            components(i).Enable = true;
                        end
                    end
                    app.ConnectELoadButton.Enable = true;
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.eloadArg)
                        if ismember(app.eloadArg.COMPort,...
                                app.COMPortDropDown_ELoad.Items)
                            app.COMPortDropDown_ELoad.Value = upper(app.eloadArg.COMPort);
                        end
                        app.BaudRateDropDown_ELoad.Value = string(app.eloadArg.baudRate);
                        app.StopBitsDropDown_ELoad.Value = string(app.eloadArg.stopBits);
                        app.ByteOrderDropDown_ELoad.Value = app.eloadArg.byteOrder;
                        app.TerminatorDropDown_ELoad.Value = app.eloadArg.terminator;
                        app.TimeoutEditField_ELoad.Value = string(app.eloadArg.timeout);
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectELoadButton
        function ConnectELoadButtonValueChanged(app, event)
            value = app.ConnectELoadButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_ELoad.Value
                    case "serial"
                        COMPort = string(app.COMPortDropDown_ELoad.Value);
                        baudRate = str2double(app.BaudRateDropDown_ELoad.Value);
                        byteOrder = app.ByteOrderDropDown_ELoad.Value;
                        stopBits = str2double(app.StopBitsDropDown_ELoad.Value);
                        terminator = app.TerminatorDropDown_ELoad.Value;
                        timeout = str2double(app.TimeoutEditField_ELoad.Value);
                        
                        if COMPort == "Select COM Port"
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a COM Port!", "Connection Error");
                            app.ConnectELoadButton.Value = false;
                            return;
                        end
                        
                        if app.ELoadDropDown.Value == ""
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                            app.ConnectELoadButton.Value = false;
                            return;
                        end
                        
                        app.eloadArg.type = app.ConnectionmethodDropDown_ELoad.Value;
                        app.eloadArg.devName = app.ELoadDropDown.Value;
                        app.eloadArg.COMPort = COMPort;
                        app.eloadArg.baudRate = baudRate;
                        app.eloadArg.byteOrder = byteOrder;
                        app.eloadArg.stopBits = stopBits;
                        app.eloadArg.terminator = terminator;
                        app.eloadArg.timeout = timeout;
                        
                        eloadArgs = app.eloadArg;
                        save(app.appDataLoc + app.backupFileName, 'eloadArgs', '-append')
                        
                        if app.SelfTestCheckBox_ELoad.Value == 1
                            switch upper(app.eloadName)
                                case "ARRAY"
                                    app.eload = Array_ELoad(COMPort, "baudRate", baudRate);
                                    app.eload.stopBits = stopBits;
                                    app.eload.byteOrder = byteOrder;
                                    app.eload.terminator = terminator;
                                    app.eload.timeout = timeout;
                                    app.updateTextArea(app.ResponseTextArea_ELoad, "E-Load Connected!");
                                otherwise
                                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                                    return;
                            end
                            
                            app.ClearAlarmCodesButton_ELoad.Enable = true;
                            app.ONButton_ELoad.Enable = true;
                            app.OFFButton_ELoad.Enable = true;
                            app.GetDeviceDetailsButton_ELoad.Enable = true;
                            app.SetControlOwnerListBox_ELoad.Enable = true;
                        end
                end
                
                app.COMPortDropDown_ELoad.Enable = false;
                
                
            else
                switch app.ConnectionmethodDropDown_ELoad.Value
                    case "serial"
                        if app.SelfTestCheckBox_ELoad.Value == true
                            response = Disconnect(app.eload);
                            app.eload = [];
                            app.updateTextArea(app.ResponseTextArea_ELoad, ...
                                string(response + newline + newline + "E-Load Disconnected!"));
                        end
                        
                        app.COMPortDropDown_ELoad.Enable = true;
                end
                app.OFFButton_ELoad.Value = true;
                app.ClearAlarmCodesButton_ELoad.Enable = false;
                app.ONButton_ELoad.Enable = false;
                app.OFFButton_ELoad.Enable = false;
                app.GetDeviceDetailsButton_ELoad.Enable = false;
                app.SetControlOwnerListBox_ELoad.Enable = false;
                
            end
        end

        % Value changed function: SetControlOwnerListBox_ELoad
        function SetControlOwnerListBox_ELoadValueChanged(app, event)
            value = app.SetControlOwnerListBox_ELoad.Value;
            
            switch upper(app.eloadName)
                case "ARRAY"
                    response = SetSystCtrl(app.eload, value);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            % Update Response Text Area
            app.updateTextArea(app.ResponseTextArea_ELoad, response);
        end

        % Selection changed function: 
        % TerminalOutputsButtonGroup_ELoad
        function TerminalOutputsButtonGroup_ELoadSelectionChanged(app, event)
            selectedButton = app.TerminalOutputsButtonGroup_ELoad.SelectedObject;
            
            switch upper(app.eloadName)
                case "ARRAY"
                    if selectedButton == app.ONButton_ELoad
                        response = Connect(app.eload);
                    else
                        response = Disconnect(app.eload);
                    end
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            
            updateTextArea(app, app.ResponseTextArea_ELoad, response);
        end

        % Button pushed function: GetDeviceDetailsButton_ELoad
        function GetDeviceDetailsButton_ELoadPushed(app, event)
            switch upper(app.eloadName)
                case "ARRAY"
                    response = WhoAmI(app.eload);
                otherwise
                    app.updateTextArea(app.ResponseTextArea_ELoad, "Invalid E-Load Device.");
                    return;
            end
            
            updateTextArea(app, app.ResponseTextArea_ELoad, response);
        end

        % Value changed function: COMPortDropDown_ELoad
        function COMPortDropDown_ELoadValueChanged(app, event)
%             value = app.COMPortDropDown_ELoad.Value;
            app.updateSerialPortDropdown(app.COMPortDropDown_ELoad);

        end

        % Button pushed function: ClearAlarmCodesButton_ELoad
        function ClearAlarmCodesButton_ELoadPushed(app, event)
            reply = ClearAlarmCode(app.eload);
            if reply == "Alarm Cleared"
                app.updateTextArea(app.ResponseTextArea_ELoad, "Errors/Alarms Cleared.");
            else
                app.updateTextArea(app.ResponseTextArea_ELoad, "Errors/Alarms NOT Cleared - " + reply);
            end
        end

        % Menu selected function: ClearMenu_ELoad
        function ClearMenu_ELoadSelected(app, event)
            app.ResponseTextArea_EStop.Value = "";
        end

        % Value changed function: ConnectALLDevicesButton
        function ConnectALLDevicesButtonValueChanged(app, event)
            value = app.ConnectALLDevicesButton.Value;
            if value == 1
                % Connect EStop
                if app.ConnectionmethodDropDown_EStop.Value ~= ""
                    app.ConnectEStopButton.Value = 1;
                    ConnectEStopButtonValueChanged(app, []);
                end
                
                % Connect Power Supply
                if app.ConnectionmethodDropDown_PSU.Value ~= ""
                    app.SelfTestCheckBox_PSU.Value = 0;
                    app.ConnectPSUButton.Value = 1;
                    ConnectPSUButtonValueChanged(app, [])
                end
                
                % Connect Electronic Load
                if app.ConnectionmethodDropDown_ELoad.Value ~= ""
                    app.SelfTestCheckBox_ELoad.Value = 0;
                    app.ConnectELoadButton.Value = 1;
                    ConnectELoadButtonValueChanged(app, [])
                end
                
                % Connect Temp Module
                if app.ConnectionmethodDropDown_TempMod.Value ~= ""
                    app.SelfTestCheckBox_TempMod.Value = 0;
                    app.ConnectTempModButton.Value = 1;
                    ConnectTempModButtonValueChanged(app, [])
                end
                
                % Connect SysMCU 
                if app.SysMCUDropDown.Value ~= ""
                    app.SelfTestCheckBox_SysMCU.Value = 0;
                    app.ConnectSysMCUButton.Value = 1;
                    ConnectSysMCUButtonValueChanged(app, [])
                end
                
            else
                app.disconnectAllDevices();
            end
        end

        % Callback function
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            if value == 1
                % Select all check box components except for
                % [SelectAllCheckBox]
                parent = app.SelectAllCheckBox.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if (components(i) ~= app.SelectAllCheckBox)
                        components(i).Value = true;
                    end
                end
            else
                % Deselect all check box components except for
                % [SelectAllCheckBox]
                parent = app.SelectAllCheckBox.Parent;
                components = parent.Children;
                for i = 1:length(components)
                    if (components(i) ~= app.SelectAllCheckBox)
                        components(i).Value = false;
                    end
                end
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.BatteryPackTab
                    app.prevTab = app.SystemMCUTab;
                    app.nextTab = app.EStopMCUTab;
                case app.EStopMCUTab
                    app.prevTab = app.BatteryPackTab;
                    app.nextTab = app.PowerSupplyTab;
                    ConnectionmethodDropDown_EStopValueChanged(app, []);
                case app.PowerSupplyTab
                    app.prevTab = app.EStopMCUTab;
                    app.nextTab = app.ElectronicLoadTab;
                    app.PSUDropDown.Value = app.psuName;
                    if isfield(app.psuArg, 'type')
                        app.ConnectionmethodDropDown_PSU.Value = app.psuArg.type;
                        ConnectionmethodDropDown_PSUValueChanged(app, []);
                    end
                case app.ElectronicLoadTab
                    app.prevTab = app.PowerSupplyTab;
                    app.nextTab = app.TemperatureModuleTab;
                    app.ELoadDropDown.Value = app.eloadName;
                    if isfield(app.eloadArg, 'type')
                        app.ConnectionmethodDropDown_ELoad.Value = app.eloadArg.type;
                        ConnectionmethodDropDown_ELoadValueChanged(app, []);
                    end
                case app.TemperatureModuleTab
                    app.prevTab = app.ElectronicLoadTab;
                    app.nextTab = app.BalancerTab;
                    app.TempModDropDown.Value = app.tempModName;
                    if isfield(app.tempModArg, 'type')
                        app.ConnectionmethodDropDown_TempMod.Value = app.tempModArg.type;
                        ConnectionmethodDropDown_TempModValueChanged(app, []);
                    end
                    scroll(app.ConnectedChannelsListBox, 'bottom');
                case app.BalancerTab
                    app.prevTab = app.BalancerTab;
                    app.nextTab = app.SystemMCUTab;
                case app.SystemMCUTab
                    app.prevTab = app.BalancerTab;
                    app.nextTab = app.BatteryPackTab;
                    app.SysMCUDropDown.Value = app.sysMCUName;
                    SysMCUDropDownValueChanged(app, []);
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.sysMCUArg)
                        if isfield(app.sysMCUArg, "relayPins")
                            app.Relay1Spinner_SysMCU.Value = app.sysMCUArg.relayPins(1);
                            app.Relay2Spinner_SysMCU.Value = app.sysMCUArg.relayPins(2);
                        end
                        if isfield(app.sysMCUArg, "voltMeasPins")
                            app.VoltagePlusSpinner_SysMCU.Value = app.sysMCUArg.voltMeasPins(1);
                            app.VoltageMinusSpinner_SysMCU.Value = app.sysMCUArg.voltMeasPins(2);
                        end
                        if isfield(app.sysMCUArg, "currMeasPins")
                            app.CurrentPlusSpinner_SysMCU.Value = app.sysMCUArg.currMeasPins(1);
                            app.CurrentMinusSpinner_SysMCU.Value = app.sysMCUArg.currMeasPins(2);
                        end
                        if isfield(app.sysMCUArg, "trigInvert")
                            app.Trigger1InvertCheckbox_SysMCU.Value = app.sysMCUArg.trigInvert(1);
                            app.Trigger2InvertCheckbox_SysMCU.Value = app.sysMCUArg.trigInvert(2);
                            app.Trigger3InvertCheckbox_SysMCU.Value = app.sysMCUArg.trigInvert(3);
                            app.Trigger4InvertCheckbox_SysMCU.Value = app.sysMCUArg.trigInvert(4);
                            app.Trigger5InvertCheckbox_SysMCU.Value = app.sysMCUArg.trigInvert(5);
                            Trigger1InvertCheckbox_SysMCUValueChanged(app, []);
                            Trigger2InvertCheckbox_SysMCUValueChanged(app, []);
                            Trigger3InvertCheckbox_SysMCUValueChanged(app, []);
                            Trigger4InvertCheckbox_SysMCUValueChanged(app, []);
                            Trigger5InvertCheckbox_SysMCUValueChanged(app, []);
                        end
                    end
            end
        end

        % Button pushed function: PrevDeviceButton
        function PrevDeviceButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.prevTab;
            TabGroupSelectionChanged(app, []);
        end

        % Button pushed function: NextDeviceButton
        function NextDeviceButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.nextTab;
            TabGroupSelectionChanged(app, []);
        end

        % Value changed function: cellConfigDropDown
        function cellConfigDropDownValueChanged(app, event)
            app.updateCellNodes();           
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if (~isempty(selectedNodes))
                tree = app.Tree.Children;
                treeNodeTexts = repmat("", 1, length(tree));
                for i = 1:length(tree)
                    treeNodeTexts(i) = string(tree(i).Text);
                end
                
                selectedNodesTexts = repmat("", 1, length(selectedNodes));
                rows2Rmv = [];
                for i = 1:length(selectedNodes)
                    selectedNodesTexts(i) = string(selectedNodes(i).Text);
                    if ismember(selectedNodesTexts(i), treeNodeTexts)
                        rows2Rmv(end+1) = i;
                    end
                end
                
                selectedNodesTexts(rows2Rmv) = []; % Remove the text of selections that are category names instead of cell names
                selectedNodesTexts = sort(selectedNodesTexts, "ascend");
%                 app.val = selectedNodesTexts;
                
                % if there are no proper selections
                if isempty(selectedNodesTexts)
                    return;
                end
                
                if app.cellConfigDropDown.Value == "single"
                    app.UITable.ColumnName = ...
                        ["Chem" ,"Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)", "Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.RowName = selectedNodesTexts;
                    dispTable = app.batteryParam(selectedNodesTexts, :);
                    app.UITable.Data = dispTable;
                    app.stackArg = app.UITable.Data; 
                else
                    app.UITable.ColumnName = ...
                        ["Chem" , "Cells", " Stack Shape","Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)","Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
%                     tWidth = repmat({'auto'}, 1, width(app.packParam));
%                     tWidth{2} = 100;
                    
                    app.UITable.RowName = selectedNodesTexts;
                    dispTable = app.packParam(selectedNodesTexts, :);
                    app.UITable.Data = dispTable; 
                    app.stackArg = app.UITable.Data; 
                end
                
                
            else
                if app.cellConfigDropDown.Value == "single"
                    app.UITable.ColumnName = ...
                        ["Chem" ,"Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)", "Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.Data = [];
                else
                    app.UITable.ColumnName = ...
                        ["Chem" , "Cells", " Stack Shape","Cap.(Ah)","SOC(%)","Full Chg. Volt.",...
                        "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                        "Max Neg. Curr(A)","Rated Cap.(Ah)","Max Surf Temp(°C)",...
                        "Max Core Temp(°C)"];
                    app.UITable.Data = [];
                end
                app.stackArg = [];
            end
        end

        % Menu selected function: CollapseAllMenu
        function CollapseAllMenuSelected(app, event)
            app.Tree.collapse();
        end

        % Menu selected function: ExpandAllMenu
        function ExpandAllMenuSelected(app, event)
            app.Tree.expand();
        end

        % Menu selected function: DeselectAllMenu
        function DeselectAllMenuSelected(app, event)
            app.Tree.SelectedNodes = [];
            TreeSelectionChanged(app, []);
        end

        % Value changed function: EditTableButton_BattPack
        function EditTableButton_BattPackValueChanged(app, event)
            value = app.EditTableButton_BattPack.Value;
            if value == 1
                app.UITable.ColumnEditable = true;
            else
                app.UITable.ColumnEditable = false;
            end

        end

        % Menu selected function: ExpandMenu_2
        function ExpandMenu_2Selected(app, event)
            selectedNode = app.Tree.SelectedNodes;
            selectedNode.expand();
        end

        % Menu selected function: CollapseMenu_2
        function CollapseMenu_2Selected(app, event)
            selectedNode = app.Tree.SelectedNodes;
            if ~isempty(selectedNode)
                selectedNode.collapse();
            end
        end

        % Callback function: DeleteCellPackMenu, DeleteCellsButton
        function DeleteCellsButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if (~isempty(selectedNodes))
                tree = app.Tree.Children;
                treeNodeTexts = repmat("", 1, length(tree));
                for i = 1:length(tree)
                    treeNodeTexts(i) = string(tree(i).Text);
                end
                
                selectedNodesTexts = repmat("", 1, length(selectedNodes));
                rows2Rmv = [];
                for i = 1:length(selectedNodes)
                    selectedNodesTexts(i) = string(selectedNodes(i).Text);
                    if ismember(selectedNodesTexts(i), treeNodeTexts)
                        rows2Rmv(end+1) = i;
                    end
                end
                
                selectedNodesTexts(rows2Rmv) = []; % Remove the text of selections that are category names instead of cell names
                selectedNodesTexts = sort(selectedNodesTexts, "ascend");
                %                 app.val = selectedNodesTexts;
                
                % if there are no proper selections
                if isempty(selectedNodesTexts)
                    return;
                end
                
                
                if app.cellConfigDropDown.Value == "single"
                    app.batteryParam(selectedNodesTexts, :) = [];
                    batteryParam = app.batteryParam; %#ok<*ADPROPLC>
                    save(app.battDataLoc + "007BatteryParam", 'batteryParam');
                else
                    app.packParam(selectedNodesTexts, :) = [];
                    packParam = app.packParam; %#ok<*ADPROPLC>
                    save(app.battDataLoc + "007packParam", 'packParam');
                end
                app.updateCellNodes();
            end
            
        end

        % Button pushed function: AddCellsButton
        function AddCellsButtonPushed(app, event)
            
            app.dialog_addCells = Dialog_AddCell(app, app.batteryParam, app.packParam);
            uiwait(app.dialog_addCells.AddCellsPackUIFigure);
            app.updateCellNodes();
        end

        % Button pushed function: DiscardChangesButton
        function DiscardChangesButtonPushed(app, event)
            app.DiscardChangesButton.Enable = "off";
            app.SaveChangesButton.Enable = "off";
            app.EditTableButton_BattPack.Value = false;
            TreeSelectionChanged(app, []);
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            config = app.cellConfigDropDown.Value;
            params = app.UITable.Data;
%             rowNames = app.UITable.RowName
            app.updateBattPackParams(config, params);
            app.DiscardChangesButton.Enable = "off";
            app.SaveChangesButton.Enable = "off";
            app.EditTableButton_BattPack.Value = false;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            app.DiscardChangesButton.Enable = "on";
            app.SaveChangesButton.Enable = "on";
        end

        % Value changed function: SelectDatatoRecordListBox
        function SelectDatatoRecordListBoxValueChanged(app, event)
            value = app.SelectDatatoRecordListBox.Value;
            %             disp(value)
            if max(ismember(value, "All"))
                app.SelectDatatoRecordListBox.Value = app.SelectDatatoRecordListBox.ItemsData;
                app.data2RecordArg = app.SelectDatatoRecordListBox.ItemsData;
            else
                app.data2RecordArg = value;
            end
            
        end

        % Value changed function: TempModDropDown
        function TempModDropDownValueChanged(app, event)
            value = app.TempModDropDown.Value;
            if value == ""
                app.ConnectTempModButton.Enable = false;
            else
                if app.ConnectionmethodDropDown_TempMod.Value ~= ""
                    app.tempModName = value;
                    app.ConnectTempModButton.Enable = true;
                end
            end
            
            if strcmpi(value, "TC16CH")
                app.ConnectedChannelsListBox.Visible = true;
                app.ConnectedChannelsListBoxLabel.Visible = true;
                app.ConnectionmethodDropDown_TempMod.Value = "modbus";
            else
                app.ConnectedChannelsListBox.Visible = false;
                app.ConnectedChannelsListBoxLabel.Visible = false;
                app.ConnectionmethodDropDown_TempMod.Value = "";
            end
            
            if strcmpi(value, "DC2100A")
                app.ConnectionmethodDropDown_TempMod.Enable = false;
                app.SelfTestCheckBox_TempMod.Enable = false;
                app.ResponseTextArea_TempMod.Enable = true;
                app.updateTextArea(app.ResponseTextArea_TempMod, "Go to Balancer Tab!");
            else
                app.ConnectionmethodDropDown_TempMod.Enable = true;
                app.SelfTestCheckBox_TempMod.Enable = true;
                app.ResponseTextArea_TempMod.Enable = false;
                app.ResponseTextArea_TempMod.Value = "";
            end
        end

        % Value changed function: ConnectionmethodDropDown_TempMod
        function ConnectionmethodDropDown_TempModValueChanged(app, event)
            value = app.ConnectionmethodDropDown_TempMod.Value;
            
            switch value
                case ""
                    % Disable [SerialPortGrid_TempMod]'s components if "Don't Connect" is selected
                    components = app.SerialPortGrid_TempMod.Children;
                    for i = 1:length(components)
                        components(i).Enable = false;
                    end
                    app.ConnectTempModButton.Enable = false;
                    return;
                    
                case {"serial", "modbus"}
                    app.updateSerialPortDropdown(app.COMPortDropDown_TempMod);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    components = app.SerialPortGrid_TempMod.Children;
                    for i = 1:length(components)
                        if (components(i) ~= app.GetTempDataButton_TempMod)
                            components(i).Enable = true;
                        end
                    end
                    if app.TempModDropDown.Value ~= ""
                        app.ConnectTempModButton.Enable = true;
                    end
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.tempModArg)
                        if ismember(app.tempModArg.COMPort,...
                                app.COMPortDropDown_TempMod.Items)
                            app.COMPortDropDown_TempMod.Value = upper(app.tempModArg.COMPort);
                        end
                        app.BaudRateDropDown_TempMod.Value = string(app.tempModArg.baudRate);
                        app.StopBitsDropDown_TempMod.Value = string(app.tempModArg.stopBits);
                        app.ByteOrderDropDown_TempMod.Value = app.tempModArg.byteOrder;
                        app.TerminatorDropDown_TempMod.Value = app.tempModArg.terminator;
                        app.TimeoutEditField_TempMod.Value = string(app.tempModArg.timeout);
                        app.ConnectedChannelsListBox.Value = cellstr(string(app.tempModArg.tempChnls));
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectTempModButton
        function ConnectTempModButtonValueChanged(app, event)
            value = app.ConnectTempModButton.Value;
            
            if value == 1
                switch app.ConnectionmethodDropDown_TempMod.Value
                    case "modbus"
                        COMPort = string(app.COMPortDropDown_TempMod.Value);
                        baudRate = str2double(app.BaudRateDropDown_TempMod.Value);
%                         byteOrder = app.ByteOrderDropDown_TempMod.Value;
%                         stopBits = str2double(app.StopBitsDropDown_TempMod.Value);
%                         terminator = app.TerminatorDropDown_TempMod.Value;
                        timeout = str2double(app.TimeoutEditField_TempMod.Value);
                        tempChnls = str2double(app.ConnectedChannelsListBox.Value);
                        
                        if COMPort == "Select COM Port"
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a COM Port!", "Connection Error")
                            app.ConnectTempModButton.Value = false;
                            return;
                        end
                        
                        if app.TempModDropDown.Value == ""
                            uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                            app.ConnectTempModButton.Value = false;
                            return;
                        end
                        
                        app.tempModArg.type = app.ConnectionmethodDropDown_TempMod.Value;
                        app.tempModArg.devName = app.TempModDropDown.Value;
                        app.tempModArg.COMPort = COMPort;
                        app.tempModArg.baudRate = baudRate;
                        app.tempModArg.timeout = timeout;
                        app.tempModArg.tempChnls = tempChnls;
                        
                        tempModArgs = app.tempModArg;
                        save(app.appDataLoc + app.backupFileName, 'tempModArgs', '-append')
                        
                        if app.SelfTestCheckBox_TempMod.Value == 1
                            switch upper(app.tempModName)
                                case "TC16CH"
                                    app.tempMod = modbus('serialrtu',COMPort,'Timeout',timeout); % Initializes a Modbus
                                    
                                    %protocol object using serial RTU interface connecting to COM6 and a Timeout of 10s.
                                    app.tempMod.BaudRate = baudRate;
%                                     if strcmpi(app.tempMod.Status, 'open')
                                    app.updateTextArea(app.ResponseTextArea_TempMod, "TempMod Connected!");
%                                     else
%                                         app.updateTextArea(app.ResponseTextArea_TempMod, "TempMod Could not connect!");
%                                         app.ConnectTempModButton.Value = false;
%                                     end
                                otherwise
                                    app.updateTextArea(app.ResponseTextArea_TempMod, "Invalid TempMod Device.");
                                    return;
                            end
                            app.GetTempDataButton_TempMod.Enable = true;
                        end
                end
                
                app.COMPortDropDown_TempMod.Enable = false;
                
            else
                switch app.ConnectionmethodDropDown_TempMod.Value
                    case "serial"
                        if app.SelfTestCheckBox_TempMod.Value == true
                            app.tempMod = [];
                        end
                        app.updateTextArea(app.ResponseTextArea_TempMod, "TempMod Disconnected!");
                        app.COMPortDropDown_TempMod.Enable = true;
                end
                app.GetTempDataButton_TempMod.Enable = false;
                
            end
        end

        % Button pushed function: GetTempDataButton_TempMod
        function GetTempDataButton_TempModPushed(app, event)
            channels = str2double(app.ConnectedChannelsListBox.Value);
            thermoData = read(app.tempMod,'holdingregs', channels(1), length(channels));
            for i = 1:length(channels)
                thermoData(i) = thermoData(i)/10;
                app.updateTextArea(app.ResponseTextArea_TempMod, "Temp @ ch " + num2str(channels(i) + " = " + num2str(thermoData(i)) + " °C"));
            end
            app.updateTextArea(app.ResponseTextArea_TempMod, newline);
        end

        % Menu selected function: ClearMenu_TempMod
        function ClearMenu_TempModSelected(app, event)
            app.ResponseTextArea_TempMod.Value = "";
        end

        % Value changed function: ResponseTextArea_TempMod
        function ResponseTextArea_TempModValueChanged(app, event)
%             value = app.ResponseTextArea_TempMod.Value;
            scroll(app.ResponseTextArea_TempMod, 'bottom');
        end

        % Value changed function: ResponseTextArea_ELoad
        function ResponseTextArea_ELoadValueChanged(app, event)
%             value = app.ResponseTextArea_ELoad.Value;
            scroll(app.ResponseTextArea_ELoad, 'bottom');
        end

        % Value changed function: ResponseTextArea_PSU
        function ResponseTextArea_PSUValueChanged(app, event)
%             value = app.ResponseTextArea_PSU.Value;
            scroll(app.ResponseTextArea_PSU, 'bottom');
        end

        % Value changed function: SysMCUDropDown
        function SysMCUDropDownValueChanged(app, event)
            value = app.SysMCUDropDown.Value;
            if value == ""
                app.ConnectSysMCUButton.Enable = false;
            elseif (strcmpi(value, "LJMCU"))
                app.sysMCUName = value;
                app.ConnectionmethodDropDown_SysMCU.Enable = false;
                app.ConnectSysMCUButton.Enable = true;
                components = app.ConnectionGrid_SysMCU.Children;
                for i=1:length(components)
                    if ~strcmpi(components(i).Type, 'uipanel')
%                         components(i) ~= app.SerialPanel_SysMCU || ...
%                             components(i) ~= app.HIDDevicePanel_SysMCU
                        components(i).Enable = true;
                    end
                end
            else
                app.ConnectionmethodDropDown_SysMCU.Enable = true;
                if app.ConnectionmethodDropDown_SysMCU.Value ~= ""
                    app.sysMCUName = value;
                    app.ConnectSysMCUButton.Enable = true;
                end
            end
            
        end

        % Value changed function: ConnectionmethodDropDown_SysMCU
        function ConnectionmethodDropDown_SysMCUValueChanged(app, event)
            value = app.ConnectionmethodDropDown_SysMCU.Value;
            
            switch value
                case ""
                    % Disable [ConnectionGrid_SysMCU]'s components if "Don't Connect" is selected
                    children = app.ConnectionGrid_SysMCU.Children;
                    for j = 1:length(children)
                        if children(j) == app.SerialPanel_SysMCU ||...
                                children(j) == app.HIDDevicePanel_SysMCU
                            components = children(j).Children; components = components.Children;
                            for i = 1:length(components)
                                components(i).Enable = false;
                            end
                        else
                            children(j).Enable = false;
                        end
                    end
                    app.ConnectSysMCUButton.Enable = false;
                    return;
                    
                case "serial"
                    app.updateSerialPortDropdown(app.COMPortDropDown_SysMCU);
                    
                    % Enable all components except those used to
                    % communicate with the device since connection hasn't
                    % occured
                    children = app.ConnectionGrid_SysMCU.Children;
                    for j = 1:length(children)
                        if children(j) == app.SerialPanel_SysMCU
                            components = children(j); components = components.Children;
                            for i = 1:length(components)
                                components(i).Enable = true;
                            end
                        end
                        app.ResponseTextArea_SysMCU.Enable = true;
                    end
                    
                    if app.SysMCUDropDown.Value ~= "" || ~strcmpi(app.SysMCUDropDown.Value, "LJMCU")
                        app.ConnectSysMCUButton.Enable = true;
                    end
                    
                    % Replace current entries with previously used entries
                    % to help reduce the number of user clicks (for
                    % conviennce only)
                    if isstruct(app.sysMCUArg)
                        if ismember(app.sysMCUArg.COMPort,...
                                app.COMPortDropDown_SysMCU.Items)
                            app.COMPortDropDown_SysMCU.Value = upper(app.sysMCUArg.COMPort);
                        end
                        app.BaudRateDropDown_SysMCU.Value = string(app.sysMCUArg.baudRate);
                        app.StopBitsDropDown_SysMCU.Value = string(app.sysMCUArg.stopBits);
                        app.ByteOrderDropDown_SysMCU.Value = app.sysMCUArg.byteOrder;
                        app.TerminatorDropDown_SysMCU.Value = app.sysMCUArg.terminator;
                        app.TimeoutEditField_SysMCU.Value = string(app.sysMCUArg.timeout);
                    end
                otherwise
                    return;
            end
        end

        % Value changed function: ConnectSysMCUButton
        function ConnectSysMCUButtonValueChanged(app, event)
            value = app.ConnectSysMCUButton.Value;
            
            if value == 1
                relayPins = [app.Relay1Spinner_SysMCU.Value, ...
                    app.Relay2Spinner_SysMCU.Value];
                if strcmpi(app.VoltageDropDown_BattPack.Value, "mcu")
                    voltMeasPins = [app.VoltagePlusSpinner_SysMCU.Value, ...
                        app.VoltageMinusSpinner_SysMCU.Value];
                    app.sysMCUArg.voltMeasPins = voltMeasPins;
                end
                if strcmpi(app.CurrentDropDown_BattPack.Value, "mcu")
                    currMeasPins = [app.CurrentPlusSpinner_SysMCU.Value, ...
                        app.CurrentMinusSpinner_SysMCU.Value];
                    app.sysMCUArg.currMeasPins = currMeasPins;
                end
                
                if strcmpi(app.SysMCUDropDown.Value, "LJMCU")
                    app.ConnectionmethodDropDown_SysMCU.Value = "";
                    app.sysMCUArg.devName = app.SysMCUDropDown.Value;
                    app.sysMCUArg.relayPins = relayPins;
                    app.sysMCUArg.trigPins = app.trigPins;
                    app.sysMCUArg.trigInvert = app.trigInvertOut;
                    
                    sysMCUArgs = app.sysMCUArg;
                    save(app.appDataLoc + app.backupFileName, 'sysMCUArgs', '-append')
                    
                    if app.SelfTestCheckBox_SysMCU.Value == true
                        % Connect to the LabJack device
                        try
                            app.LJAsm = NET.addAssembly('LJUDDotNet');
                            app.sysMCU = LabJack.LabJackUD.LJUD;
                            
                            % Open the first found LabJack U3.
                            [~, app.LJHandle] = app.sysMCU.OpenLabJackS('LJ_dtU3', 'LJ_ctUSB', '0', ...
                                true, 0);
                            % Start by using the pin_configuration_reset IOType so that all pin
                            % assignments are in the factory default condition.
                            app.sysMCU.ePutS(app.LJHandle, 'LJ_ioPIN_CONFIGURATION_RESET', 0, 0, 0);
                            app.sysMCU.ePutS(app.LJHandle, 'LJ_ioPUT_ANALOG_ENABLE_BIT', 7, 1, 0);
                        catch ME
                            uialert(app.devConfigsUIFigure, "Could not connect! Please make sure the device is plugged in!", "Connection Error");
                            app.ConnectSysMCUButton.Value = false;
                            rethrow(ME);
                            
                        end
                        app.updateTextArea(app.ResponseTextArea_SysMCU, "System MCU Connected!");
                        app.DeactivateRelayButton.Enable = true;
                        app.ActivateRelayButton.Enable = true;
                    end
                    
                else
                    switch app.ConnectionmethodDropDown_SysMCU.Value
                        case "serial"
                            COMPort = string(app.COMPortDropDown_SysMCU.Value);
                            baudRate = str2double(app.BaudRateDropDown_SysMCU.Value);
                            byteOrder = app.ByteOrderDropDown_SysMCU.Value;
                            stopBits = str2double(app.StopBitsDropDown_SysMCU.Value);
                            terminator = app.TerminatorDropDown_SysMCU.Value;
                            timeout = str2double(app.TimeoutEditField_SysMCU.Value);
                            
                            if COMPort == "Select COM Port"
                                uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a COM Port!", "Connection Error");
                                app.ConnectSysMCUButton.Value = false;
                                return;
                            end
                            
                            if app.SysMCUDropDown.Value == ""
                                uialert(app.devConfigsUIFigure, "Cannot connect! Please choose a DEVICE!", "Connection Error");
                                app.ConnectSysMCUButton.Value = false;
                                return;
                            end
                            
                            app.sysMCUArg.type = app.ConnectionmethodDropDown_SysMCU.Value;
                            app.sysMCUArg.devName = app.SysMCUDropDown.Value;
                            app.sysMCUArg.COMPort = COMPort;
                            app.sysMCUArg.baudRate = baudRate;
                            app.sysMCUArg.byteOrder = byteOrder;
                            app.sysMCUArg.stopBits = stopBits;
                            app.sysMCUArg.terminator = terminator;
                            app.sysMCUArg.timeout = timeout;
                            app.sysMCUArg.relayPins = relayPins;
                            app.sysMCUArg.trigPins = app.trigPins;
                            
                            sysMCUArgs = app.sysMCUArg;
                            save(app.appDataLoc + app.backupFileName, 'sysMCUArgs', '-append')
                            
                            if app.SelfTestCheckBox_SysMCU.Value == 1
                                switch upper(app.sysMCUName)
                                    case "ardMCU"
                                        app.sysMCU = serialport(COMPort, baudRate);
                                        app.sysMCU.stopBits = stopBits;
                                        app.sysMCU.byteOrder = byteOrder;
                                        app.sysMCU.terminator = terminator;
                                        app.sysMCU.timeout = timeout;
                                        app.updateTextArea(app.ResponseTextArea_SysMCU, "System MCU Connected!");
                                    otherwise
                                        app.updateTextArea(app.ResponseTextArea_SysMCU, "Invalid System MCU Device.");
                                        return;
                                end
                                
                                app.DeactivateRelayButton.Enable = true;
                                app.ActivateRelayButton.Enable = true;
                            end
                            app.COMPortDropDown_SysMCU.Enable = false;
                    end
                end
                
                % Prevent user from changing the Triggers pins
                app.Trigger1Checkbox_SysMCU.Enable = false;
                app.Trigger2Checkbox_SysMCU.Enable = false;
                app.Trigger3Checkbox_SysMCU.Enable = false;
                app.Trigger4Checkbox_SysMCU.Enable = false;
                app.Trigger5Checkbox_SysMCU.Enable = false;
                
                app.Trigger1Spinner.Enable = false;
                app.Trigger2Spinner.Enable = false;
                app.Trigger3Spinner.Enable = false;
                app.Trigger4Spinner.Enable = false;
                app.Trigger5Spinner.Enable = false;
                
                app.Trigger1InvertCheckbox_SysMCU.Enable = false;
                app.Trigger2InvertCheckbox_SysMCU.Enable = false;
                app.Trigger3InvertCheckbox_SysMCU.Enable = false;
                app.Trigger4InvertCheckbox_SysMCU.Enable = false;
                app.Trigger5InvertCheckbox_SysMCU.Enable = false; 
                
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.ActivateButton_1.Enable = true; end
                if app.Trigger2Checkbox_SysMCU.Value == 1, app.ActivateButton_2.Enable = true; end
                if app.Trigger3Checkbox_SysMCU.Value == 1, app.ActivateButton_3.Enable = true; end
                if app.Trigger4Checkbox_SysMCU.Value == 1, app.ActivateButton_4.Enable = true; end
                if app.Trigger5Checkbox_SysMCU.Value == 1, app.ActivateButton_5.Enable = true; end
                
                app.devConnected = true;
            else
                if strcmpi(app.SysMCUDropDown.Value, "LJMCU")
                    if app.SelfTestCheckBox_SysMCU.Value == true
                        % Connect to the LabJack device
                        try
                            if ~isempty(app.LJAsm)
                                switchRelays(app, false);
                                if ~isempty(app.trigPins), switchTrig(app, false, "all"); end
                                app.sysMCU.Close();
                                app.sysMCU = []; app.LJAsm = [];
                                app.updateTextArea(app.ResponseTextArea_SysMCU, "System MCU Disconnected!")
                            end
                        catch ME
                            uialert(app.devConfigsUIFigure, "Could not disconnect!", "Disconnection Error");
                            rethrow(ME);
                        end
                        app.DeactivateRelayButton.Enable = false;
                        app.ActivateRelayButton.Enable = false;
                    end
                else
                    switch app.ConnectionmethodDropDown_SysMCU.Value
                        case "serial"
                            if app.SelfTestCheckBox_SysMCU.Value == true
                                app.sysMCU = [];
                                app.updateTextArea(app.ResponseTextArea_SysMCU, ...
                                    string(response + newline + newline + "System MCU Disconnected!"));
                            end
                            
                            app.COMPortDropDown_SysMCU.Enable = true;
                    end
                end
                app.DeactivateRelayButton.Enable = false;
                app.ActivateRelayButton.Enable = false;
                
                % Prevent user from changing the Triggers pins
                app.Trigger1Checkbox_SysMCU.Enable = true;
                app.Trigger2Checkbox_SysMCU.Enable = true;
                app.Trigger3Checkbox_SysMCU.Enable = true;
                app.Trigger4Checkbox_SysMCU.Enable = true;
                app.Trigger5Checkbox_SysMCU.Enable = true;
                
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger1Spinner.Enable = true; end
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger2Spinner.Enable = true; end
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger3Spinner.Enable = true; end
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger4Spinner.Enable = true; end
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger5Spinner.Enable = true; end
                
                if app.Trigger1Checkbox_SysMCU.Value == 1, app.Trigger1InvertCheckbox_SysMCU.Enable = true; end
                if app.Trigger2Checkbox_SysMCU.Value == 1, app.Trigger2InvertCheckbox_SysMCU.Enable = true; end
                if app.Trigger3Checkbox_SysMCU.Value == 1, app.Trigger3InvertCheckbox_SysMCU.Enable = true; end
                if app.Trigger4Checkbox_SysMCU.Value == 1, app.Trigger4InvertCheckbox_SysMCU.Enable = true; end
                if app.Trigger5Checkbox_SysMCU.Value == 1, app.Trigger5InvertCheckbox_SysMCU.Enable = true; end
                
                app.ActivateButton_1.Enable = false;
                app.ActivateButton_2.Enable = false;
                app.ActivateButton_3.Enable = false;
                app.ActivateButton_4.Enable = false;
                app.ActivateButton_5.Enable = false;
                
                app.devConnected = false;
            end
        end

        % Value changed function: Trigger1Checkbox_SysMCU
        function Trigger1Checkbox_SysMCUValueChanged(app, event)
            value = app.Trigger1Checkbox_SysMCU.Value;
            if value == 1
               app.trigPins =  [app.trigPins, app.Trigger1Spinner.Value];
               app.Trigger1Spinner.Enable = true;
               app.Trigger1InvertCheckbox_SysMCU.Enable = true;
               app.StartTimeEditField1.Enable = true;
               app.DurationEditField1.Enable = true;
            else
                ind = app.trigPins == app.Trigger1Spinner.Value;
                app.trigPins(ind) = [];
                app.Trigger1Spinner.Enable = false;
                app.ActivateButton_1.Enable = false;
                app.Trigger1InvertCheckbox_SysMCU.Enable = false;
                app.StartTimeEditField1.Enable = false;
                app.DurationEditField1.Enable = false;
            end
        end

        % Value changed function: Trigger2Checkbox_SysMCU
        function Trigger2Checkbox_SysMCUValueChanged(app, event)
            value = app.Trigger2Checkbox_SysMCU.Value;
            
            if value == 1
               app.trigPins = [app.trigPins, app.Trigger2Spinner.Value];
               app.Trigger2Spinner.Enable = true;
               app.Trigger2InvertCheckbox_SysMCU.Enable = true;
               app.StartTimeEditField2.Enable = true;
               app.DurationEditField2.Enable = true;
            else
                ind = app.trigPins == app.Trigger2Spinner.Value;
                app.trigPins(ind) = [];
                app.Trigger2Spinner.Enable = false;
                app.ActivateButton_2.Enable = false;
                app.Trigger2InvertCheckbox_SysMCU.Enable = false;
                app.StartTimeEditField2.Enable = false;
                app.DurationEditField2.Enable = false;
            end
        end

        % Value changed function: Trigger3Checkbox_SysMCU
        function Trigger3Checkbox_SysMCUValueChanged(app, event)
            value = app.Trigger3Checkbox_SysMCU.Value;
            
            if value == 1
                app.trigPins = [app.trigPins, app.Trigger3Spinner.Value];
                app.Trigger3Spinner.Enable = true;
                app.Trigger3InvertCheckbox_SysMCU.Enable = true;
                app.StartTimeEditField3.Enable = true;
                app.DurationEditField3.Enable = true;
                %                app.ActivateButton_3.Enable = true;
            else
                ind = app.trigPins == app.Trigger3Spinner.Value;
                app.trigPins(ind) = [];
                app.Trigger3Spinner.Enable = false;
                app.ActivateButton_3.Enable = false;
                app.Trigger3InvertCheckbox_SysMCU.Enable = false;
                app.StartTimeEditField3.Enable = false;
                app.DurationEditField3.Enable = false;
            end
        end

        % Value changed function: Trigger4Checkbox_SysMCU
        function Trigger4Checkbox_SysMCUValueChanged(app, event)
            value = app.Trigger4Checkbox_SysMCU.Value;
            
            if value == 1
                app.trigPins = [app.trigPins, app.Trigger4Spinner.Value];
                app.Trigger4Spinner.Enable = true;
                app.Trigger4InvertCheckbox_SysMCU.Enable = true;
                app.StartTimeEditField4.Enable = true;
                app.DurationEditField4.Enable = true;
                %                app.ActivateButton_4.Enable = true;
            else
                ind = app.trigPins == app.Trigger4Spinner.Value;
                app.trigPins(ind) = [];
                app.Trigger4Spinner.Enable = false;
                app.ActivateButton_4.Enable = false;
                app.Trigger4InvertCheckbox_SysMCU.Enable = false;
                app.StartTimeEditField4.Enable = false;
                app.DurationEditField4.Enable = false;
            end
        end

        % Value changed function: Trigger5Checkbox_SysMCU
        function Trigger5Checkbox_SysMCUValueChanged(app, event)
            value = app.Trigger5Checkbox_SysMCU.Value;
            
            if value == 1
                app.trigPins = [app.trigPins, app.Trigger5Spinner.Value];
                app.Trigger5Spinner.Enable = true;
                app.Trigger5InvertCheckbox_SysMCU.Enable = true;
                app.StartTimeEditField5.Enable = true;
                app.DurationEditField5.Enable = true;
                %                app.ActivateButton_5.Enable = true;
            else
                ind = app.trigPins == app.Trigger5Spinner.Value;
                app.trigPins(ind) = [];
                app.Trigger5Spinner.Enable = false;
                app.ActivateButton_5.Enable = false;
                app.Trigger5InvertCheckbox_SysMCU.Enable = false;
                app.StartTimeEditField5.Enable = false;
                app.DurationEditField5.Enable = false;
            end
        end

        % Value changed function: ActivateButton_1
        function ActivateButton_1ValueChanged(app, event)
            value = app.ActivateButton_1.Value;
            
            if value == 1
                ind = app.trigPins == app.Trigger1Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(1)), app.trigPins(ind));
            else
                ind = app.trigPins == app.Trigger1Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(1)), app.trigPins(ind));
            end
        end

        % Value changed function: ActivateButton_2
        function ActivateButton_2ValueChanged(app, event)
            value = app.ActivateButton_2.Value;
            
            if value == 1
                ind = app.trigPins == app.Trigger2Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(2)), app.trigPins(ind));
            else
                ind = app.trigPins == app.Trigger2Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(2)), app.trigPins(ind));
            end
        end

        % Value changed function: ActivateButton_3
        function ActivateButton_3ValueChanged(app, event)
            value = app.ActivateButton_3.Value;
            
            if value == 1
                ind = app.trigPins == app.Trigger3Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(3)), app.trigPins(ind));
            else
                ind = app.trigPins == app.Trigger3Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(3)), app.trigPins(ind));
            end
        end

        % Value changed function: ActivateButton_4
        function ActivateButton_4ValueChanged(app, event)
            value = app.ActivateButton_4.Value;
            
            if value == 1
                ind = app.trigPins == app.Trigger4Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(4)), app.trigPins(ind));
            else
                ind = app.trigPins == app.Trigger4Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(4)), app.trigPins(ind));
            end
        end

        % Value changed function: ActivateButton_5
        function ActivateButton_5ValueChanged(app, event)
            value = app.ActivateButton_5.Value;
            
            if value == 1
                ind = app.trigPins == app.Trigger5Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(5)), app.trigPins(ind));
            else
                ind = app.trigPins == app.Trigger5Spinner.Value;
                app.switchTrig(~(value&app.trigInvertOut(5)), app.trigPins(ind));
            end
        end

        % Value changed function: Trigger1InvertCheckbox_SysMCU
        function Trigger1InvertCheckbox_SysMCUValueChanged(app, event)
            value = app.Trigger1InvertCheckbox_SysMCU.Value;
            app.trigInvertOut(1, 1) = value;
        end

        % Value changed function: Trigger2InvertCheckbox_SysMCU
        function Trigger2InvertCheckbox_SysMCUValueChanged(app, event)
            value = app.Trigger2InvertCheckbox_SysMCU.Value;
            app.trigInvertOut(2, 1) = value;
        end

        % Value changed function: Trigger3InvertCheckbox_SysMCU
        function Trigger3InvertCheckbox_SysMCUValueChanged(app, event)
            value = app.Trigger3InvertCheckbox_SysMCU.Value;
            app.trigInvertOut(3, 1) = value;
        end

        % Value changed function: Trigger4InvertCheckbox_SysMCU
        function Trigger4InvertCheckbox_SysMCUValueChanged(app, event)
            value = app.Trigger4InvertCheckbox_SysMCU.Value;
            app.trigInvertOut(4, 1) = value;
        end

        % Value changed function: Trigger5InvertCheckbox_SysMCU
        function Trigger5InvertCheckbox_SysMCUValueChanged(app, event)
            value = app.Trigger5InvertCheckbox_SysMCU.Value;
            app.trigInvertOut(5, 1) = value;
        end

        % Value changed function: VoltageDropDown_BattPack
        function VoltageDropDown_BattPackValueChanged(app, event)
            value = app.VoltageDropDown_BattPack.Value;
            
            if strcmpi(value, "mcu")
                
                
            end
        end

        % Selection changed function: RelayStateButtonGroup
        function RelayStateButtonGroupSelectionChanged(app, event)
            selectedButton = app.RelayStateButtonGroup.SelectedObject;
            
            if selectedButton == app.ActivateRelayButton
                switchRelays(app, true);
            else
                switchRelays(app, false);
            end
        end

        % Value changed function: CurrentDropDown_BattPack
        function CurrentDropDown_BattPackValueChanged(app, event)
            value = app.CurrentDropDown_BattPack.Value;
            
            if strcmpi(value, "powerDev")
                app.CurrentPlusSpinner_SysMCU.Enable = true;
                app.CurrentMinusSpinner_SysMCU.Enable = true;
            end
        end

        % Value changed function: StartTimeEditField1
        function StartTimeEditField1ValueChanged(app, event)
            value = app.StartTimeEditField1.Value;
%             app.val = value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigStartTimes(1, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 1 Start Times. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: StartTimeEditField2
        function StartTimeEditField2ValueChanged(app, event)
            value = app.StartTimeEditField2.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigStartTimes(2, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 2 Start Times. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: StartTimeEditField3
        function StartTimeEditField3ValueChanged(app, event)
            value = app.StartTimeEditField3.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigStartTimes(3, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 3 Start Times. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: StartTimeEditField4
        function StartTimeEditField4ValueChanged(app, event)
            value = app.StartTimeEditField4.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigStartTimes(4, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 4 Start Times. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: StartTimeEditField5
        function StartTimeEditField5ValueChanged(app, event)
            value = app.StartTimeEditField5.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigStartTimes(5, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 5 Start Times. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: DurationEditField1
        function DurationEditField1ValueChanged(app, event)
            value = app.DurationEditField1.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigDurations(1, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 1 Duration. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: DurationEditField2
        function DurationEditField2ValueChanged(app, event)
            value = app.DurationEditField2.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigDurations(2, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 2 Duration. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: DurationEditField3
        function DurationEditField3ValueChanged(app, event)
            value = app.DurationEditField3.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigDurations(3, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 3 Duration. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: DurationEditField4
        function DurationEditField4ValueChanged(app, event)
            value = app.DurationEditField4.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigDurations(4, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 4 Duration. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Value changed function: DurationEditField5
        function DurationEditField5ValueChanged(app, event)
            value = app.DurationEditField5.Value;
            tempVal = str2double(strsplit(value,','));
            tempVal = tempVal(~isnan(tempVal));
            app.trigDurations(5, 1) = {tempVal};
            app.updateTextArea(app.ResponseTextArea_SysMCU, "Trig 5 Duration. You've entered: " + strjoin(string(tempVal), ', '));
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            selpath = uigetdir(app.saveDir, "Select Folder to Save Data to");
            figure(app.devConfigsUIFigure);
            if selpath ~= 0
               app.saveDir = selpath;
               app.SaveDirectoryEditField.Value = selpath;
            end
        end

        % Button pushed function: LoadDeviceConfigsButton
        function LoadDeviceConfigsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Load a Configuration File');
            figure(app.devConfigsUIFigure);
            if file ~= 0
                load(path + "/" + file, "-mat", 'saveConfigsArgs');
            end
            
             % Reinstate all Config settings
             app.cellConfigDropDown.Value       = saveConfigsArgs.cellConfig;
             app.SampleTimeSpinner.Value        = saveConfigsArgs.sampleTime;
             app.SaveDirectoryEditField.Value   = saveConfigsArgs.saveDir;
             app.SaveFileNameEditField.Value    = saveConfigsArgs.dataSaveName;
             app.PurposeofTestTextArea.Value    = saveConfigsArgs.purpose;
             app.SelectDatatoRecordListBox.Value= saveConfigsArgs.data2Record;
             app.VoltageDropDown_BattPack.Value = saveConfigsArgs.voltMeasDev;
             app.CurrentDropDown_BattPack.Value = saveConfigsArgs.currMeasDev;
             app.TempDropDown_BattPack.Value = saveConfigsArgs.ambTMeasDev;
             
        end

        % Button pushed function: SaveDeviceConfigsButton
        function SaveDeviceConfigsButtonPushed(app, event)
            % Collect all Config settings
            saveConfigsArgs = struct;
            saveConfigsArgs.cellConfig  = app.cellConfigDropDown.Value;
            saveConfigsArgs.sampleTime  = app.SampleTimeSpinner.Value;
            saveConfigsArgs.saveDir     = app.SaveDirectoryEditField.Value;
            saveConfigsArgs.dataSaveName= app.SaveFileNameEditField.Value;
            saveConfigsArgs.purpose     = app.PurposeofTestTextArea.Value;
            saveConfigsArgs.data2Record = app.SelectDatatoRecordListBox.Value;
            saveConfigsArgs.voltMeasDev = app.VoltageDropDown_BattPack.Value;
            saveConfigsArgs.currMeasDev = app.CurrentDropDown_BattPack.Value;
            saveConfigsArgs.ambTMeasDev = app.TempDropDown_BattPack.Value;
            
            [file,path] = uiputfile('*.mat','Save current Configuration to File');
            figure(app.devConfigsUIFigure);
            if file ~= 0
                save(path + "/" + file, "-mat", 'saveConfigsArgs');
            end
        end

        % Value changed function: SaveFileNameEditField
        function SaveFileNameEditFieldValueChanged(app, event)
            value = app.SaveFileNameEditField.Value;
            app.saveName = value;
        end

        % Value changed function: SaveDirectoryEditField
        function SaveDirectoryEditFieldValueChanged(app, event)
            value = app.SaveDirectoryEditField.Value;
            app.saveDir = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.devConfigsUIFigure = uifigure('Visible', 'off');
            app.devConfigsUIFigure.Name = 'Device Configurations';
            app.devConfigsUIFigure.CloseRequestFcn = createCallbackFcn(app, @devConfigsUIFigureCloseRequest, true);
            app.devConfigsUIFigure.Tag = 'devConfigs';
            app.GridLayout2 = uigridlayout(app.devConfigsUIFigure);
            app.devConfigCtrlPanel = uipanel(app.GridLayout2);
            app.GridLayout = uigridlayout(app.devConfigCtrlPanel);
            app.LoadDeviceConfigsButton = uibutton(app.GridLayout, 'push');
            app.LoadDeviceConfigsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDeviceConfigsButtonPushed, true);
            app.LoadDeviceConfigsButton.Icon = 'download.png';
            app.LoadDeviceConfigsButton.Text = {'Load '; 'Device Configs'};
            app.SaveDeviceConfigsButton = uibutton(app.GridLayout, 'push');
            app.SaveDeviceConfigsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDeviceConfigsButtonPushed, true);
            app.SaveDeviceConfigsButton.Icon = 'save.png';
            app.SaveDeviceConfigsButton.Text = {'Save '; 'Device Configs'};
            app.ConnectALLDevicesButton = uibutton(app.GridLayout, 'state');
            app.ConnectALLDevicesButton.ValueChangedFcn = createCallbackFcn(app, @ConnectALLDevicesButtonValueChanged, true);
            app.ConnectALLDevicesButton.Text = 'Connect ALL Devices';
            app.PrevDeviceButton = uibutton(app.GridLayout, 'push');
            app.PrevDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @PrevDeviceButtonPushed, true);
            app.PrevDeviceButton.Icon = 'left-arrow.png';
            app.PrevDeviceButton.Text = 'Prev Device';
            app.NextDeviceButton = uibutton(app.GridLayout, 'push');
            app.NextDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @NextDeviceButtonPushed, true);
            app.NextDeviceButton.Icon = 'next.png';
            app.NextDeviceButton.Text = 'Next Device';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup.Tag = 'tab1';
            app.BatteryPackTab = uitab(app.TabGroup);
            app.BatteryPackTab.Title = 'Battery Pack';
            app.GridLayout7 = uigridlayout(app.BatteryPackTab);
            app.CellIDsPanel = uipanel(app.GridLayout7);
            app.CellIDsPanel.Title = 'Cell IDs';
            app.Tree = uitree(app.CellIDsPanel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'List of cells availble to system'};
            app.DeleteCellsButton = uibutton(app.CellIDsPanel, 'push');
            app.DeleteCellsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCellsButtonPushed, true);
            app.DeleteCellsButton.Icon = 'minus.png';
            app.DeleteCellsButton.Text = '';
            app.AddCellsButton = uibutton(app.CellIDsPanel, 'push');
            app.AddCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellsButtonPushed, true);
            app.AddCellsButton.Icon = 'plus.png';
            app.AddCellsButton.Text = '';
            app.CellConfigurationPanel = uipanel(app.GridLayout7);
            app.CellConfigurationPanel.Title = 'Cell Configuration';
            app.CellStackArrangementDropDownLabel = uilabel(app.CellConfigurationPanel);
            app.CellStackArrangementDropDownLabel.Text = 'Cell Stack Arrangement';
            app.cellConfigDropDown = uidropdown(app.CellConfigurationPanel);
            app.cellConfigDropDown.Items = {'Single Cell', 'Series Stack', 'Parallel Stack', 'Series-Parallel Stack'};
            app.cellConfigDropDown.ItemsData = {'single', 'series', 'parallel', 'ser_par'};
            app.cellConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @cellConfigDropDownValueChanged, true);
            app.cellConfigDropDown.Value = 'single';
            app.UITable = uitable(app.CellConfigurationPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.EditTableButton_BattPack = uibutton(app.CellConfigurationPanel, 'state');
            app.EditTableButton_BattPack.ValueChangedFcn = createCallbackFcn(app, @EditTableButton_BattPackValueChanged, true);
            app.EditTableButton_BattPack.Text = 'Edit Table';
            app.SaveChangesButton = uibutton(app.CellConfigurationPanel, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Enable = 'off';
            app.SaveChangesButton.Text = 'Save Changes';
            app.DiscardChangesButton = uibutton(app.CellConfigurationPanel, 'push');
            app.DiscardChangesButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardChangesButtonPushed, true);
            app.DiscardChangesButton.Enable = 'off';
            app.DiscardChangesButton.Text = 'Discard Changes';
            app.DataRecordingPanel = uipanel(app.GridLayout7);
            app.DataRecordingPanel.Title = 'Data Recording';
            app.SampleTimesSpinnerLabel = uilabel(app.DataRecordingPanel);
            app.SampleTimesSpinnerLabel.Text = 'Sample Time (s)';
            app.SampleTimeSpinner = uispinner(app.DataRecordingPanel);
            app.SampleTimeSpinner.Step = 0.25;
            app.SampleTimeSpinner.Limits = [0.25 3600];
            app.SampleTimeSpinner.ValueDisplayFormat = '%11.2f';
            app.SampleTimeSpinner.Value = 1;
            app.SaveFileNameEditFieldLabel = uilabel(app.DataRecordingPanel);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name';
            app.SaveFileNameEditField = uieditfield(app.DataRecordingPanel, 'text');
            app.SaveFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @SaveFileNameEditFieldValueChanged, true);
            app.SaveDirectoryEditFieldLabel = uilabel(app.DataRecordingPanel);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.DataRecordingPanel, 'text');
            app.SaveDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @SaveDirectoryEditFieldValueChanged, true);
            app.SaveDirectoryEditField.Editable = 'off';
            app.ChooseButton = uibutton(app.DataRecordingPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Icon = 'folder.png';
            app.ChooseButton.Text = 'Choose';
            app.PurposeofTestTextAreaLabel = uilabel(app.DataRecordingPanel);
            app.PurposeofTestTextAreaLabel.Text = 'Purpose of Test';
            app.PurposeofTestTextArea = uitextarea(app.DataRecordingPanel);
            app.SelectDatatoRecordLabel = uilabel(app.DataRecordingPanel);
            app.SelectDatatoRecordLabel.Text = 'Select Data to Record';
            app.SelectDatatoRecordListBox = uilistbox(app.DataRecordingPanel);
            app.SelectDatatoRecordListBox.Items = {'Record All', 'Voltage', 'Current', 'State of Charge (SOC)', 'Capacity', 'Temperature'};
            app.SelectDatatoRecordListBox.ItemsData = {'All', 'volt', 'curr', 'SOC', 'Cap', 'Temp'};
            app.SelectDatatoRecordListBox.Multiselect = 'on';
            app.SelectDatatoRecordListBox.ValueChangedFcn = createCallbackFcn(app, @SelectDatatoRecordListBoxValueChanged, true);
            app.SelectDatatoRecordListBox.Value = {'volt'};
            app.MeasurementDevicesPanel = uipanel(app.DataRecordingPanel);
            app.MeasurementDevicesPanel.Title = 'Measurement Devices';
            app.VoltageDropDownLabel = uilabel(app.MeasurementDevicesPanel);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.VoltageDropDown_BattPack = uidropdown(app.MeasurementDevicesPanel);
            app.VoltageDropDown_BattPack.Items = {'PSU & E-Load', 'System MCU', 'Balancer'};
            app.VoltageDropDown_BattPack.ItemsData = {'powerDev', 'mcu', 'balancer'};
            app.VoltageDropDown_BattPack.ValueChangedFcn = createCallbackFcn(app, @VoltageDropDown_BattPackValueChanged, true);
            app.VoltageDropDown_BattPack.Value = 'mcu';
            app.CurrentDropDownLabel = uilabel(app.MeasurementDevicesPanel);
            app.CurrentDropDownLabel.Text = 'Current';
            app.CurrentDropDown_BattPack = uidropdown(app.MeasurementDevicesPanel);
            app.CurrentDropDown_BattPack.Items = {'PSU & E-Load', 'System MCU', 'Balancer'};
            app.CurrentDropDown_BattPack.ItemsData = {'powerDev', 'mcu', 'balancer'};
            app.CurrentDropDown_BattPack.ValueChangedFcn = createCallbackFcn(app, @CurrentDropDown_BattPackValueChanged, true);
            app.CurrentDropDown_BattPack.Value = 'powerDev';
            app.TemperatureLabel = uilabel(app.MeasurementDevicesPanel);
            app.TemperatureLabel.Text = 'Temperature';
            app.TempDropDown_BattPack = uidropdown(app.MeasurementDevicesPanel);
            app.TempDropDown_BattPack.Items = {'Balancer', 'Arduino TC', '16 Ch. Module'};
            app.TempDropDown_BattPack.ItemsData = {'balancer', 'ardMod', 'Mod16Ch'};
            app.TempDropDown_BattPack.Value = 'Mod16Ch';
            app.EStopMCUTab = uitab(app.TabGroup);
            app.EStopMCUTab.Title = 'E-Stop MCU';
            app.EStopMCUTab.Tag = 'tab2';
            app.EStopGrid = uigridlayout(app.EStopMCUTab);
            app.SerialPortPanel_EStop = uipanel(app.EStopGrid);
            app.SerialPortPanel_EStop.Title = 'Serial Port';
            app.SerialPortGrid_EStop = uigridlayout(app.SerialPortPanel_EStop);
            app.COMPortDropDown_Label = uilabel(app.SerialPortGrid_EStop);
            app.COMPortDropDown_Label.Enable = 'off';
            app.COMPortDropDown_Label.Text = 'COM Port';
            app.COMPortDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.COMPortDropDown_EStop.Items = {'Select COM Port'};
            app.COMPortDropDown_EStop.Enable = 'off';
            app.COMPortDropDown_EStop.Value = 'Select COM Port';
            app.BaudRateDropDown_2Label = uilabel(app.SerialPortGrid_EStop);
            app.BaudRateDropDown_2Label.Enable = 'off';
            app.BaudRateDropDown_2Label.Text = 'Baud Rate';
            app.BaudRateDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.BaudRateDropDown_EStop.Items = {'4800', '9600', '19200', '38400', '57600', '115200', '230400', '500000', '1000000'};
            app.BaudRateDropDown_EStop.Enable = 'off';
            app.BaudRateDropDown_EStop.Value = '115200';
            app.TimeoutEditField_2Label = uilabel(app.SerialPortGrid_EStop);
            app.TimeoutEditField_2Label.Enable = 'off';
            app.TimeoutEditField_2Label.Tooltip = {'in milliseconds'};
            app.TimeoutEditField_2Label.Text = 'Timeout';
            app.TimeoutEditField_EStop = uieditfield(app.SerialPortGrid_EStop, 'text');
            app.TimeoutEditField_EStop.Enable = 'off';
            app.TimeoutEditField_EStop.Tooltip = {'in Seconds'};
            app.TimeoutEditField_EStop.Value = '10';
            app.EmergencyButton_EStop = uibutton(app.SerialPortGrid_EStop, 'state');
            app.EmergencyButton_EStop.ValueChangedFcn = createCallbackFcn(app, @EmergencyButton_EStopValueChanged, true);
            app.EmergencyButton_EStop.Enable = 'off';
            app.EmergencyButton_EStop.Icon = 'stop.png';
            app.EmergencyButton_EStop.Text = 'Emergency ';
            app.ResponseTextAreaLabel = uilabel(app.SerialPortGrid_EStop);
            app.ResponseTextAreaLabel.Enable = 'off';
            app.ResponseTextAreaLabel.Text = 'Response';
            app.ResponseTextArea_EStop = uitextarea(app.SerialPortGrid_EStop);
            app.ResponseTextArea_EStop.Enable = 'off';
            app.ByteOrderDropDownLabel = uilabel(app.SerialPortGrid_EStop);
            app.ByteOrderDropDownLabel.Enable = 'off';
            app.ByteOrderDropDownLabel.Text = 'Byte Order';
            app.ByteOrderDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.ByteOrderDropDown_EStop.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_EStop.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_EStop.Enable = 'off';
            app.ByteOrderDropDown_EStop.Value = 'big-endian';
            app.TerminatorLabel = uilabel(app.SerialPortGrid_EStop);
            app.TerminatorLabel.Enable = 'off';
            app.TerminatorLabel.Text = 'Terminator';
            app.TerminatorDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.TerminatorDropDown_EStop.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_EStop.Enable = 'off';
            app.TerminatorDropDown_EStop.Value = 'LF';
            app.StopBitsDropDownLabel_2 = uilabel(app.SerialPortGrid_EStop);
            app.StopBitsDropDownLabel_2.Enable = 'off';
            app.StopBitsDropDownLabel_2.Text = 'Stop Bits';
            app.StopBitsDropDown_EStop = uidropdown(app.SerialPortGrid_EStop);
            app.StopBitsDropDown_EStop.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_EStop.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_EStop.Editable = 'on';
            app.StopBitsDropDown_EStop.Enable = 'off';
            app.StopBitsDropDown_EStop.Value = '1';
            app.ConnectionMethodPanel_EStop = uipanel(app.EStopGrid);
            app.ConnectEStopButton = uibutton(app.ConnectionMethodPanel_EStop, 'state');
            app.ConnectEStopButton.ValueChangedFcn = createCallbackFcn(app, @ConnectEStopButtonValueChanged, true);
            app.ConnectEStopButton.Enable = 'off';
            app.ConnectEStopButton.Text = 'Connect E-Stop';
            app.ConnectionmethodDropDown_2Label = uilabel(app.ConnectionMethodPanel_EStop);
            app.ConnectionmethodDropDown_2Label.Text = 'Connection method';
            app.ConnectionmethodDropDown_EStop = uidropdown(app.ConnectionMethodPanel_EStop);
            app.ConnectionmethodDropDown_EStop.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_EStop.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_EStop.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_EStopValueChanged, true);
            app.ConnectionmethodDropDown_EStop.Value = 'serial';
            app.PowerSupplyTab = uitab(app.TabGroup);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout3 = uigridlayout(app.PowerSupplyTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SerialPortPanel_PSU = uipanel(app.GridLayout4);
            app.SerialPortPanel_PSU.Title = 'SERIAL PORT';
            app.SerialPortGrid_PSU = uigridlayout(app.SerialPortPanel_PSU);
            app.ClearAlarmCodesButton_PSU = uibutton(app.SerialPortGrid_PSU, 'push');
            app.ClearAlarmCodesButton_PSU.ButtonPushedFcn = createCallbackFcn(app, @ClearAlarmCodesButton_PSUPushed, true);
            app.ClearAlarmCodesButton_PSU.Enable = 'off';
            app.ClearAlarmCodesButton_PSU.Text = 'Clear Alarm Codes';
            app.TerminalOutputsButtonGroup_PSU = uibuttongroup(app.SerialPortGrid_PSU);
            app.TerminalOutputsButtonGroup_PSU.SelectionChangedFcn = createCallbackFcn(app, @TerminalOutputsButtonGroup_PSUSelectionChanged, true);
            app.TerminalOutputsButtonGroup_PSU.Tooltip = {'Allow or disallow the PSU from transferring power via terminals'};
            app.TerminalOutputsButtonGroup_PSU.Title = 'Terminal Outputs';
            app.ONButton_PSU = uitogglebutton(app.TerminalOutputsButtonGroup_PSU);
            app.ONButton_PSU.Enable = 'off';
            app.ONButton_PSU.Text = 'ON';
            app.OFFButton_PSU = uitogglebutton(app.TerminalOutputsButtonGroup_PSU);
            app.OFFButton_PSU.Enable = 'off';
            app.OFFButton_PSU.Text = 'OFF';
            app.OFFButton_PSU.Value = true;
            app.GetDeviceDetailsButton_PSU = uibutton(app.SerialPortGrid_PSU, 'push');
            app.GetDeviceDetailsButton_PSU.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceDetailsButton_PSUPushed, true);
            app.GetDeviceDetailsButton_PSU.Enable = 'off';
            app.GetDeviceDetailsButton_PSU.Text = 'Get Device Details';
            app.COMPortDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.COMPortDropDownLabel.Enable = 'off';
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.COMPortDropDown_PSU.Items = {'Select COM Port'};
            app.COMPortDropDown_PSU.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDown_PSUValueChanged, true);
            app.COMPortDropDown_PSU.Enable = 'off';
            app.COMPortDropDown_PSU.Value = 'Select COM Port';
            app.BaudRateDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.BaudRateDropDownLabel.Enable = 'off';
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.BaudRateDropDown_PSU.Items = {'4800', '9600', '19200', '38400', '57600', '115200', '230400', '500000', '1000000'};
            app.BaudRateDropDown_PSU.Enable = 'off';
            app.BaudRateDropDown_PSU.Value = '115200';
            app.TimeoutEditFieldLabel = uilabel(app.SerialPortGrid_PSU);
            app.TimeoutEditFieldLabel.Enable = 'off';
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField_PSU = uieditfield(app.SerialPortGrid_PSU, 'text');
            app.TimeoutEditField_PSU.Enable = 'off';
            app.TimeoutEditField_PSU.Tooltip = {'in Seconds'};
            app.TimeoutEditField_PSU.Value = '10';
            app.ByteOrderDropDownLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.ByteOrderDropDownLabel_2.Enable = 'off';
            app.ByteOrderDropDownLabel_2.Text = 'Byte Order';
            app.ByteOrderDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.ByteOrderDropDown_PSU.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_PSU.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_PSU.Enable = 'off';
            app.ByteOrderDropDown_PSU.Value = 'big-endian';
            app.TerminatorLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.TerminatorLabel_2.Enable = 'off';
            app.TerminatorLabel_2.Text = 'Terminator';
            app.TerminatorDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.TerminatorDropDown_PSU.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_PSU.Enable = 'off';
            app.TerminatorDropDown_PSU.Value = 'LF';
            app.ResponseTextAreaLabel_2 = uilabel(app.SerialPortGrid_PSU);
            app.ResponseTextAreaLabel_2.Enable = 'off';
            app.ResponseTextAreaLabel_2.Text = 'Response';
            app.ResponseTextArea_PSU = uitextarea(app.SerialPortGrid_PSU);
            app.ResponseTextArea_PSU.ValueChangedFcn = createCallbackFcn(app, @ResponseTextArea_PSUValueChanged, true);
            app.ResponseTextArea_PSU.Editable = 'off';
            app.ResponseTextArea_PSU.Enable = 'off';
            app.SetControlOwnerListBoxLabel = uilabel(app.SerialPortGrid_PSU);
            app.SetControlOwnerListBoxLabel.Enable = 'off';
            app.SetControlOwnerListBoxLabel.Text = 'Set Control Owner';
            app.SetControlOwnerListBox_PSU = uilistbox(app.SerialPortGrid_PSU);
            app.SetControlOwnerListBox_PSU.Items = {'Software', 'Local Device'};
            app.SetControlOwnerListBox_PSU.ItemsData = {'remote', 'local'};
            app.SetControlOwnerListBox_PSU.ValueChangedFcn = createCallbackFcn(app, @SetControlOwnerListBox_PSUValueChanged, true);
            app.SetControlOwnerListBox_PSU.Enable = 'off';
            app.SetControlOwnerListBox_PSU.Value = 'remote';
            app.StopBitsDropDownLabel = uilabel(app.SerialPortGrid_PSU);
            app.StopBitsDropDownLabel.Enable = 'off';
            app.StopBitsDropDownLabel.Text = 'Stop Bits';
            app.StopBitsDropDown_PSU = uidropdown(app.SerialPortGrid_PSU);
            app.StopBitsDropDown_PSU.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_PSU.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_PSU.Editable = 'on';
            app.StopBitsDropDown_PSU.Enable = 'off';
            app.StopBitsDropDown_PSU.Value = '1';
            app.LANCOMMPanel = uipanel(app.GridLayout4);
            app.LANCOMMPanel.Title = 'LAN COMM';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PSUDropDownLabel = uilabel(app.GridLayout6);
            app.PSUDropDownLabel.Text = 'PSU';
            app.PSUDropDown = uidropdown(app.GridLayout6);
            app.PSUDropDown.Items = {'Select ...', 'APM', 'BK Precision', ''};
            app.PSUDropDown.ItemsData = {'', 'apm', 'bk'};
            app.PSUDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUDropDownValueChanged, true);
            app.PSUDropDown.Tooltip = {'Select Device used as a power supply (PSU)'};
            app.PSUDropDown.Value = '';
            app.ConnectonMethodGrid_PSU = uigridlayout(app.GridLayout5);
            app.ConnectionmethodDropDownLabel = uilabel(app.ConnectonMethodGrid_PSU);
            app.ConnectionmethodDropDownLabel.Text = 'Connection method';
            app.ConnectionmethodDropDown_PSU = uidropdown(app.ConnectonMethodGrid_PSU);
            app.ConnectionmethodDropDown_PSU.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_PSU.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_PSU.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_PSUValueChanged, true);
            app.ConnectionmethodDropDown_PSU.Value = '';
            app.ConnectPSUButton = uibutton(app.ConnectonMethodGrid_PSU, 'state');
            app.ConnectPSUButton.ValueChangedFcn = createCallbackFcn(app, @ConnectPSUButtonValueChanged, true);
            app.ConnectPSUButton.Enable = 'off';
            app.ConnectPSUButton.Text = 'Connect PSU';
            app.SelfTestCheckBox_PSU = uicheckbox(app.ConnectonMethodGrid_PSU);
            app.SelfTestCheckBox_PSU.Text = 'Self Test';
            app.ElectronicLoadTab = uitab(app.TabGroup);
            app.ElectronicLoadTab.Title = 'Electronic Load';
            app.GridLayout3_2 = uigridlayout(app.ElectronicLoadTab);
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.SerialPortPanel_ELoad = uipanel(app.GridLayout4_2);
            app.SerialPortPanel_ELoad.Title = 'SERIAL PORT';
            app.SerialPortGrid_ELoad = uigridlayout(app.SerialPortPanel_ELoad);
            app.ClearAlarmCodesButton_ELoad = uibutton(app.SerialPortGrid_ELoad, 'push');
            app.ClearAlarmCodesButton_ELoad.ButtonPushedFcn = createCallbackFcn(app, @ClearAlarmCodesButton_ELoadPushed, true);
            app.ClearAlarmCodesButton_ELoad.Enable = 'off';
            app.ClearAlarmCodesButton_ELoad.Text = 'Clear Alarm Codes';
            app.TerminalOutputsButtonGroup_ELoad = uibuttongroup(app.SerialPortGrid_ELoad);
            app.TerminalOutputsButtonGroup_ELoad.SelectionChangedFcn = createCallbackFcn(app, @TerminalOutputsButtonGroup_ELoadSelectionChanged, true);
            app.TerminalOutputsButtonGroup_ELoad.Tooltip = {'Allow or disallow the PSU from transferring power via terminals'};
            app.TerminalOutputsButtonGroup_ELoad.Title = 'Terminal Outputs';
            app.ONButton_ELoad = uitogglebutton(app.TerminalOutputsButtonGroup_ELoad);
            app.ONButton_ELoad.Enable = 'off';
            app.ONButton_ELoad.Text = 'ON';
            app.OFFButton_ELoad = uitogglebutton(app.TerminalOutputsButtonGroup_ELoad);
            app.OFFButton_ELoad.Enable = 'off';
            app.OFFButton_ELoad.Text = 'OFF';
            app.OFFButton_ELoad.Value = true;
            app.GetDeviceDetailsButton_ELoad = uibutton(app.SerialPortGrid_ELoad, 'push');
            app.GetDeviceDetailsButton_ELoad.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceDetailsButton_ELoadPushed, true);
            app.GetDeviceDetailsButton_ELoad.Enable = 'off';
            app.GetDeviceDetailsButton_ELoad.Text = 'Get Device Details';
            app.COMPortDropDownLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.COMPortDropDownLabel_2.Enable = 'off';
            app.COMPortDropDownLabel_2.Text = 'COM Port';
            app.COMPortDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.COMPortDropDown_ELoad.Items = {'Select COM Port'};
            app.COMPortDropDown_ELoad.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDown_ELoadValueChanged, true);
            app.COMPortDropDown_ELoad.Enable = 'off';
            app.COMPortDropDown_ELoad.Value = 'Select COM Port';
            app.BaudRateDropDownLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.BaudRateDropDownLabel_2.Enable = 'off';
            app.BaudRateDropDownLabel_2.Text = 'Baud Rate';
            app.BaudRateDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.BaudRateDropDown_ELoad.Items = {'4800', '9600', '19200', '38400'};
            app.BaudRateDropDown_ELoad.Enable = 'off';
            app.BaudRateDropDown_ELoad.Value = '38400';
            app.TimeoutEditFieldLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.TimeoutEditFieldLabel_2.Enable = 'off';
            app.TimeoutEditFieldLabel_2.Text = 'Timeout';
            app.TimeoutEditField_ELoad = uieditfield(app.SerialPortGrid_ELoad, 'text');
            app.TimeoutEditField_ELoad.Enable = 'off';
            app.TimeoutEditField_ELoad.Tooltip = {'in Seconds'};
            app.TimeoutEditField_ELoad.Value = '10';
            app.ByteOrderDropDownLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.ByteOrderDropDownLabel_3.Enable = 'off';
            app.ByteOrderDropDownLabel_3.Text = 'Byte Order';
            app.ByteOrderDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.ByteOrderDropDown_ELoad.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_ELoad.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_ELoad.Enable = 'off';
            app.ByteOrderDropDown_ELoad.Value = 'big-endian';
            app.TerminatorLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.TerminatorLabel_3.Enable = 'off';
            app.TerminatorLabel_3.Text = 'Terminator';
            app.TerminatorDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.TerminatorDropDown_ELoad.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_ELoad.Enable = 'off';
            app.TerminatorDropDown_ELoad.Value = 'LF';
            app.ResponseTextAreaLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.ResponseTextAreaLabel_3.Enable = 'off';
            app.ResponseTextAreaLabel_3.Text = 'Response';
            app.ResponseTextArea_ELoad = uitextarea(app.SerialPortGrid_ELoad);
            app.ResponseTextArea_ELoad.ValueChangedFcn = createCallbackFcn(app, @ResponseTextArea_ELoadValueChanged, true);
            app.ResponseTextArea_ELoad.Editable = 'off';
            app.ResponseTextArea_ELoad.Enable = 'off';
            app.SetControlOwnerListBoxLabel_2 = uilabel(app.SerialPortGrid_ELoad);
            app.SetControlOwnerListBoxLabel_2.Enable = 'off';
            app.SetControlOwnerListBoxLabel_2.Text = 'Set Control Owner';
            app.SetControlOwnerListBox_ELoad = uilistbox(app.SerialPortGrid_ELoad);
            app.SetControlOwnerListBox_ELoad.Items = {'Software', 'Local Device'};
            app.SetControlOwnerListBox_ELoad.ItemsData = {'remote', 'local'};
            app.SetControlOwnerListBox_ELoad.ValueChangedFcn = createCallbackFcn(app, @SetControlOwnerListBox_ELoadValueChanged, true);
            app.SetControlOwnerListBox_ELoad.Enable = 'off';
            app.SetControlOwnerListBox_ELoad.Value = 'remote';
            app.StopBitsDropDownLabel_3 = uilabel(app.SerialPortGrid_ELoad);
            app.StopBitsDropDownLabel_3.Enable = 'off';
            app.StopBitsDropDownLabel_3.Text = 'Stop Bits';
            app.StopBitsDropDown_ELoad = uidropdown(app.SerialPortGrid_ELoad);
            app.StopBitsDropDown_ELoad.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_ELoad.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_ELoad.Editable = 'on';
            app.StopBitsDropDown_ELoad.Enable = 'off';
            app.StopBitsDropDown_ELoad.Value = '1';
            app.GPIBPanel = uipanel(app.GridLayout4_2);
            app.GPIBPanel.Title = 'GPIB';
            app.GridLayout5_2 = uigridlayout(app.GridLayout3_2);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.ELoadLabel = uilabel(app.GridLayout6_2);
            app.ELoadLabel.Text = 'E-Load';
            app.ELoadDropDown = uidropdown(app.GridLayout6_2);
            app.ELoadDropDown.Items = {'Select...', 'Array'};
            app.ELoadDropDown.ItemsData = {'', 'array'};
            app.ELoadDropDown.ValueChangedFcn = createCallbackFcn(app, @ELoadDropDownValueChanged, true);
            app.ELoadDropDown.Value = '';
            app.ConnectonMethodGrid_ELoad = uigridlayout(app.GridLayout5_2);
            app.ConnectionmethodDropDownLabel_2 = uilabel(app.ConnectonMethodGrid_ELoad);
            app.ConnectionmethodDropDownLabel_2.Text = 'Connection method';
            app.ConnectionmethodDropDown_ELoad = uidropdown(app.ConnectonMethodGrid_ELoad);
            app.ConnectionmethodDropDown_ELoad.Items = {'Don''t Connect', 'Serial Port'};
            app.ConnectionmethodDropDown_ELoad.ItemsData = {'', 'serial'};
            app.ConnectionmethodDropDown_ELoad.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_ELoadValueChanged, true);
            app.ConnectionmethodDropDown_ELoad.Value = '';
            app.ConnectELoadButton = uibutton(app.ConnectonMethodGrid_ELoad, 'state');
            app.ConnectELoadButton.ValueChangedFcn = createCallbackFcn(app, @ConnectELoadButtonValueChanged, true);
            app.ConnectELoadButton.Enable = 'off';
            app.ConnectELoadButton.Text = 'Connect E-Load';
            app.SelfTestCheckBox_ELoad = uicheckbox(app.ConnectonMethodGrid_ELoad);
            app.SelfTestCheckBox_ELoad.Text = 'Self Test';
            app.TemperatureModuleTab = uitab(app.TabGroup);
            app.TemperatureModuleTab.Title = 'Temperature Module';
            app.GridLayout3_3 = uigridlayout(app.TemperatureModuleTab);
            app.GridLayout4_3 = uigridlayout(app.GridLayout3_3);
            app.SerialPortPanel_TempMod = uipanel(app.GridLayout4_3);
            app.SerialPortPanel_TempMod.Title = 'SERIAL PORT';
            app.SerialPortGrid_TempMod = uigridlayout(app.SerialPortPanel_TempMod);
            app.COMPortDropDownLabel_3 = uilabel(app.SerialPortGrid_TempMod);
            app.COMPortDropDownLabel_3.Enable = 'off';
            app.COMPortDropDownLabel_3.Text = 'COM Port';
            app.COMPortDropDown_TempMod = uidropdown(app.SerialPortGrid_TempMod);
            app.COMPortDropDown_TempMod.Items = {'Select COM Port'};
            app.COMPortDropDown_TempMod.Enable = 'off';
            app.COMPortDropDown_TempMod.Value = 'Select COM Port';
            app.BaudRateDropDownLabel_3 = uilabel(app.SerialPortGrid_TempMod);
            app.BaudRateDropDownLabel_3.Enable = 'off';
            app.BaudRateDropDownLabel_3.Text = 'Baud Rate';
            app.BaudRateDropDown_TempMod = uidropdown(app.SerialPortGrid_TempMod);
            app.BaudRateDropDown_TempMod.Items = {'4800', '9600', '19200', '38400'};
            app.BaudRateDropDown_TempMod.Enable = 'off';
            app.BaudRateDropDown_TempMod.Value = '38400';
            app.TimeoutEditFieldLabel_3 = uilabel(app.SerialPortGrid_TempMod);
            app.TimeoutEditFieldLabel_3.Enable = 'off';
            app.TimeoutEditFieldLabel_3.Text = 'Timeout';
            app.TimeoutEditField_TempMod = uieditfield(app.SerialPortGrid_TempMod, 'text');
            app.TimeoutEditField_TempMod.Enable = 'off';
            app.TimeoutEditField_TempMod.Tooltip = {'in Seconds'};
            app.TimeoutEditField_TempMod.Value = '10';
            app.ByteOrderDropDownLabel_4 = uilabel(app.SerialPortGrid_TempMod);
            app.ByteOrderDropDownLabel_4.Enable = 'off';
            app.ByteOrderDropDownLabel_4.Text = 'Byte Order';
            app.ByteOrderDropDown_TempMod = uidropdown(app.SerialPortGrid_TempMod);
            app.ByteOrderDropDown_TempMod.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_TempMod.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_TempMod.Enable = 'off';
            app.ByteOrderDropDown_TempMod.Value = 'big-endian';
            app.TerminatorLabel_4 = uilabel(app.SerialPortGrid_TempMod);
            app.TerminatorLabel_4.Enable = 'off';
            app.TerminatorLabel_4.Text = 'Terminator';
            app.TerminatorDropDown_TempMod = uidropdown(app.SerialPortGrid_TempMod);
            app.TerminatorDropDown_TempMod.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_TempMod.Enable = 'off';
            app.TerminatorDropDown_TempMod.Value = 'LF';
            app.ResponseTextAreaLabel_4 = uilabel(app.SerialPortGrid_TempMod);
            app.ResponseTextAreaLabel_4.Enable = 'off';
            app.ResponseTextAreaLabel_4.Text = 'Response';
            app.ResponseTextArea_TempMod = uitextarea(app.SerialPortGrid_TempMod);
            app.ResponseTextArea_TempMod.ValueChangedFcn = createCallbackFcn(app, @ResponseTextArea_TempModValueChanged, true);
            app.ResponseTextArea_TempMod.Editable = 'off';
            app.ResponseTextArea_TempMod.Enable = 'off';
            app.StopBitsDropDownLabel_4 = uilabel(app.SerialPortGrid_TempMod);
            app.StopBitsDropDownLabel_4.Enable = 'off';
            app.StopBitsDropDownLabel_4.Text = 'Stop Bits';
            app.StopBitsDropDown_TempMod = uidropdown(app.SerialPortGrid_TempMod);
            app.StopBitsDropDown_TempMod.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_TempMod.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_TempMod.Editable = 'on';
            app.StopBitsDropDown_TempMod.Enable = 'off';
            app.StopBitsDropDown_TempMod.Value = '1';
            app.GetTempDataButton_TempMod = uibutton(app.SerialPortGrid_TempMod, 'push');
            app.GetTempDataButton_TempMod.ButtonPushedFcn = createCallbackFcn(app, @GetTempDataButton_TempModPushed, true);
            app.GetTempDataButton_TempMod.Enable = 'off';
            app.GetTempDataButton_TempMod.Text = 'Get Temp Data';
            app.ConnectedChannelsListBoxLabel = uilabel(app.SerialPortGrid_TempMod);
            app.ConnectedChannelsListBoxLabel.Enable = 'off';
            app.ConnectedChannelsListBoxLabel.Text = 'Connected Channels';
            app.ConnectedChannelsListBox = uilistbox(app.SerialPortGrid_TempMod);
            app.ConnectedChannelsListBox.Items = {'Ch 01', 'Ch 02', 'Ch 03', 'Ch 04', 'Ch 05', 'Ch 06', 'Ch 07', 'Ch 08', 'Ch 09', 'Ch 10', 'Ch 11', 'Ch 12', 'Ch 13', 'Ch 14', 'Ch 15', 'Ch 16'};
            app.ConnectedChannelsListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.ConnectedChannelsListBox.Multiselect = 'on';
            app.ConnectedChannelsListBox.Enable = 'off';
            app.ConnectedChannelsListBox.Value = {'9'};
            app.GPIBPanel_TempMod = uipanel(app.GridLayout4_3);
            app.GPIBPanel_TempMod.Title = 'GPIB';
            app.GridLayout5_3 = uigridlayout(app.GridLayout3_3);
            app.GridLayout6_3 = uigridlayout(app.GridLayout5_3);
            app.TempModLabel = uilabel(app.GridLayout6_3);
            app.TempModLabel.Text = 'Temp. Mod';
            app.TempModDropDown = uidropdown(app.GridLayout6_3);
            app.TempModDropDown.Items = {'Select ...', '16Ch Module', 'Arduino Module', 'DC2100A'};
            app.TempModDropDown.ItemsData = {'', 'TC16ch', 'ArdMod', 'DC2100A'};
            app.TempModDropDown.ValueChangedFcn = createCallbackFcn(app, @TempModDropDownValueChanged, true);
            app.TempModDropDown.Value = '';
            app.ConnectonMethodGrid_TempMod = uigridlayout(app.GridLayout5_3);
            app.ConnectionmethodDropDownLabel_3 = uilabel(app.ConnectonMethodGrid_TempMod);
            app.ConnectionmethodDropDownLabel_3.Text = 'Connection method';
            app.ConnectionmethodDropDown_TempMod = uidropdown(app.ConnectonMethodGrid_TempMod);
            app.ConnectionmethodDropDown_TempMod.Items = {'Don''t Connect', 'ModBus', 'Serial Port'};
            app.ConnectionmethodDropDown_TempMod.ItemsData = {'', 'modbus', 'serial'};
            app.ConnectionmethodDropDown_TempMod.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_TempModValueChanged, true);
            app.ConnectionmethodDropDown_TempMod.Value = '';
            app.ConnectTempModButton = uibutton(app.ConnectonMethodGrid_TempMod, 'state');
            app.ConnectTempModButton.ValueChangedFcn = createCallbackFcn(app, @ConnectTempModButtonValueChanged, true);
            app.ConnectTempModButton.Enable = 'off';
            app.ConnectTempModButton.Text = 'Connect Temp. Module';
            app.SelfTestCheckBox_TempMod = uicheckbox(app.ConnectonMethodGrid_TempMod);
            app.SelfTestCheckBox_TempMod.Text = 'Self Test';
            app.BalancerTab = uitab(app.TabGroup);
            app.BalancerTab.Title = 'Balancer';
            app.SystemMCUTab = uitab(app.TabGroup);
            app.SystemMCUTab.Title = 'System MCU';
            app.GridLayout8 = uigridlayout(app.SystemMCUTab);
            app.GridLayout5_4 = uigridlayout(app.GridLayout8);
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_4);
            app.MCUDropDownLabel = uilabel(app.GridLayout6_4);
            app.MCUDropDownLabel.Text = 'MCU';
            app.SysMCUDropDown = uidropdown(app.GridLayout6_4);
            app.SysMCUDropDown.Items = {'Select ...', 'Arduino', 'LabJack U3-HV'};
            app.SysMCUDropDown.ItemsData = {'', 'ArdMCU', 'LJMCU'};
            app.SysMCUDropDown.ValueChangedFcn = createCallbackFcn(app, @SysMCUDropDownValueChanged, true);
            app.SysMCUDropDown.Value = 'LJMCU';
            app.ConnectonMethodGrid_SysMCU = uigridlayout(app.GridLayout5_4);
            app.ConnectionmethodDropDownLabel_4 = uilabel(app.ConnectonMethodGrid_SysMCU);
            app.ConnectionmethodDropDownLabel_4.Text = 'Connection method';
            app.ConnectionmethodDropDown_SysMCU = uidropdown(app.ConnectonMethodGrid_SysMCU);
            app.ConnectionmethodDropDown_SysMCU.Items = {'Don''t Connect', 'Serial Port', 'HID Device'};
            app.ConnectionmethodDropDown_SysMCU.ItemsData = {'', 'serial', 'hid'};
            app.ConnectionmethodDropDown_SysMCU.ValueChangedFcn = createCallbackFcn(app, @ConnectionmethodDropDown_SysMCUValueChanged, true);
            app.ConnectionmethodDropDown_SysMCU.Value = '';
            app.ConnectSysMCUButton = uibutton(app.ConnectonMethodGrid_SysMCU, 'state');
            app.ConnectSysMCUButton.ValueChangedFcn = createCallbackFcn(app, @ConnectSysMCUButtonValueChanged, true);
            app.ConnectSysMCUButton.Enable = 'off';
            app.ConnectSysMCUButton.Text = 'Connect MCU';
            app.SelfTestCheckBox_SysMCU = uicheckbox(app.ConnectonMethodGrid_SysMCU);
            app.SelfTestCheckBox_SysMCU.Text = 'Self Test';
            app.RelayStateButtonGroup = uibuttongroup(app.GridLayout5_4);
            app.RelayStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RelayStateButtonGroupSelectionChanged, true);
            app.RelayStateButtonGroup.Title = 'Relay State';
            app.DeactivateRelayButton = uitogglebutton(app.RelayStateButtonGroup);
            app.DeactivateRelayButton.Enable = 'off';
            app.DeactivateRelayButton.Tooltip = {'Places the relay in the OFF position or the System in Discharge Mode'};
            app.DeactivateRelayButton.Text = 'Deactivate Relay';
            app.DeactivateRelayButton.Value = true;
            app.ActivateRelayButton = uitogglebutton(app.RelayStateButtonGroup);
            app.ActivateRelayButton.Enable = 'off';
            app.ActivateRelayButton.Tooltip = {'Places the relay in the ON position or the System in Charge Mode'};
            app.ActivateRelayButton.Text = 'Activate Relay';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.ConnectionPanel_SysMCU = uipanel(app.GridLayout9);
            app.ConnectionPanel_SysMCU.Title = 'Connection Method';
            app.ConnectionGrid_SysMCU = uigridlayout(app.ConnectionPanel_SysMCU);
            app.ResponseTextAreaLabel_5 = uilabel(app.ConnectionGrid_SysMCU);
            app.ResponseTextAreaLabel_5.Enable = 'off';
            app.ResponseTextAreaLabel_5.Text = 'Response';
            app.ResponseTextArea_SysMCU = uitextarea(app.ConnectionGrid_SysMCU);
            app.ResponseTextArea_SysMCU.Editable = 'off';
            app.ResponseTextArea_SysMCU.Enable = 'off';
            app.SerialPanel_SysMCU = uipanel(app.ConnectionGrid_SysMCU);
            app.SerialPanel_SysMCU.Title = 'Serial';
            app.GridLayout11 = uigridlayout(app.SerialPanel_SysMCU);
            app.COMPortDropDownLabel_4 = uilabel(app.GridLayout11);
            app.COMPortDropDownLabel_4.Enable = 'off';
            app.COMPortDropDownLabel_4.Text = 'COM Port';
            app.COMPortDropDown_SysMCU = uidropdown(app.GridLayout11);
            app.COMPortDropDown_SysMCU.Items = {'Select COM Port'};
            app.COMPortDropDown_SysMCU.Enable = 'off';
            app.COMPortDropDown_SysMCU.Value = 'Select COM Port';
            app.BaudRateDropDownLabel_4 = uilabel(app.GridLayout11);
            app.BaudRateDropDownLabel_4.Enable = 'off';
            app.BaudRateDropDownLabel_4.Text = 'Baud Rate';
            app.BaudRateDropDown_SysMCU = uidropdown(app.GridLayout11);
            app.BaudRateDropDown_SysMCU.Items = {'4800', '9600', '19200', '38400'};
            app.BaudRateDropDown_SysMCU.Enable = 'off';
            app.BaudRateDropDown_SysMCU.Value = '38400';
            app.ByteOrderDropDownLabel_5 = uilabel(app.GridLayout11);
            app.ByteOrderDropDownLabel_5.Enable = 'off';
            app.ByteOrderDropDownLabel_5.Text = 'Byte Order';
            app.ByteOrderDropDown_SysMCU = uidropdown(app.GridLayout11);
            app.ByteOrderDropDown_SysMCU.Items = {'little-endian(LSB[yte])', 'big-endian (MSB[yte])'};
            app.ByteOrderDropDown_SysMCU.ItemsData = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown_SysMCU.Enable = 'off';
            app.ByteOrderDropDown_SysMCU.Value = 'big-endian';
            app.StopBitsDropDownLabel_5 = uilabel(app.GridLayout11);
            app.StopBitsDropDownLabel_5.Enable = 'off';
            app.StopBitsDropDownLabel_5.Text = 'Stop Bits';
            app.StopBitsDropDown_SysMCU = uidropdown(app.GridLayout11);
            app.StopBitsDropDown_SysMCU.Items = {'1', '1.5', '2'};
            app.StopBitsDropDown_SysMCU.ItemsData = {'1', '1.5', '2'};
            app.StopBitsDropDown_SysMCU.Editable = 'on';
            app.StopBitsDropDown_SysMCU.Enable = 'off';
            app.StopBitsDropDown_SysMCU.Value = '1';
            app.TerminatorLabel_5 = uilabel(app.GridLayout11);
            app.TerminatorLabel_5.Enable = 'off';
            app.TerminatorLabel_5.Text = 'Terminator';
            app.TerminatorDropDown_SysMCU = uidropdown(app.GridLayout11);
            app.TerminatorDropDown_SysMCU.Items = {'LF', 'CR', 'CR/LF'};
            app.TerminatorDropDown_SysMCU.Enable = 'off';
            app.TerminatorDropDown_SysMCU.Value = 'LF';
            app.TimeoutEditFieldLabel_4 = uilabel(app.GridLayout11);
            app.TimeoutEditFieldLabel_4.Enable = 'off';
            app.TimeoutEditFieldLabel_4.Text = 'Timeout';
            app.TimeoutEditField_SysMCU = uieditfield(app.GridLayout11, 'text');
            app.TimeoutEditField_SysMCU.Enable = 'off';
            app.TimeoutEditField_SysMCU.Tooltip = {'in Seconds'};
            app.TimeoutEditField_SysMCU.Value = '10';
            app.HIDDevicePanel_SysMCU = uipanel(app.ConnectionGrid_SysMCU);
            app.HIDDevicePanel_SysMCU.Title = 'HID Device';
            app.PinSettingsPanel = uipanel(app.GridLayout9);
            app.PinSettingsPanel.Title = 'Pin Settings';
            app.GridLayout10 = uigridlayout(app.PinSettingsPanel);
            app.DCTriggerPinsPanel = uipanel(app.GridLayout10);
            app.DCTriggerPinsPanel.Title = 'DC Trigger Pins';
            app.Trigger1Checkbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger1Checkbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger1Checkbox_SysMCUValueChanged, true);
            app.Trigger1Checkbox_SysMCU.Text = ' ';
            app.Trigger2Checkbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger2Checkbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger2Checkbox_SysMCUValueChanged, true);
            app.Trigger2Checkbox_SysMCU.Text = ' ';
            app.Trigger3Checkbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger3Checkbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger3Checkbox_SysMCUValueChanged, true);
            app.Trigger3Checkbox_SysMCU.Text = ' ';
            app.Trigger4Checkbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger4Checkbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger4Checkbox_SysMCUValueChanged, true);
            app.Trigger4Checkbox_SysMCU.Text = ' ';
            app.Trigger5Checkbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger5Checkbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger5Checkbox_SysMCUValueChanged, true);
            app.Trigger5Checkbox_SysMCU.Text = ' ';
            app.ActivateButton_1 = uibutton(app.DCTriggerPinsPanel, 'state');
            app.ActivateButton_1.ValueChangedFcn = createCallbackFcn(app, @ActivateButton_1ValueChanged, true);
            app.ActivateButton_1.Enable = 'off';
            app.ActivateButton_1.Text = 'Activate';
            app.ActivateButton_2 = uibutton(app.DCTriggerPinsPanel, 'state');
            app.ActivateButton_2.ValueChangedFcn = createCallbackFcn(app, @ActivateButton_2ValueChanged, true);
            app.ActivateButton_2.Enable = 'off';
            app.ActivateButton_2.Text = 'Activate';
            app.ActivateButton_3 = uibutton(app.DCTriggerPinsPanel, 'state');
            app.ActivateButton_3.ValueChangedFcn = createCallbackFcn(app, @ActivateButton_3ValueChanged, true);
            app.ActivateButton_3.Enable = 'off';
            app.ActivateButton_3.Text = 'Activate';
            app.ActivateButton_4 = uibutton(app.DCTriggerPinsPanel, 'state');
            app.ActivateButton_4.ValueChangedFcn = createCallbackFcn(app, @ActivateButton_4ValueChanged, true);
            app.ActivateButton_4.Enable = 'off';
            app.ActivateButton_4.Text = 'Activate';
            app.ActivateButton_5 = uibutton(app.DCTriggerPinsPanel, 'state');
            app.ActivateButton_5.ValueChangedFcn = createCallbackFcn(app, @ActivateButton_5ValueChanged, true);
            app.ActivateButton_5.Enable = 'off';
            app.ActivateButton_5.Text = 'Activate';
            app.Trigger1SpinnerLabel = uilabel(app.DCTriggerPinsPanel);
            app.Trigger1SpinnerLabel.Text = 'Trigger 1';
            app.Trigger1Spinner = uispinner(app.DCTriggerPinsPanel);
            app.Trigger1Spinner.Limits = [0 100];
            app.Trigger1Spinner.ValueDisplayFormat = '%3d';
            app.Trigger1Spinner.Enable = 'off';
            app.Trigger1Spinner.Value = 4;
            app.Trigger2SpinnerLabel = uilabel(app.DCTriggerPinsPanel);
            app.Trigger2SpinnerLabel.Text = 'Trigger 2';
            app.Trigger2Spinner = uispinner(app.DCTriggerPinsPanel);
            app.Trigger2Spinner.Limits = [0 100];
            app.Trigger2Spinner.ValueDisplayFormat = '%3d';
            app.Trigger2Spinner.Enable = 'off';
            app.Trigger3SpinnerLabel = uilabel(app.DCTriggerPinsPanel);
            app.Trigger3SpinnerLabel.Text = 'Trigger 3';
            app.Trigger3Spinner = uispinner(app.DCTriggerPinsPanel);
            app.Trigger3Spinner.Limits = [0 100];
            app.Trigger3Spinner.ValueDisplayFormat = '%3d';
            app.Trigger3Spinner.Enable = 'off';
            app.Trigger4SpinnerLabel = uilabel(app.DCTriggerPinsPanel);
            app.Trigger4SpinnerLabel.Text = 'Trigger 4';
            app.Trigger4Spinner = uispinner(app.DCTriggerPinsPanel);
            app.Trigger4Spinner.Limits = [0 100];
            app.Trigger4Spinner.ValueDisplayFormat = '%3d';
            app.Trigger4Spinner.Enable = 'off';
            app.Trigger5SpinnerLabel = uilabel(app.DCTriggerPinsPanel);
            app.Trigger5SpinnerLabel.Text = 'Trigger 5';
            app.Trigger5Spinner = uispinner(app.DCTriggerPinsPanel);
            app.Trigger5Spinner.Limits = [0 100];
            app.Trigger5Spinner.ValueDisplayFormat = '%3d';
            app.Trigger5Spinner.Enable = 'off';
            app.Trigger1InvertCheckbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger1InvertCheckbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger1InvertCheckbox_SysMCUValueChanged, true);
            app.Trigger1InvertCheckbox_SysMCU.Enable = 'off';
            app.Trigger1InvertCheckbox_SysMCU.Tooltip = {'Invert Trigger Output'};
            app.Trigger1InvertCheckbox_SysMCU.Text = ' ';
            app.Trigger2InvertCheckbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger2InvertCheckbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger2InvertCheckbox_SysMCUValueChanged, true);
            app.Trigger2InvertCheckbox_SysMCU.Enable = 'off';
            app.Trigger2InvertCheckbox_SysMCU.Tooltip = {'Invert Trigger Output'};
            app.Trigger2InvertCheckbox_SysMCU.Text = ' ';
            app.Trigger3InvertCheckbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger3InvertCheckbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger3InvertCheckbox_SysMCUValueChanged, true);
            app.Trigger3InvertCheckbox_SysMCU.Enable = 'off';
            app.Trigger3InvertCheckbox_SysMCU.Tooltip = {'Invert Trigger Output'};
            app.Trigger3InvertCheckbox_SysMCU.Text = ' ';
            app.Trigger4InvertCheckbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger4InvertCheckbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger4InvertCheckbox_SysMCUValueChanged, true);
            app.Trigger4InvertCheckbox_SysMCU.Enable = 'off';
            app.Trigger4InvertCheckbox_SysMCU.Tooltip = {'Invert Trigger Output'};
            app.Trigger4InvertCheckbox_SysMCU.Text = ' ';
            app.Trigger5InvertCheckbox_SysMCU = uicheckbox(app.DCTriggerPinsPanel);
            app.Trigger5InvertCheckbox_SysMCU.ValueChangedFcn = createCallbackFcn(app, @Trigger5InvertCheckbox_SysMCUValueChanged, true);
            app.Trigger5InvertCheckbox_SysMCU.Enable = 'off';
            app.Trigger5InvertCheckbox_SysMCU.Tooltip = {'Invert Trigger Output'};
            app.Trigger5InvertCheckbox_SysMCU.Text = ' ';
            app.InvertLabel = uilabel(app.DCTriggerPinsPanel);
            app.InvertLabel.Text = 'Invert';
            app.EnableLabel = uilabel(app.DCTriggerPinsPanel);
            app.EnableLabel.Text = 'Enable';
            app.StartTimeEditField1 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.StartTimeEditField1.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField1ValueChanged, true);
            app.StartTimeEditField1.Enable = 'off';
            app.StartTimeEditField1.Tooltip = {'Enter start times (in seconds) for when the triggers will occur after the program starts. Values should be seperated by commas. E.g. 20, 100, 210'};
            app.DurationEditField1 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.DurationEditField1.ValueChangedFcn = createCallbackFcn(app, @DurationEditField1ValueChanged, true);
            app.DurationEditField1.Enable = 'off';
            app.DurationEditField1.Tooltip = {'Enter durations (in seconds) for how long the trigger will last after each start time. Values should be seperated by commas. E.g. 20, 5, 8'};
            app.StartTimesLabel = uilabel(app.DCTriggerPinsPanel);
            app.StartTimesLabel.Text = 'Start Times';
            app.DurationsLabel = uilabel(app.DCTriggerPinsPanel);
            app.DurationsLabel.Text = 'Durations';
            app.StartTimeEditField2 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.StartTimeEditField2.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField2ValueChanged, true);
            app.StartTimeEditField2.Enable = 'off';
            app.StartTimeEditField2.Tooltip = {'Enter start times (in seconds) for when the triggers will occur after the program starts. Values should be seperated by commas. E.g. 20, 100, 210'};
            app.DurationEditField2 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.DurationEditField2.ValueChangedFcn = createCallbackFcn(app, @DurationEditField2ValueChanged, true);
            app.DurationEditField2.Enable = 'off';
            app.DurationEditField2.Tooltip = {'Enter durations (in seconds) for how long the trigger will last after each start time. Values should be seperated by commas. E.g. 20, 5, 8'};
            app.StartTimeEditField3 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.StartTimeEditField3.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField3ValueChanged, true);
            app.StartTimeEditField3.Enable = 'off';
            app.StartTimeEditField3.Tooltip = {'Enter start times (in seconds) for when the triggers will occur after the program starts. Values should be seperated by commas. E.g. 20, 100, 210'};
            app.DurationEditField3 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.DurationEditField3.ValueChangedFcn = createCallbackFcn(app, @DurationEditField3ValueChanged, true);
            app.DurationEditField3.Enable = 'off';
            app.DurationEditField3.Tooltip = {'Enter durations (in seconds) for how long the trigger will last after each start time. Values should be seperated by commas. E.g. 20, 5, 8'};
            app.StartTimeEditField4 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.StartTimeEditField4.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField4ValueChanged, true);
            app.StartTimeEditField4.Enable = 'off';
            app.StartTimeEditField4.Tooltip = {'Enter start times (in seconds) for when the triggers will occur after the program starts. Values should be seperated by commas. E.g. 20, 100, 210'};
            app.DurationEditField4 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.DurationEditField4.ValueChangedFcn = createCallbackFcn(app, @DurationEditField4ValueChanged, true);
            app.DurationEditField4.Enable = 'off';
            app.DurationEditField4.Tooltip = {'Enter durations (in seconds) for how long the trigger will last after each start time. Values should be seperated by commas. E.g. 20, 5, 8'};
            app.StartTimeEditField5 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.StartTimeEditField5.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditField5ValueChanged, true);
            app.StartTimeEditField5.Enable = 'off';
            app.StartTimeEditField5.Tooltip = {'Enter start times (in seconds) for when the triggers will occur after the program starts. Values should be seperated by commas. E.g. 20, 100, 210'};
            app.DurationEditField5 = uieditfield(app.DCTriggerPinsPanel, 'text');
            app.DurationEditField5.ValueChangedFcn = createCallbackFcn(app, @DurationEditField5ValueChanged, true);
            app.DurationEditField5.Enable = 'off';
            app.DurationEditField5.Tooltip = {'Enter durations (in seconds) for how long the trigger will last after each start time. Values should be seperated by commas. E.g. 20, 5, 8'};
            app.RelayPinsPanel_2 = uipanel(app.GridLayout10);
            app.RelayPinsPanel_2.Title = 'Relay Pins';
            app.RelayPinLabel = uilabel(app.RelayPinsPanel_2);
            app.RelayPinLabel.Text = 'Relay Pin (+)';
            app.Relay1Spinner_SysMCU = uispinner(app.RelayPinsPanel_2);
            app.Relay1Spinner_SysMCU.Limits = [0 100];
            app.Relay1Spinner_SysMCU.ValueDisplayFormat = '%3d';
            app.Relay1Spinner_SysMCU.Tooltip = {'Pin where the positive line relay is connected'};
            app.RelayPinLabel_2 = uilabel(app.RelayPinsPanel_2);
            app.RelayPinLabel_2.Text = 'Relay Pin (-)';
            app.Relay2Spinner_SysMCU = uispinner(app.RelayPinsPanel_2);
            app.Relay2Spinner_SysMCU.Limits = [0 100];
            app.Relay2Spinner_SysMCU.ValueDisplayFormat = '%3d';
            app.SignalMeasurementPinsPanel = uipanel(app.GridLayout10);
            app.SignalMeasurementPinsPanel.Title = 'Signal Measurement Pins';
            app.VoltagePinLabel = uilabel(app.SignalMeasurementPinsPanel);
            app.VoltagePinLabel.Text = 'Voltage Pin (+)';
            app.VoltagePlusSpinner_SysMCU = uispinner(app.SignalMeasurementPinsPanel);
            app.VoltagePlusSpinner_SysMCU.Limits = [0 100];
            app.VoltagePlusSpinner_SysMCU.ValueDisplayFormat = '%3d';
            app.VoltagePinLabel_2 = uilabel(app.SignalMeasurementPinsPanel);
            app.VoltagePinLabel_2.Text = 'Voltage Pin (-)';
            app.VoltageMinusSpinner_SysMCU = uispinner(app.SignalMeasurementPinsPanel);
            app.VoltageMinusSpinner_SysMCU.Limits = [0 100];
            app.VoltageMinusSpinner_SysMCU.ValueDisplayFormat = '%3d';
            app.CurrentPinLabel = uilabel(app.SignalMeasurementPinsPanel);
            app.CurrentPinLabel.Enable = 'off';
            app.CurrentPinLabel.Text = 'Current Pin (+)';
            app.CurrentPlusSpinner_SysMCU = uispinner(app.SignalMeasurementPinsPanel);
            app.CurrentPlusSpinner_SysMCU.Limits = [0 100];
            app.CurrentPlusSpinner_SysMCU.ValueDisplayFormat = '%3d';
            app.CurrentPlusSpinner_SysMCU.Enable = 'off';
            app.CurrentPinLabel_2 = uilabel(app.SignalMeasurementPinsPanel);
            app.CurrentPinLabel_2.Enable = 'off';
            app.CurrentPinLabel_2.Text = 'Current Pin (-)';
            app.CurrentMinusSpinner_SysMCU = uispinner(app.SignalMeasurementPinsPanel);
            app.CurrentMinusSpinner_SysMCU.Limits = [0 100];
            app.CurrentMinusSpinner_SysMCU.ValueDisplayFormat = '%3d';
            app.CurrentMinusSpinner_SysMCU.Enable = 'off';
            app.TextBoxContextMenu_EStop = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.TextBoxContextMenu_EStop
            app.ResponseTextArea_EStop.ContextMenu = app.TextBoxContextMenu_EStop;
            app.ClearMenu_ELoad = uimenu(app.TextBoxContextMenu_EStop);
            app.ClearMenu_ELoad.MenuSelectedFcn = createCallbackFcn(app, @ClearMenu_ELoadSelected, true);
            app.ClearMenu_ELoad.Text = 'Clear';
            app.TextBoxContextMenu_PSU = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.TextBoxContextMenu_PSU
            app.ResponseTextArea_PSU.ContextMenu = app.TextBoxContextMenu_PSU;
            app.ClearMenu_PSU = uimenu(app.TextBoxContextMenu_PSU);
            app.ClearMenu_PSU.MenuSelectedFcn = createCallbackFcn(app, @ClearMenu_PSUSelected, true);
            app.ClearMenu_PSU.Text = 'Clear';
            app.TextBoxContextMenu_TempMod = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.TextBoxContextMenu_TempMod
            app.ResponseTextArea_TempMod.ContextMenu = app.TextBoxContextMenu_TempMod;
            app.ClearMenu_TempMod = uimenu(app.TextBoxContextMenu_TempMod);
            app.ClearMenu_TempMod.MenuSelectedFcn = createCallbackFcn(app, @ClearMenu_TempModSelected, true);
            app.ClearMenu_TempMod.Text = 'Clear';
            app.CellTreeContextMenu = uicontextmenu(app.devConfigsUIFigure);
            % Assign app.CellTreeContextMenu
            app.Tree.ContextMenu = app.CellTreeContextMenu;
            app.AddCellPackMenu = uimenu(app.CellTreeContextMenu);
            app.AddCellPackMenu.Text = 'Add Cell / Pack';
            app.ExpandMenu = uimenu(app.CellTreeContextMenu);
            app.ExpandMenu.Separator = 'on';
            app.ExpandMenu.Text = 'Expand';
            app.ExpandMenu_2 = uimenu(app.ExpandMenu);
            app.ExpandMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExpandMenu_2Selected, true);
            app.ExpandMenu_2.Text = 'Expand';
            app.ExpandAllMenu = uimenu(app.ExpandMenu);
            app.ExpandAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpandAllMenuSelected, true);
            app.ExpandAllMenu.Text = 'Expand All';
            app.CollapseMenu = uimenu(app.CellTreeContextMenu);
            app.CollapseMenu.Text = 'Collapse';
            app.CollapseMenu_2 = uimenu(app.CollapseMenu);
            app.CollapseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CollapseMenu_2Selected, true);
            app.CollapseMenu_2.Text = 'Collapse';
            app.CollapseAllMenu = uimenu(app.CollapseMenu);
            app.CollapseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CollapseAllMenuSelected, true);
            app.CollapseAllMenu.Text = 'Collapse All';
            app.DeselectAllMenu = uimenu(app.CellTreeContextMenu);
            app.DeselectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectAllMenuSelected, true);
            app.DeselectAllMenu.Separator = 'on';
            app.DeselectAllMenu.Accelerator = 'A';
            app.DeselectAllMenu.Text = 'Deselect All';
            app.CellDeleteContextMenu = uicontextmenu(app.devConfigsUIFigure);
            app.DeleteCellPackMenu = uimenu(app.CellDeleteContextMenu);
            app.DeleteCellPackMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellsButtonPushed, true);
            app.DeleteCellPackMenu.Text = 'Delete Cell / Pack';
            % Show the figure after all components are created
            app.devConfigsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceConfigs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.devConfigsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.devConfigsUIFigure)
        end
    end
end