classdef Dialog_AddCell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddCellsPackUIFigure            matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        CellStackArrangementDropDownLabel  matlab.ui.control.Label
        cellConfigDropDown              matlab.ui.control.DropDown
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        NumberofcellsinParallelSpinnerLabel  matlab.ui.control.Label
        NumberofcellsinParallelSpinner  matlab.ui.control.Spinner
        NumberofcellsinSeriesSpinnerLabel  matlab.ui.control.Label
        NumberofcellsinSeriesSpinner    matlab.ui.control.Spinner
        Tree                            matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        ContextMenu                     matlab.ui.container.ContextMenu
        CutMenu                         matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        PasteMenu                       matlab.ui.container.Menu
        RemoveCellsMenu                 matlab.ui.container.Menu
        DeleteRowsMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        callerApp % App that calls this dialog box
    end
    
    properties (Access = private)
        batteryParam
        packParam
        selectedCells
        dataClipBoard
    end
    
    methods (Access = private)
        
        function updateCellNodes(app)
            app.Tree.Children.delete; % Remove all nodes first
            
            if app.cellConfigDropDown.Value ~= "single"
                
                % First level nodes
                uniqueChemList = sort(unique(app.batteryParam.chemistry));
                len = length(uniqueChemList);
                chems = cell(1, len);
                cellNodes = cell(len, height(app.batteryParam));
                
                for i = 1:length(uniqueChemList)
                    chems{i} = uitreenode(app.Tree,'Text', uniqueChemList(i));
                    
                    
                    % Second level nodes
                    rows = (app.batteryParam.chemistry==uniqueChemList(i));
                    cellList = sort(app.batteryParam(rows, "chemistry").Properties.RowNames);
                    
                    for j = 1:length(cellList)
                        cellNodes{i, j} = uitreenode(chems{i},'Text',cellList{j});
                    end
                    chems{i}.expand();
                end
            end
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin == 2
                caller_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(caller_App) && isvalid(caller_App))
                    app.callerApp = caller_App;
                end
            elseif nargin == 3
                caller_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(caller_App) && isvalid(caller_App))
                    app.callerApp = caller_App;
                end
                app.batteryParam = varargin{2};
            elseif nargin == 4
                caller_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(caller_App) && isvalid(caller_App))
                    app.callerApp = caller_App;
                end
                app.batteryParam = varargin{2};
                app.packParam = varargin{3};
            end
            
            % Set initial state of the dialog
            cellConfigDropDownValueChanged(app, []);
            
        end

        % Value changed function: cellConfigDropDown
        function cellConfigDropDownValueChanged(app, event)
            value = app.cellConfigDropDown.Value;
            if value == "single"
                % Hide tree UI for cells in pack configs
                app.Tree.Visible = false;
                app.UITable.Position = [211,15,1029,420];
                
                % Hide Spinners for pack configs
                app.NumberofcellsinSeriesSpinnerLabel.Visible = false;
                app.NumberofcellsinSeriesSpinner.Visible = false;
                app.NumberofcellsinParallelSpinnerLabel.Visible = false;
                app.NumberofcellsinParallelSpinner.Visible = false;
                
                
                app.UITable.ColumnName = ...
                    ["CellID", "Chem" ,"Cap.(Ah)","SOC","Full Chg. Volt.",...
                    "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                    "Max Neg. Curr(A)", "Rated Cap.(Ah)","Max Surf Temp(°C)",...
                    "Max Core Temp(°C)"];
                
                % Get dropdown entries for chemistry column
                if ~isempty(app.batteryParam)
                    cellChems = transpose(cellstr(sort(unique(app.batteryParam.chemistry))));
                else
                    cellChems = [];
                end
                
                
                % Enable Drop down menus for the chemistry column
                app.UITable.ColumnFormat = repmat({'numeric'}, 1, length(app.UITable.ColumnName));
                app.UITable.ColumnFormat{1} = 'char';
                app.UITable.ColumnFormat{2} = cellChems;
                
                % Insert Empty Row Data
                app.UITable.Data = cell(1, length(app.UITable.ColumnName)); % repmat({[]}, 1, length(app.UITable.ColumnName));
                
                % Add Prompt to add a new cell
                rowNames = repmat({[]}, length(app.UITable.Data(:, 1)), 1);
                rowNames{end} = 'Add...';
                app.UITable.RowName = rowNames;
                
                % Configure all columns to be editable
                app.UITable.ColumnEditable = true;
                
                % Assign Context Menu
                app.UITable.ContextMenu = app.ContextMenu;
            else
                
                app.updateCellNodes();
                app.Tree.Visible = true;
                app.UITable.Position = [331,15,909,420];
                
                app.UITable.Data = [];
                app.UITable.ColumnName = [];
                
                app.UITable.ColumnName = ...
                    ["PackID", "Chem" , "Cells", " Stack Shape","Cap.(Ah)","SOC","Full Chg. Volt.",...
                    "Full Dchg. Volt.","End Curr[in CV](A)", "OV (V)","UV (V)","Max Pos. Curr(A)",...
                    "Max Neg. Curr(A)","Rated Cap.(Ah)","Max Surf Temp(°C)",...
                    "Max Core Temp(°C)"];
                
                if ~isempty(app.batteryParam)
                    cellChems = transpose(cellstr(sort(unique(app.batteryParam.chemistry))));
                else
                    cellChems = [];
                end

                % Enable Drop down menus for the chemistry column
                app.UITable.ColumnFormat = repmat({'numeric'}, 1, length(app.UITable.ColumnName));
                app.UITable.ColumnFormat{1} = 'char';
                app.UITable.ColumnFormat{2} = cellChems;
                app.UITable.ColumnFormat{3} = 'char';
                app.UITable.ColumnFormat{4} = 'char';
                
               
                % Insert Empty Row Data
                app.UITable.Data = repmat({[]}, 1, length(app.UITable.ColumnName));
                
                 %#1PackAddition Commented out for 1 pack addition in UITable
                %
                %                 % Add Prompt to add a new cell
%                                 rowNames = repmat({[]}, length(app.UITable.Data(:, 1)), 1);
                %                 rowNames{end} = 'Add...';
                %                 app.UITable.RowName = rowNames;
                app.UITable.RowName = {[]}; %#1PackAddition Added this line for 1 pack addition in UITable
                
                % Configure all columns except cells to be editable
                editables = true(1, length(app.UITable.ColumnName));
                editables(3) = false;
                app.UITable.ColumnEditable = editables;
                
                % Assign Context Menu
                app.UITable.ContextMenu = app.ContextMenu;
            end
            
            if value == "series"
                app.NumberofcellsinSeriesSpinner.Visible = true;
                app.NumberofcellsinSeriesSpinnerLabel.Visible = true;
                app.NumberofcellsinParallelSpinner.Visible = false;
                app.NumberofcellsinParallelSpinnerLabel.Visible = false;
                NumberofcellsinSeriesSpinnerValueChanged(app, []);
            elseif value == "parallel"
                app.NumberofcellsinSeriesSpinner.Visible = false;
                app.NumberofcellsinSeriesSpinnerLabel.Visible = false;
                app.NumberofcellsinParallelSpinner.Visible = true;
                app.NumberofcellsinParallelSpinnerLabel.Visible = true;
                NumberofcellsinParallelSpinnerValueChanged(app, []);
            elseif value == "ser_par"
                app.NumberofcellsinSeriesSpinner.Visible = true;
                app.NumberofcellsinSeriesSpinnerLabel.Visible = true;
                app.NumberofcellsinParallelSpinner.Visible = true;
                app.NumberofcellsinParallelSpinnerLabel.Visible = true;
                NumberofcellsinSeriesSpinnerValueChanged(app, []);
            end
            
            app.SaveButton.Enable = "off";
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if indices(2) == 1
                newData = upper(newData);
                app.UITable.Data{indices(1), indices(2)} = newData;
            end
            
            rowLength = length(app.UITable.Data(:, 1));
            if app.cellConfigDropDown.Value == "single"
                % Check to see if data has been entered to the last line
                % prompting the creation of a new row
                if indices(1) == rowLength
                    app.UITable.Data = [app.UITable.Data; repmat({[]}, 1, length(app.UITable.ColumnName))];
                    rowNames = repmat({[]}, length(app.UITable.Data(:, 1)), 1);
                    rowNames{end} = 'Add...';
                    app.UITable.RowName = rowNames;
                end
                
                % Allow user to only turn on the button if there
                % is a cellID for each row at a minimum
                cellArr2Check = app.UITable.Data(1:rowLength-1, 1);
                if ~max(cellfun(@isempty, cellArr2Check))
                    app.SaveButton.Enable = "on";
                else
                    app.SaveButton.Enable = "off";
                end
            else
                  %#1PackAddition Commented out for 1 pack addition in UITable
%                 if indices(1) == rowLength
%                     app.UITable.Data = [app.UITable.Data; repmat({[]}, 1, length(app.UITable.ColumnName))];
%                     rowNames = repmat({[]}, length(app.UITable.Data(:, 1)), 1);
%                     rowNames{end} = 'Add...';
%                     app.UITable.RowName = rowNames;
%                 end
                
                cellArr2Check = app.UITable.Data(1:rowLength, 1);%#1PackAddition 1:rowLength instead of 1:rowLength-1 for 1 pack addition in UITable
                if length(app.Tree.SelectedNodes) >= 2 && ~max(cellfun(@isempty, cellArr2Check))
                    app.SaveButton.Enable = "on";
                else
                    app.SaveButton.Enable = "off";
                end
            end


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.callerApp.newCellsAdded = app.UITable.Data;
            config = app.cellConfigDropDown.Value;
            params = app.UITable.Data;
            if config == "single"
                params(end, :) = [];
                varNames = app.batteryParam.Properties.VariableNames;
                Tbl = cell2table(params(:, 2:end), 'VariableNames',varNames, 'RowNames',params(:, 1));
                Tbl.minCurr = -abs(Tbl.minCurr);
            else
%                 params(end, :) = [];%#1PackAddition Commented out for 1 pack addition in UITable
                varNames = app.packParam.Properties.VariableNames
                Tbl = cell2table(params(:, 2:end), 'VariableNames',varNames, 'RowNames',params(:, 1));
                Tbl.minCurr = -abs(Tbl.minCurr);
            end
            
            updateBattPackParams(app.callerApp, config, Tbl)
            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedCells = event.Indices;
            %             disp(app.selectedCells)
        end

        % Menu selected function: CutMenu
        function CutMenuSelected(app, event)
            rowLen = length(app.selectedCells(:, 1));
            app.dataClipBoard.row1 = app.selectedCells(1, 1);
            app.dataClipBoard.col1 = app.selectedCells(1, 2);
            app.dataClipBoard.ind_x = zeros(1, rowLen);
            app.dataClipBoard.ind_y = zeros(1, rowLen);
            app.dataClipBoard.Data = cell(1, rowLen);
            for i = 1:rowLen
                app.dataClipBoard.ind_x(i) = app.selectedCells(i, 2) - app.dataClipBoard.col1;
                app.dataClipBoard.ind_y(i) = app.selectedCells(i, 1) - app.dataClipBoard.row1;
                app.dataClipBoard.Data{i} = app.UITable.Data{...
                    app.selectedCells(i, 1),...
                    app.selectedCells(i, 2)};
            end
            
            app.UITable.Data(app.selectedCells(:, 1),...
                    app.selectedCells(:, 2)) = {[]};
            
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            rowLen = length(app.selectedCells(:, 1));
            app.dataClipBoard.row1 = app.selectedCells(1, 1);
            app.dataClipBoard.col1 = app.selectedCells(1, 2);
            app.dataClipBoard.ind_x = zeros(1, rowLen);
            app.dataClipBoard.ind_y = zeros(1, rowLen);
            app.dataClipBoard.Data = cell(1, rowLen);
            for i = 1:rowLen
                app.dataClipBoard.ind_x(i) = app.selectedCells(i, 2) - app.dataClipBoard.col1;
                app.dataClipBoard.ind_y(i) = app.selectedCells(i, 1) - app.dataClipBoard.row1;
                app.dataClipBoard.Data{i} = app.UITable.Data{...
                    app.selectedCells(i, 1),...
                    app.selectedCells(i, 2)};
            end
            
        end

        % Menu selected function: PasteMenu
        function PasteMenuSelected(app, event)
            rowLen = length(app.dataClipBoard.Data);
            ind_x = zeros(1, rowLen);
            ind_y = zeros(1, rowLen);
            
            row1 = app.selectedCells(1, 1);
            col1 = app.selectedCells(1, 2);
            
            for i = 1:rowLen
                ind_x(i) = col1 + app.dataClipBoard.ind_x(i);
                ind_y(i) = row1 + app.dataClipBoard.ind_y(i);
%                 app.UITable.Data{ind_y(i), ind_x(i)} =...
%                     app.dataClipBoard.Data{i};
            end

            i = 0;
            for u = unique(ind_y)
                ind = ind_y == u;
                i = i+1;
                app.UITable.Data(u, ind_x(ind)) =...
                        app.dataClipBoard.Data(i, :);
            end
            
            rowLength = length(app.UITable.Data(:, 1));
            if ind_y(end) >= rowLength
                app.UITable.Data = [app.UITable.Data; repmat({[]}, 1, length(app.UITable.ColumnName))];
                rowNames = repmat({[]}, length(app.UITable.Data(:, 1)), 1);
                rowNames{end} = 'Add...';
                app.UITable.RowName = rowNames;
            end
            
            
        end

        % Menu selected function: RemoveCellsMenu
        function RemoveCellsMenuSelected(app, event)
            app.UITable.Data(app.selectedCells(:, 1),...
                    app.selectedCells(:, 2)) = {[]};
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            len = length(app.UITable.Data(:, 1));
            if  len > 1
                cellArr2Check = app.UITable.Data(1:len-1, 1);
            else
                cellArr2Check = app.UITable.Data(1, 1);
            end
            
            if length(selectedNodes) >= 2 && ~max(cellfun(@isempty, cellArr2Check))
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end
            
            if (~isempty(selectedNodes))
                tree = app.Tree.Children;
                treeNodeTexts = repmat("", 1, length(tree));
                for i = 1:length(tree)
                    treeNodeTexts(i) = string(tree(i).Text);
                end
                
                selectedNodesTexts = repmat("", 1, length(selectedNodes));
                rows2Rmv = [];
                for i = 1:length(selectedNodes)
                    selectedNodesTexts(i) = string(selectedNodes(i).Text);
                    if ismember(selectedNodesTexts(i), treeNodeTexts)
                        rows2Rmv(end+1) = i;
                    end
                end
                
                selectedNodesTexts(rows2Rmv) = []; % Remove the text of selections that are category names instead of cell names
                selectedNodesTexts = sort(selectedNodesTexts, "ascend");
                
                % if there are no proper selections
                if isempty(selectedNodesTexts)
                    return;
                end
            end
            
            app.UITable.Data{len , 3} = char(strjoin(selectedNodesTexts, ', '));
            
        end

        % Value changed function: NumberofcellsinSeriesSpinner
        function NumberofcellsinSeriesSpinnerValueChanged(app, event)
            value = app.NumberofcellsinSeriesSpinner.Value;
            len = length(app.UITable.Data(:, 1));
            if app.cellConfigDropDown.Value == "series"
                stackType = string(value) + "S";
                app.UITable.Data(len, 4) = cellstr(stackType);
            elseif app.cellConfigDropDown.Value == "ser_par"
                value_Par = app.NumberofcellsinParallelSpinner.Value;
                stackType = string(value) + "S" + string(value_Par) + "P";
                app.UITable.Data(len, 4) = cellstr(stackType);
            end
            
        end

        % Value changed function: NumberofcellsinParallelSpinner
        function NumberofcellsinParallelSpinnerValueChanged(app, event)
            value = app.NumberofcellsinParallelSpinner.Value;
            len = length(app.UITable.Data(:, 1));
            if app.cellConfigDropDown.Value == "parallel"
                stackType = string(value) + "P";
                app.UITable.Data(len, 4) = cellstr(stackType);
            elseif app.cellConfigDropDown.Value == "ser_par"
                value_Ser = app.NumberofcellsinSeriesSpinner.Value;
                stackType = string(value_Ser) + "S" + string(value) + "P";
                app.UITable.Data(len, 4) = cellstr(stackType);
            end
        end

        % Menu selected function: DeleteRowsMenu
        function DeleteRowsMenuSelected(app, event)
            app.UITable.Data(app.selectedCells(:, 1), :) = [];
            app.UITable.RowName(app.selectedCells(:, 1)) = [];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddCellsPackUIFigure = uifigure('Visible', 'off');
            app.AddCellsPackUIFigure.Name = 'Add Cells / Pack';
            app.UITable = uitable(app.AddCellsPackUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CellStackArrangementDropDownLabel = uilabel(app.AddCellsPackUIFigure);
            app.CellStackArrangementDropDownLabel.Text = 'Cell Stack Arrangement';
            app.cellConfigDropDown = uidropdown(app.AddCellsPackUIFigure);
            app.cellConfigDropDown.Items = {'Single Cell', 'Series Stack', 'Parallel Stack', 'Series-Parallel Stack'};
            app.cellConfigDropDown.ItemsData = {'single', 'series', 'parallel', 'ser_par'};
            app.cellConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @cellConfigDropDownValueChanged, true);
            app.cellConfigDropDown.Value = 'single';
            app.CancelButton = uibutton(app.AddCellsPackUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.AddCellsPackUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.NumberofcellsinParallelSpinnerLabel = uilabel(app.AddCellsPackUIFigure);
            app.NumberofcellsinParallelSpinnerLabel.Visible = 'off';
            app.NumberofcellsinParallelSpinnerLabel.Text = 'Number of cells in  Parallel';
            app.NumberofcellsinParallelSpinner = uispinner(app.AddCellsPackUIFigure);
            app.NumberofcellsinParallelSpinner.Limits = [2 100];
            app.NumberofcellsinParallelSpinner.ValueDisplayFormat = '%3d';
            app.NumberofcellsinParallelSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcellsinParallelSpinnerValueChanged, true);
            app.NumberofcellsinParallelSpinner.Visible = 'off';
            app.NumberofcellsinParallelSpinner.Value = 2;
            app.NumberofcellsinSeriesSpinnerLabel = uilabel(app.AddCellsPackUIFigure);
            app.NumberofcellsinSeriesSpinnerLabel.Visible = 'off';
            app.NumberofcellsinSeriesSpinnerLabel.Text = 'Number of cells in  Series';
            app.NumberofcellsinSeriesSpinner = uispinner(app.AddCellsPackUIFigure);
            app.NumberofcellsinSeriesSpinner.Limits = [2 100];
            app.NumberofcellsinSeriesSpinner.ValueDisplayFormat = '%3d';
            app.NumberofcellsinSeriesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcellsinSeriesSpinnerValueChanged, true);
            app.NumberofcellsinSeriesSpinner.Visible = 'off';
            app.NumberofcellsinSeriesSpinner.Value = 2;
            app.Tree = uitree(app.AddCellsPackUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Visible = 'off';
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.ContextMenu = uicontextmenu(app.AddCellsPackUIFigure);
            app.CutMenu = uimenu(app.ContextMenu);
            app.CutMenu.MenuSelectedFcn = createCallbackFcn(app, @CutMenuSelected, true);
            app.CutMenu.Accelerator = 'x';
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Accelerator = 'C';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.ContextMenu);
            app.PasteMenu.MenuSelectedFcn = createCallbackFcn(app, @PasteMenuSelected, true);
            app.PasteMenu.Accelerator = 'V';
            app.PasteMenu.Text = 'Paste';
            app.RemoveCellsMenu = uimenu(app.ContextMenu);
            app.RemoveCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveCellsMenuSelected, true);
            app.RemoveCellsMenu.Separator = 'on';
            app.RemoveCellsMenu.Accelerator = 'R';
            app.RemoveCellsMenu.Text = 'Remove Cells';
            app.DeleteRowsMenu = uimenu(app.ContextMenu);
            app.DeleteRowsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowsMenuSelected, true);
            app.DeleteRowsMenu.Accelerator = 'D';
            app.DeleteRowsMenu.Text = 'Delete Row(s)';
            % Show the figure after all components are created
            app.AddCellsPackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialog_AddCell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddCellsPackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddCellsPackUIFigure)
        end
    end
end