classdef EventLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        errLogUIFigure          matlab.ui.Figure
        GridLayout2             matlab.ui.container.GridLayout
        TextArea                matlab.ui.control.TextArea
        MostRecentLabel         matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        ClearLogButton          matlab.ui.control.Button
        EnableLoggingCheckBox   matlab.ui.control.CheckBox
        SaveToFileButton        matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        cellArrayText = ""; % Cell Array to store error logs
        savePath = "";
        mainApp % Parent App (Battery Manager)
    end
    
    methods (Access = public)
        
        function Add(app, code, error_string, varargin)
            %Add Adds errors to the Event Log Window (initially the matlab
            %command window
            %   Compiles Event Logs for debugging or notifications
            %   Inputs:
            %       code            : The Event code from the EventCode
            %                           Enumeration class
            %       error_string    : The string of error to add to the log
            %                           window
            %       varargin        : Optional. Should only contain extra
            %                           data that could possibly help with
            %                           debugging. Data from here is collected
            %                           into the debug_string variable
            
            
%             textline = '';
            if (app.EnableLoggingCheckBox.Value == 1)
                % Build the Event Log Entry
                textline = string(datetime('now')) + ", ";
                textline = textline + string(code);
                
                % Add the Error code data
                if ~(strcmp(error_string, ""))
                    error_string = char(error_string);
                    textline = textline + ", ";
                    
                    switch code
                        case ErrorCode.USB_DROPPED
                            for character = 1 : strlength(error_string)
                                if (error_string(character) >= " ") && (error_string(character) <= "~")
                                    textline = textline + error_string(character);
                                else
                                    number  = double(char(error_string(character)));
                                    textline = textline + "0x" + dec2hex(number, 2);
                                end
                            end
                        otherwise
                            textline = textline + error_string;
                    end
                end
                
                
                % Add the Error code data
                if(nargin > 3)
                    debug_string = varargin{1};
                    if ~(strcmp(debug_string, ""))
                        textline = textline + ", ";
                        
                        switch code
                            case ErrorCode.USB_DROPPED
                                for character = 1 : strlength(debug_string)
                                    if (debug_string(character) >= " ") && (debug_string(character) <= "~") % if debug_string(character) is a recognizable character
                                        textline = textline + debug_string(character);
                                    else
                                        number  = double(char(debug_string(character)));
                                        textline = textline + "0x" + dec2hex(number, 2);
                                    end
                                end
                            otherwise
                                textline = textline + debug_string;
                        end
                    end
                end
                
                
%                  if strcmp(app.TextArea.Value, '')
%                      app.TextArea.Value = [char(textline) , newline];
%                  else
%                      app.TextArea.Value = [[char(textline) , newline]; app.TextArea.Value];
%                  end
                 
                if (strcmp(app.cellArrayText, ""))
                    app.cellArrayText = textline + newline;
                else
                    app.cellArrayText(end+1) = textline + newline;
                end
                
                app.TextArea.Value = flip(app.cellArrayText);

            end
            
        end
        
        function SaveLogs(app, selpath)
            if selpath == 0
                return;
            end
            app.savePath = selpath;
            file = app.FileNameEditField.Value;
            if strcmpi(file, "(Default)") || strcmpi(file, "")
                file = string(datetime('today', 'format', 'yyMMdd')) + "_EventLog";
            end
            fileID = fopen(app.savePath+"\"+file + ".txt", 'at');
            fprintf(fileID, '%s', app.cellArrayText);
            fclose(fileID);
            
            str = flip(app.TextArea.Value);
            str(end+1) = {['Event Log Saved To : "' , char(app.savePath), '\', char(file) , '.txt"', newline]};   
            app.TextArea.Value = flip(str);
        end
        
        function Clear(app)
            app.cellArrayText = "Event Log Cleared!" + newline;
            app.cellArrayText(end + 1) = newline;
            app.TextArea.Value = flip(app.cellArrayText);
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin == 2
                main_App = varargin{1};
                
                % if the argument passed is not empty and is a valid handle
                if (~isempty(main_App) && isvalid(main_App))
                    app.mainApp = main_App;
                end
            end
            % Find Screen Resolution
            temp = get(groot,'Screensize'); % eventLog.errLogUIFigure.Position
            ScreenWidth = temp(3);
            ScreenHeight = temp(4);
            
            % Position window in center of screen, and set the desired width and height
            set (app.errLogUIFigure, 'position', [0, 40, ScreenWidth/2, ScreenHeight-70]);
        end

        % Close request function: errLogUIFigure
        function errLogUIFigureCloseRequest(app, event)
           % if the argument passed is not empty and is a valid handle
            if (~isempty(app.mainApp) && isvalid(app.mainApp))
                app.errLogUIFigure.Visible = false;
            else
                delete(app)
            end
            
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.Clear();
        end

        % Button pushed function: SaveToFileButton
        function SaveToFileButtonPushed(app, event)
            selpath = uigetdir(app.savePath);
            app.SaveLogs(selpath);

        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
%             position = app.Panel.Position;
%             fileNameLblPos = app.FileNameEditFieldLabel.Position;
%             fileNamePos = app.FileNameEditField.Position;
%             saveBtnPos = app.SaveToFileButton.Position;
% 
%             app.FileNameEditFieldLabel.Position = [position(3) - 374, fileNameLblPos(2), fileNameLblPos(3), fileNameLblPos(4)];
%             app.FileNameEditField.Position = [position(3) - 296, fileNamePos(2), fileNamePos(3), fileNamePos(4)];
%             app.SaveToFileButton.Position = [position(3) - 111, saveBtnPos(2), saveBtnPos(3), saveBtnPos(4)];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.errLogUIFigure = uifigure('Visible', 'off');
            app.errLogUIFigure.Name = 'Event Logger';
            app.errLogUIFigure.CloseRequestFcn = createCallbackFcn(app, @errLogUIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.errLogUIFigure);
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {'Event Logs'};
            app.MostRecentLabel = uilabel(app.GridLayout2);
            app.MostRecentLabel.Text = '<- Most Recent';
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.GridLayout = uigridlayout(app.Panel);
            app.ClearLogButton = uibutton(app.GridLayout, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.EnableLoggingCheckBox = uicheckbox(app.GridLayout);
            app.EnableLoggingCheckBox.Text = 'Enable Logging';
            app.EnableLoggingCheckBox.Value = true;
            app.SaveToFileButton = uibutton(app.GridLayout, 'push');
            app.SaveToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButtonPushed, true);
            app.SaveToFileButton.Tooltip = {'Saves Current Visible Log to Text File'};
            app.SaveToFileButton.Text = 'Save To File';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout, 'text');
            app.FileNameEditField.Tooltip = {'Enter Text File name here'};
            app.FileNameEditField.Value = '(Default)';
            % Show the figure after all components are created
            app.errLogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventLogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.errLogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.errLogUIFigure)
        end
    end
end